classdef egse_DS20_11_11_21 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        egse_guiUIFigure               matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ConnectTab                     matlab.ui.container.Tab
        CommandlogTextAreaLabel        matlab.ui.control.Label
        CommandlogTextArea             matlab.ui.control.TextArea
        NopButton                      matlab.ui.control.Button
        StatusButton                   matlab.ui.control.Button
        COMPortDropDownLabel           matlab.ui.control.Label
        COMPortDropDown                matlab.ui.control.DropDown
        BaudrateDropDownLabel          matlab.ui.control.Label
        BaudrateDropDown               matlab.ui.control.DropDown
        ErrorLampLabel                 matlab.ui.control.Label
        ErrorLamp                      matlab.ui.control.Lamp
        ClearlogButton                 matlab.ui.control.Button
        SensedataButton                matlab.ui.control.Button
        StateEditFieldLabel            matlab.ui.control.Label
        StateEditField                 matlab.ui.control.EditField
        SwitchOffButton                matlab.ui.control.Button
        SampleSwitch_2                 matlab.ui.control.Switch
        HelpButton                     matlab.ui.control.Button
        SensemodeButton                matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        TestdataButton                 matlab.ui.control.Button
        ProgresGauge_2Label            matlab.ui.control.Label
        ConnectProgresGauge            matlab.ui.control.LinearGauge
        error_text                     matlab.ui.control.EditField
        DirEditFieldLabel              matlab.ui.control.Label
        DirEditField                   matlab.ui.control.EditField
        ConfigButton                   matlab.ui.control.Button
        FilenameEditFieldLabel         matlab.ui.control.Label
        FilenameEditField              matlab.ui.control.EditField
        ButtonBrowseDir                matlab.ui.control.Button
        CheckBoxFileName               matlab.ui.control.CheckBox
        ReadConfigButton               matlab.ui.control.Button
        ConfigPanel                    matlab.ui.container.Panel
        ModelButtonGroup               matlab.ui.container.ButtonGroup
        CM1Button                      matlab.ui.control.RadioButton
        EMEQMButton                    matlab.ui.control.RadioButton
        FMButton                       matlab.ui.control.RadioButton
        CM2Button                      matlab.ui.control.RadioButton
        FSButton                       matlab.ui.control.RadioButton
        CM3Button                      matlab.ui.control.RadioButton
        EBBButton                      matlab.ui.control.RadioButton
        DustSensorPreferencesPanel     matlab.ui.container.Panel
        EmitterPanel                   matlab.ui.container.Panel
        WaveDropDownLabel              matlab.ui.control.Label
        WaveDropDown                   matlab.ui.control.DropDown
        FrequencyDropDownLabel         matlab.ui.control.Label
        FrequencyDropDown              matlab.ui.control.DropDown
        EmitterWaveCheckBox            matlab.ui.control.CheckBox
        EmitterFreqCheckBox            matlab.ui.control.CheckBox
        EmitterVpCheckBox              matlab.ui.control.CheckBox
        EmitterWaveLamp                matlab.ui.control.Lamp
        EmitterFreqLamp                matlab.ui.control.Lamp
        EmitterVpLamp                  matlab.ui.control.Lamp
        VpLabel                        matlab.ui.control.Label
        VpEditField                    matlab.ui.control.NumericEditField
        SensetimeEditFieldLabel        matlab.ui.control.Label
        SensetimeC_EditField           matlab.ui.control.NumericEditField
        ConfigApplyButton              matlab.ui.control.Button
        CloseConfigButton              matlab.ui.control.Button
        WarmUpTimeLamp                 matlab.ui.control.Lamp
        SensingTimeLamp                matlab.ui.control.Lamp
        WarmUpTimeCheckBox             matlab.ui.control.CheckBox
        SensingTimeCheckBox            matlab.ui.control.CheckBox
        ConfigInfoButton               matlab.ui.control.Button
        WarmUptimeEditFieldLabel       matlab.ui.control.Label
        WarmUptimeC_EditField          matlab.ui.control.NumericEditField
        ModeLabel                      matlab.ui.control.Label
        ModeSwitch                     matlab.ui.control.Switch
        ConfigInfoPanel                matlab.ui.container.Panel
        EmitterWaveEditFieldLabel      matlab.ui.control.Label
        EmitterWaveInfoEditField       matlab.ui.control.EditField
        EmitterVpEditFieldLabel        matlab.ui.control.Label
        EmitterVpInfoEditField         matlab.ui.control.NumericEditField
        WarmUptimeEditFieldLabel_2     matlab.ui.control.Label
        WarmUptimeInfoEditField        matlab.ui.control.NumericEditField
        SensetimeEditFieldLabel_2      matlab.ui.control.Label
        SensetimeInfoEditField         matlab.ui.control.NumericEditField
        EmitterFrequencyLabel          matlab.ui.control.Label
        EmitterFrequencyInfoEditField  matlab.ui.control.EditField
        AcquireTab                     matlab.ui.container.Tab
        DrawButton                     matlab.ui.control.Button
        FigureButton                   matlab.ui.control.Button
        SignalsPanel                   matlab.ui.container.Panel
        V1CheckBox                     matlab.ui.control.CheckBox
        Detector1PbSFwdLabel           matlab.ui.control.Label
        CheckBox                       matlab.ui.control.CheckBox
        XCheckBox                      matlab.ui.control.CheckBox
        YCheckBox                      matlab.ui.control.CheckBox
        V2CheckBox                     matlab.ui.control.CheckBox
        Detector2PbSeFwdLabel          matlab.ui.control.Label
        V3CheckBox                     matlab.ui.control.CheckBox
        Detector3PbSBwdLabel           matlab.ui.control.Label
        CheckBox_2                     matlab.ui.control.CheckBox
        XCheckBox_2                    matlab.ui.control.CheckBox
        YCheckBox_2                    matlab.ui.control.CheckBox
        CheckBox_3                     matlab.ui.control.CheckBox
        XCheckBox_3                    matlab.ui.control.CheckBox
        YCheckBox_3                    matlab.ui.control.CheckBox
        Detector4PbSeBwdLabel          matlab.ui.control.Label
        V4CheckBox                     matlab.ui.control.CheckBox
        CheckBox_4                     matlab.ui.control.CheckBox
        XCheckBox_4                    matlab.ui.control.CheckBox
        YCheckBox_4                    matlab.ui.control.CheckBox
        Vp1EditFieldLabel              matlab.ui.control.Label
        Vp1EditField                   matlab.ui.control.NumericEditField
        Vp2EditFieldLabel              matlab.ui.control.Label
        Vp2EditField                   matlab.ui.control.NumericEditField
        Vp3EditFieldLabel              matlab.ui.control.Label
        Vp3EditField                   matlab.ui.control.NumericEditField
        Vp4EditFieldLabel              matlab.ui.control.Label
        Vp4EditField                   matlab.ui.control.NumericEditField
        TButton                        matlab.ui.control.Button
        VButton                        matlab.ui.control.Button
        PhaseButton                    matlab.ui.control.Button
        ExportdataButton               matlab.ui.control.Button
        ConfigButton_2                 matlab.ui.control.Button
        ConfigPanel_2                  matlab.ui.container.Panel
        InputdataPanel                 matlab.ui.container.Panel
        SignedCheckBox                 matlab.ui.control.CheckBox
        BitsEditFieldLabel             matlab.ui.control.Label
        BitsEditField                  matlab.ui.control.NumericEditField
        AxisPanel                      matlab.ui.container.Panel
        SamplingFreqHzEditFieldLabel   matlab.ui.control.Label
        SamplingFreqHzEditField        matlab.ui.control.NumericEditField
        YmaxEditField_2Label           matlab.ui.control.Label
        YmaxEditField                  matlab.ui.control.NumericEditField
        YminEditFieldLabel             matlab.ui.control.Label
        YminEditField                  matlab.ui.control.NumericEditField
        SaveExportedDataLabel          matlab.ui.control.Label
        SaveExportedDataSwitch         matlab.ui.control.Switch
        ReferenceSignalPanel           matlab.ui.container.Panel
        RefSwitch_3Label               matlab.ui.control.Label
        RefSwitch                      matlab.ui.control.Switch
        EmitterDropDownLabel           matlab.ui.control.Label
        WaveEmitRDropDown              matlab.ui.control.DropDown
        VrefEditFieldLabel             matlab.ui.control.Label
        VrefEditField                  matlab.ui.control.NumericEditField
        TempCorrectionPanel            matlab.ui.container.Panel
        CM3TempcorrectionVcc2SwitchLabel  matlab.ui.control.Label
        CM3TempcorrectionVcc2Switch    matlab.ui.control.Switch
        FMTempcorrectionVcc2SwitchLabel  matlab.ui.control.Label
        FMTempcorrectionVcc2Switch     matlab.ui.control.Switch
        UIAxes                         matlab.ui.control.UIAxes
        MultiTab                       matlab.ui.container.Tab
        UIAxes_1                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        MultiTab_fft                   matlab.ui.container.Tab
        UIAxes_fft_1                   matlab.ui.control.UIAxes
        UIAxes_fft_2                   matlab.ui.control.UIAxes
        UIAxes_fft_3                   matlab.ui.control.UIAxes
        UIAxes_fft_4                   matlab.ui.control.UIAxes
        AutoTab                        matlab.ui.container.Tab
        LogwindowTextAreaLabel         matlab.ui.control.Label
        LogwindowTextArea              matlab.ui.control.TextArea
        AutosetupPanel                 matlab.ui.container.Panel
        SelftesttimeLabel              matlab.ui.control.Label
        SelftesttimeEditField          matlab.ui.control.NumericEditField
        WarmuptimeEditFieldLabel       matlab.ui.control.Label
        WarmuptimeEditField            matlab.ui.control.NumericEditField
        MeasurementtimeEditFieldLabel  matlab.ui.control.Label
        MeasurementtimeEditField       matlab.ui.control.NumericEditField
        SwitchofftimeEditFieldLabel    matlab.ui.control.Label
        SwitchofftimeEditField         matlab.ui.control.NumericEditField
        NofcampaignsEditFieldLabel     matlab.ui.control.Label
        NofcampaignsEditField          matlab.ui.control.NumericEditField
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        StartButton_2                  matlab.ui.control.Button
        ProgresGaugeLabel              matlab.ui.control.Label
        ProgresGauge                   matlab.ui.control.LinearGauge
        CancelButton                   matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        MaxtempEditFieldLabel          matlab.ui.control.Label
        MaxtempEditField               matlab.ui.control.NumericEditField
        PlotButton                     matlab.ui.control.Button
        ContinuousmodeCheckBox         matlab.ui.control.CheckBox
        ConfigButton_3                 matlab.ui.control.Button
        AutoplotconfigPanel            matlab.ui.container.Panel
        OrderfilesbyButtonGroup        matlab.ui.container.ButtonGroup
        nameButton                     matlab.ui.control.RadioButton
        campaigneButton                matlab.ui.control.RadioButton
        dateButton                     matlab.ui.control.RadioButton
        FMTempcorrectionVcc2Switch_2Label  matlab.ui.control.Label
        FMTempcorrectionVcc2Switch_2   matlab.ui.control.Switch
        RefSwitchLabel_2               matlab.ui.control.Label
        VrefEditField_2Label           matlab.ui.control.Label
        VrefEditField_2                matlab.ui.control.NumericEditField
        RefSwitch_2                    matlab.ui.control.Switch
        EmitterLabel                   matlab.ui.control.Label
        WaveEmitRDropDown_2            matlab.ui.control.DropDown
        DrawingoptionsPanel            matlab.ui.container.Panel
        PbSFwdCheckBox                 matlab.ui.control.CheckBox
        PbSeFwdCheckBox                matlab.ui.control.CheckBox
        PbSBwdCheckBox                 matlab.ui.control.CheckBox
        Vcc2CheckBox                   matlab.ui.control.CheckBox
        RatioPbSCheckBox               matlab.ui.control.CheckBox
        RatioPbSeCheckBox              matlab.ui.control.CheckBox
        PbSeBwdCheckBox                matlab.ui.control.CheckBox
        ProgresTimeEditField           matlab.ui.control.EditField
        DatafileEditFieldLabel         matlab.ui.control.Label
        DatafileEditField              matlab.ui.control.EditField
        ButtonBrowse                   matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        ConfigString % Description
        StatusDS % Description
        InitComm % Description
        
        %%%%RELAY%%%%%%
        
        %Relays on and off state
        Relay_on=0;
        Relay_off=1;
        %Arduino pins used to control Relays
        pin_1='D11';
        pin_2='D12';  %5V
        pin_3='D13';
        %Arduino instance
        arduino;
        
        %%%END RELAY%%%%
        
        %%AUTO FUNCTIONALITY%%
        
        AutoTimer;           %Timer instance for auto funcionality
        AutoState;           %State variable for control DS state
        AutoStop;            %State variable to ensure proper auto mode stop when user stops auto procedure
        
        AutoProgresTimer;             %Timer instance for Progres Bar with continue movement
        AutoProgresTimerCount;        %Var to store current timer time. Its updated each second. Progress bar
        AutoProgresTimerTotalCount;   %Total time acutal procedure. Progress bar
        %Variable to store the configured auto times
        SelfTest_time;
        WarmUp_time;
        Meas_time;
        switch_off_time;
        
        %Time configuration
        WarmUpConfig_time;
        MeasConfig_time;
        %Emitter configuration
        EmitterFreq;
        EmitterWave;
        EmitterVp;
        
        N_campaign;             %Variable to store the configured number of campaignes
        Name;
        TotalTime;              %Total time per campaigne. Used to compute % progress
        campaign;               %Number of actual campaigne
        fileID_general_log;     %File ID of general log
        fileID_log_concreto;    %File ID of concret(per campaigne - more detailed) log
        number_errors;          %number of total errors
        error1;                 %temporary var for errors
        dir_name;               %directory to save logs, data etc
        AutoFirstRun            %Var to track if the current campaigne is the first one
        
        %END AUTO
        
        
        Vpref_seno_default;        %Var to store amplitud when a sine ref is used in lock in
        SamplingFreqUserChange;    %Var to track change in sampling freq gui box
        TargetModel;               %Var to store DS20 model
        
        %%PROGRESS BAR AND ALERT CONNECT-MANUAL CAMPAIGNE%%
        
        ConnectTimer;                %Timer instance
        ConnectTimerCount;           %Var to store current timer time. Its updated each second. Progress bar
        ConnectTimerTotalCount;      %Total time acutal procedure. Progress bar
        
        %%END
        
        Sonido;                      %var to store sound for alerts
        
        LastFile;                    %Var to store last data save in manual campaigne
        
        
    end
    
    methods (Access = private)
        
        %%Function to send commands and read the responses
        function results = SendCmd(app,cmd)
            app.ConfigString = strcat('egse_cli -p',app.COMPortDropDown.Value,' -b',app.BaudrateDropDown.Value );
            if strcmp(app.ModeSwitch.Value,'L') %lock in - no lock in modes
                command = strcat(app.ConfigString, ' -c ', cmd);
            else
                command = strcat(app.ConfigString, ' -c ', cmd);
                command = strrep(command,'egse_cli','egse_cli_no_lockin');
                
            end
            
            app.CommandlogTextArea.Value = command;   %log info
            
            [ok,cmdout] = system(command);       %Execute command
            if ok ~= 0  %System error(PC not executing egse_cli.exe or egse_cli.exe error)
                app.ErrorLamp.Color = 'red';
                app.ErrorLamp.Enable = 'on';
                results =0;
                app.error_text.Value='System error';
                app.CommandlogTextArea.Value = { command; cmdout };
            elseif contains(cmdout,'Ack not received') %no answer error
                app.ErrorLamp.Color = 'red';
                app.ErrorLamp.Enable = 'on';
                results =0;
                app.error_text.Value='No Ack';
                app.CommandlogTextArea.Value = { command; cmdout };
            else     %no error or diferent type of error
                lines = strsplit(deblank(cmdout), '\n');
                %Print answer on screen (depends on the command)
                if contains(cmd,'04')   %Get Data command
                    final_line=replace(lines(end-2),'/ ','');
                    dataline = deblank(extractAfter(final_line,') '));
                    if ~contains(dataline,'Data not received')
                        result2 = split(dataline);
                        data_measured=hex2dec(strcat(result2(6) ,result2(5)));
                        string=sprintf('Data recieved=%i',data_measured);
                        app.CommandlogTextArea.Value = { command; cmdout;string };
                    else
                        app.CommandlogTextArea.Value = { command; cmdout };
                    end
                elseif strcmp(cmd,'03')  %Status command
                    line = replace(lines(6),'/ ','');
                    status_line = deblank(extractAfter(line,') '));
                    if ~contains(status_line,'Data not received')
                        result_2 = split(status_line);
                        data_measured=hex2dec(strcat(result_2(5) ,result_2(4)));
                        data_sended=hex2dec(strcat(result_2(7) ,result_2(6)));
                        string=sprintf('Data measured=%i Data sended=%i',data_measured,data_sended);
                        app.CommandlogTextArea.Value = { command; cmdout; string };
                    else
                        app.CommandlogTextArea.Value = { command; cmdout };
                    end
                elseif strcmp(cmd, '09') %Read Config Command
                    app.CommandlogTextArea.Value = { command; cmdout };
                    
                    %Saves Config value into ConfigInfo Panel
                    %                   ID_data = deblank(extractAfter(lines(8),': '))
                    app.WarmUpConfig_time = str2double(deblank(extractBetween(lines(9),': ',' s')));
                    app.MeasConfig_time = str2double(deblank(extractBetween(lines(10),': ',' s')));
                    app.EmitterFreq = char(deblank(extractAfter(lines(11),': ')));
                    app.EmitterWave = char(deblank(extractAfter(lines(12),': ')));
                    app.EmitterVp = str2double(deblank(extractBetween(lines(13),': ',' V')));
                    
                    
                else %rest of the commands
                    app.CommandlogTextArea.Value = { command; cmdout };
                end %end print answer
                
                %Proccess answer
                if length(lines)>3
                    line = replace(lines(4),'/ ','');
                    cmdline = deblank(extractAfter(line,') '));
                end
                if length(lines)>4
                    if length(cmd)==2  %not telemetry command
                        line = replace(lines(5),'/ ','');
                    else
                        line = replace(lines(6),'/ ','');
                    end
                    
                    ackline = deblank(extractAfter(line,') '));
                    result = split(ackline);
                    
                    size = result(2);
                    data = result(3:length(result)-2);
                    status = hex2dec(char(result(length(result)-1)));
                    crc = result(length(result));
                    %end process answer
                    
                    %Extract status and errors in response
                    ok = fix(status/16);
                    status = status - 16*ok;
                    command_status=fix(ok/8);
                    error_code=ok-command_status*8;
                    
                    %Decode error data and shows in GUI text box
                    if ~strcmp(cmd,'00')  %No nop command
                        if command_status  %Command not procesed
                            app.error_text.Value='Invalid state';
                        else
                            switch error_code
                                case 0
                                    app.error_text.Value='No error';
                                case 1
                                    app.error_text.Value='Wrong command';
                                case 2
                                    app.error_text.Value='Length error';
                                case 3
                                    app.error_text.Value='Checksum error';
                                case 4
                                    app.error_text.Value='Parity error';
                                case 5
                                    app.error_text.Value='Time error';
                            end
                        end
                    else
                        switch error_code
                            case 0
                                app.error_text.Value='No error';
                            case 1
                                app.error_text.Value='Wrong command';
                            case 2
                                app.error_text.Value='Length error';
                            case 3
                                app.error_text.Value='Checksum error';
                            case 4
                                app.error_text.Value='Parity error';
                            case 5
                                app.error_text.Value='Time error';
                        end
                        
                        
                    end  %end decode error
                    
                    
                    %Color indicator
                    if strcmp(app.error_text.Value,'No error')
                        app.ErrorLamp.Color = 'green';
                        app.ErrorLamp.Enable = 'on';
                        results=1;
                    else
                        app.ErrorLamp.Color = 'red';
                        app.ErrorLamp.Enable = 'on';
                        results=0;
                    end %End Color indicator
                    
                    %Show state in GUI text box
                    switch status
                        case 0
                            app.StateEditField.Value = 'Idle';
                        case 1
                            app.StateEditField.Value ='Config';
                        case 2
                            app.StateEditField.Value = 'W. up';
                        case 3
                            app.StateEditField.Value = 'Sens';
                        case 4
                            app.StateEditField.Value = 'Tele';
                        otherwise
                            app.error_text.Value='?';
                    end %end show sate
                    
                end
                
            end
        end %end fuction
        
        %%Draw the plots of the detector's data
        function results = Draw(app)
            if isempty(app.DatafileEditField.Value) %safe check
                return;
            end
            
            run(app.DatafileEditField.Value);       %execute data file to get data
            
            scale = (app.YmaxEditField.Value-app.YminEditField.Value)/(2^app.BitsEditField.Value-1);
            
            if  (exist('sensors','var'))  %No lock in data
                
                if app.SamplingFreqUserChange==0   %Check user has changed sampling freq if not adjust to default no lock in freq
                    if app.SamplingFreqHzEditField.Value~=2 && app.SamplingFreqHzEditField.Value~=1000
                        fs = app.SamplingFreqHzEditField.Value;
                    else
                        app.SamplingFreqHzEditField.Value=1000;
                        fs=1000;
                    end
                end
                
                %change gui acording to no lock state
                app.V1CheckBox.Text='V';
                app.V2CheckBox.Text='V';
                app.V3CheckBox.Text='V';
                app.V4CheckBox.Text='V';
                
                app.CheckBox.Visible='off';
                app.CheckBox_2.Visible='off';
                app.CheckBox_3.Visible='off';
                app.CheckBox_4.Visible='off';
                
                app.XCheckBox.Visible='off';
                app.XCheckBox_2.Visible='off';
                app.XCheckBox_3.Visible='off';
                app.XCheckBox_4.Visible='off';
                
                app.YCheckBox.Visible='off';
                app.YCheckBox_2.Visible='off';
                app.YCheckBox_3.Visible='off';
                app.YCheckBox_4.Visible='off';
                
                app.Vp1EditField.Enable='off';
                app.Vp2EditField.Enable='off';
                app.Vp3EditField.Enable='off';
                app.Vp4EditField.Enable='off';
                
                app.VrefEditField.Enable='off';
                app.VrefEditField.Visible='off';
                
                app.RefSwitch.Enable='off';
                app.RefSwitch.Visible='off';
                
                %Proccess data
                if app.SignedCheckBox.Value
                    for i = 1:size(sensors,1)
                        for j = 1:size(sensors,2)
                            if sensors(i,j) >= 2^(app.BitsEditField.Value-1)
                                sensors(i,j) = sensors(i,j) - 2^app.BitsEditField.Value;
                            end
                        end
                    end
                    sensors = sensors + 2^(app.BitsEditField.Value-1);
                end
                voltages = sensors*scale + app.YminEditField.Value;
                l = length(voltages(1,:));
                x = linspace(0,(l-1)/fs,l);
                
                %Plot data
                app.UIAxes.GridLineStyle = '--';
                cla(app.UIAxes);
                hold(app.UIAxes,'on');
                grid(app.UIAxes,'on');
                
                if app.V1CheckBox.Value
                    plot(app.UIAxes,x,voltages(1,:),'red');
                end
                if app.V2CheckBox.Value
                    plot(app.UIAxes,x,voltages(2,:),'green');
                end
                if app.V3CheckBox.Value
                    plot(app.UIAxes,x,voltages(3,:),'blue');
                end
                if app.V4CheckBox.Value
                    plot(app.UIAxes,x,voltages(4,:),'black');
                end
                hold(app.UIAxes,'off');
                
                
                % Plot Multi tab
                plot(app.UIAxes_1,x,voltages(1,:),'red');
                plot(app.UIAxes_2,x,voltages(2,:),'green');
                plot(app.UIAxes_3,x,voltages(3,:),'blue');
                plot(app.UIAxes_4,x,voltages(4,:),'black');
                
                legend(app.UIAxes_1,'off');
                legend(app.UIAxes_2,'off');
                legend(app.UIAxes_3,'off');
                legend(app.UIAxes_4,'off');
                
                % Plot FFT tab
                d = voltages(1,:);
                [pxx,f] =periodogram(d,hann(length(d),'periodic'),length(d),fs);
                plot(app.UIAxes_fft_1,f,10*log10(pxx),'red');
                d = voltages(2,:);
                [pxx,f] =periodogram(d,hann(length(d),'periodic'),length(d),fs);
                plot(app.UIAxes_fft_2,f,10*log10(pxx),'green');
                d = voltages(3,:);
                [pxx,f] =periodogram(d,hann(length(d),'periodic'),length(d),fs);
                plot(app.UIAxes_fft_3,f,10*log10(pxx),'blue');
                d = voltages(4,:);
                [pxx,f] =periodogram(d,hann(length(d),'periodic'),length(d),fs);
                plot(app.UIAxes_fft_4,f,10*log10(pxx),'black');
                
            else
                %lock in data
                
                
                if ~exist('sensors_x','var') %safe check
                    return
                end
                if ~exist('sensors_y','var') %safe check
                    return
                end
                
                if app.SamplingFreqUserChange==0 %Check user has changed sampling freq if not adjust to default lock in freq
                    if app.SamplingFreqHzEditField.Value~=2 && app.SamplingFreqHzEditField.Value~=1000
                        fs = app.SamplingFreqHzEditField.Value;
                    else
                        app.SamplingFreqHzEditField.Value=2;
                        fs=2;
                    end
                end
                %change gui acording to no lock state
                app.V1CheckBox.Text='Vp';
                app.V2CheckBox.Text='Vp';
                app.V3CheckBox.Text='Vp';
                app.V4CheckBox.Text='Vp';
                
                app.CheckBox.Visible='on';
                app.CheckBox_2.Visible='on';
                app.CheckBox_3.Visible='on';
                app.CheckBox_4.Visible='on';
                
                app.XCheckBox.Visible='on';
                app.XCheckBox_2.Visible='on';
                app.XCheckBox_3.Visible='on';
                app.XCheckBox_4.Visible='on';
                
                app.YCheckBox.Visible='on';
                app.YCheckBox_2.Visible='on';
                app.YCheckBox_3.Visible='on';
                app.YCheckBox_4.Visible='on';
                
                app.Vp1EditField.Enable='on';
                app.Vp2EditField.Enable='on';
                app.Vp3EditField.Enable='on';
                app.Vp4EditField.Enable='on';
                
                app.VrefEditField.Enable='on';
                app.VrefEditField.Visible='on';
                
                app.WaveEmitRDropDown.Enable='on';
                app.WaveEmitRDropDown.Visible='on';
                
                %Generate conversion-scaling factor
                if strcmp(app.RefSwitch.Value,'Sine')%check lock in ref type
                    scale = (app.YmaxEditField.Value-app.YminEditField.Value)^2/(2^app.BitsEditField.Value);
                else
                    scale = (app.YmaxEditField.Value-app.YminEditField.Value)/(2^app.BitsEditField.Value);
                end
                %Poccess data
                
                if app.SignedCheckBox.Value
                    for i = 1:size(sensors_x,1)
                        for j = 1:size(sensors_x,2)
                            if sensors_x(i,j) >= 2^(app.BitsEditField.Value-1)
                                sensors_x(i,j) = sensors_x(i,j) - 2^app.BitsEditField.Value;
                            end
                        end
                    end
                    
                end
                
                if app.SignedCheckBox.Value
                    for i = 1:size(sensors_y,1)
                        for j = 1:size(sensors_y,2)
                            if sensors_y(i,j) >= 2^(app.BitsEditField.Value-1)
                                sensors_y(i,j) = sensors_y(i,j) - 2^app.BitsEditField.Value;
                            end
                        end
                    end
                end
                voltages_x = sensors_x*scale;
                voltages_y = sensors_y*scale;
                R=sqrt((voltages_x.^2)+(voltages_y.^2));
                Vp=R*2/app.VrefEditField.Value;
                
                Vp1=Vp(1,:);
                Vp2=Vp(2,:);
                Vp3=Vp(3,:);
                Vp4=Vp(4,:);


                %Compute mean for gui text boxes
                Vp1_mean=mean(Vp1(Vp1~=0));
                Vp2_mean=mean(Vp2(Vp2~=0));
                Vp3_mean=mean(Vp3(Vp3~=0));
                Vp4_mean=mean(Vp4(Vp4~=0));
                
                %If Meas_time is 0, saves 0 as mean value to avoid errors
                if isempty(Vp1(Vp1~=0))
                    Vp1_mean = 0;
                end
                
                if isempty(Vp2(Vp2~=0))
                    Vp2_mean = 0;
                end
                
                if isempty(Vp3(Vp3~=0))
                    Vp3_mean = 0;
                end
                
                if isempty(Vp4(Vp4~=0))
                    Vp4_mean = 0;
                end
                
                
                app.Vp1EditField.Value=Vp1_mean;
                app.Vp2EditField.Value=Vp2_mean;
                app.Vp3EditField.Value=Vp3_mean;
                app.Vp4EditField.Value=Vp4_mean;
                
                l = length(voltages_x(1,:));
                x = linspace(0.5,l/fs,l);
                %%%%Plot data%%%%%
                
                app.UIAxes.GridLineStyle = '--';
                cla(app.UIAxes);
                hold(app.UIAxes,'on');
                grid(app.UIAxes,'on');
                
                %plot adquire tab
                if app.V1CheckBox.Value  %check if the user want this plot(same procedure for all channels)
                    plot(app.UIAxes,x,Vp(1,:),'red');
                end
                if app.V2CheckBox.Value
                    plot(app.UIAxes,x,Vp(2,:),'green');
                end
                if app.V3CheckBox.Value
                    plot(app.UIAxes,x,Vp(3,:),'blue');
                end
                if app.V4CheckBox.Value
                    plot(app.UIAxes,x,Vp(4,:),'black');
                end
                
                hold(app.UIAxes,'off');
                
                % Plot Multi tab
                plot(app.UIAxes_1,x,voltages_x(1,:),'red');
                plot(app.UIAxes_2,x,voltages_x(2,:),'red');
                plot(app.UIAxes_3,x,voltages_x(3,:),'red');
                plot(app.UIAxes_4,x,voltages_x(4,:),'red');
                
                hold(app.UIAxes_1,'on');
                hold(app.UIAxes_2,'on');
                hold(app.UIAxes_3,'on');
                hold(app.UIAxes_4,'on');
                
                
                plot(app.UIAxes_1,x,voltages_y(1,:),'blue');
                plot(app.UIAxes_2,x,voltages_y(2,:),'blue');
                plot(app.UIAxes_3,x,voltages_y(3,:),'blue');
                plot(app.UIAxes_4,x,voltages_y(4,:),'blue');
                
                legend(app.UIAxes_1,{'X','Y'});
                legend(app.UIAxes_2,{'X','Y'});
                legend(app.UIAxes_3,{'X','Y'});
                legend(app.UIAxes_4,{'X','Y'});
                
                hold(app.UIAxes_1,'off');
                hold(app.UIAxes_2,'off');
                hold(app.UIAxes_3,'off');
                hold(app.UIAxes_4,'off');
                
                
                % Plot FFT tab
                
                d = Vp1;
                [pxx,f] =periodogram(d,hanning(length(d),'periodic'),length(d),fs);
                plot(app.UIAxes_fft_1,f,10*log10(pxx),'red');
                d = Vp2;
                [pxx,f] =periodogram(d,hanning(length(d),'periodic'),length(d),fs);
                plot(app.UIAxes_fft_2,f,10*log10(pxx),'green');
                d = Vp3;
                [pxx,f] =periodogram(d,hanning(length(d),'periodic'),length(d),fs);
                plot(app.UIAxes_fft_3,f,10*log10(pxx),'blue');
                d = Vp4;
                [pxx,f] =periodogram(d,hanning(length(d),'periodic'),length(d),fs);
                plot(app.UIAxes_fft_4,f,10*log10(pxx),'black');
                
                
            end
            
        end
        
        function results = FindSenseFile(app)
            %Find last sense file to auto fill file text box at app init
            files = dir('sens*.m');
            if length(files) > 0
                j = 0;
                for i = 1:length(files)
                    if j == 0 || files(i).datenum < files(j).datenum
                        j = i;
                    end
                end
                results = files(j).name;
            else
                results = '';
            end
            
        end
        
        function error =send_command(app,cmd,next_state,fileID_general_log,fileID_log_concreto)
            %              Function to send command for auto campaignes. Similar to sendCmd but diferent logs and error management
            % Inputs:
            %     - next_state: expected next state  string of 2 digits ej:'01'
            %     - fileID_general_log: file id general
            %     - fileID_log_concreto: file id campaigne log
            
            
            %              Generate cmd command
            app.ConfigString = strcat('egse_cli -p',app.COMPortDropDown.Value,' -b',app.BaudrateDropDown.Value );
            if strcmp(app.ModeSwitch.Value,'L')
                command = strcat(app.ConfigString, ' -c ', cmd);
            else
                command = strcat(app.ConfigString, ' -c ', cmd);
                command = strrep(command,'egse_cli','egse_cli_no_lockin');
                
            end
            %             Write command to log
            fprintf(fileID_log_concreto,'Comand: %s\n',command);
            %             Send command
            [ok,cmdout] = system(command);
            error=0;
            %             Check error
            if ok ~= 0 %system error
                fprintf(fileID_log_concreto,'SYSTEM ERROR\nComand response: %s\n',cmdout);
                fprintf(fileID_general_log,'System Error ');
                error=1;
                errorA = 1
                cmdA = cmd%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            elseif contains(cmdout,'Ack not received')
                fprintf(fileID_log_concreto,'ACK NOT RECEIVED ERROR\nComand response: %s\n',cmdout);
                fprintf(fileID_general_log,'ACK NOT RECEIVED ERROR ');
                error=1;
                errorB = 1
                cmdB = cmd%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            elseif contains(cmdout,'<CRC error>')
                fprintf(fileID_log_concreto,'CRC ERROR\nComand response: %s\n',cmdout);
                fprintf(fileID_general_log,'CRC ERROR ');
                error=1;
                errorC = 1
                cmdC = cmd%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            else %no error or diferent error
                
                %process response
                lines = strsplit(deblank(cmdout), '\n');
                if length(lines)>3
                    line = replace(lines(4),'/ ','');
                    cmdline = deblank(extractAfter(line,') '));
                end
                
                if length(lines)>4
                    if length(cmd)==2  %telemetry command
                        line = replace(lines(5),'/ ','');
                    else
                        line = replace(lines(6),'/ ','');
                    end
                    ackline = deblank(extractAfter(line,') '));
                    result = split(ackline);
                    
                    size = result(2);
                    data = result(3:length(result)-2);
                    status = hex2dec(char(result(length(result)-1)));
                    crc = result(length(result));
                    
                    %extract status and error
                    ok = fix(status/16);
                    status = status - 16*ok;
                    %check errors, correct DS state and extract number of measured data and sended data
                    
                    if ~strcmp(cmd,'03') %not Status command
                        if ~strcmp(cmd,'00') %not nop cmd
                            if(ok==0 && status==(next_state  ))
                                fprintf(fileID_log_concreto,'Success\nComand Response: %s\n',cmdout);
                                fprintf(fileID_general_log,'Success ') ;
                            else
                                fprintf(fileID_log_concreto,'EIS ERROR\nError code: %i State: %i\nComand Response: %s\n',ok,status,cmdout);
                                fprintf(fileID_general_log,'Error ');
                                error=1;
                                errorD = 1
                                cmdD = cmd%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            end
                        else
                            fprintf(fileID_log_concreto,'Success\nComand Response: %s\n',cmdout);
                            fprintf(fileID_general_log,'Success ') ;
                        end
                    else %status comand
                        if contains(cmd,'04')   %%COMPROBAR
                            final_line=replace(lines(end - 2),'/ ','');
                            dataline = deblank(extractAfter(final_line,') '));
                            if ~contains(dataline,'Data not received')
                                result2 = split(dataline);
                                data_measured=hex2dec(strcat(result2(6) ,result2(5)));
                                data_sended=data_measured;
                                data_received=1;
                            else
                                data_measured=0;
                                data_sended=0;
                                data_received=0;
                            end
                        else
                            line = replace(lines(6),'/ ','');
                            status_line = deblank(extractAfter(line,') '));
                            if ~contains(status_line,'Data not received')
                                result_2 = split(status_line);
                                data_measured=hex2dec(strcat(result_2(5) ,result_2(4)));
                                data_sended=hex2dec(strcat(result_2(7) ,result_2(6)));
                                data_received=1;
                            else
                                data_measured=0;
                                data_sended=0;
                                data_received=0;
                            end
                        end


                        if(ok==0 && status==(next_state ) &&data_measured==data_sended)   %&& status==next_state
                            temp=sprintf('Data measured=%i Data sended=%i',data_measured,data_sended);
                            fprintf(fileID_log_concreto,'Success\n%s\nComand Response: %s\n',temp,cmdout) ;
                            fprintf(fileID_general_log,'Success ') ;
                        else
                            if(data_received)
                                temp=sprintf('Data measured=%i Data sended=%i',data_measured,data_sended);
                                fprintf(fileID_log_concreto,'EIS ERROR\nError code: %i State: %i\n%s\nComand Response: %s\n',ok,status,temp,cmdout);
                                fprintf(fileID_general_log,'Error ');
                                error=1;
                                errorE = 1
                                cmdE = cmd%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            else
                                temp=sprintf('Data not received');
                                fprintf(fileID_log_concreto,'EIS ERROR\nError code: %i State: %i\n%s\nComand Response: %s\n',ok,status,temp,cmdout);
                                fprintf(fileID_general_log,'Error ');
                                error=1;
                                errorF = 1
                                cmdF = cmd%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            end
                        end
                    end
                end
                
            end
            
        end
        
        function lCOM_Port = getAvailableComPort(app)
            % function lCOM_Port = getAvailableComPort()
            % Return a Cell Array of COM port names available on your computer
            
            try
                s=serial('IMPOSSIBLE_NAME_ON_PORT');fopen(s);
            catch
                lErrMsg = lasterr;
            end
            
            %Start of the COM available port
            lIndex1 = findstr(lErrMsg,'COM');
            %End of COM available port
            lIndex2 = findstr(lErrMsg,'Use')-3;
            
            lComStr = lErrMsg(lIndex1:lIndex2);
            
            %Parse the resulting string
            lIndexDot = findstr(lComStr,',');
            
            % If no Port are available
            if isempty(lIndex1)
                lCOM_Port{1}='';
                return;
            end
            
            % If only one Port is available
            if isempty(lIndexDot)
                lCOM_Port{1}=lComStr;
                return;
            end
            
            lCOM_Port{1} = lComStr(1:lIndexDot(1)-1);
            
            for i=1:numel(lIndexDot)+1
                % First One
                if (i==1)
                    lCOM_Port{1,1} = lComStr(1:lIndexDot(i)-1);
                    % Last One
                elseif (i==numel(lIndexDot)+1)
                    lCOM_Port{i,1} = lComStr(lIndexDot(i-1)+2:end);
                    % Others
                else
                    lCOM_Port{i,1} = lComStr(lIndexDot(i-1)+2:lIndexDot(i)-1);
                end
            end
            
            % In order to put at the beginning always the FPGA port
            [stat, cmdo] = system("reg query HKLM\HARDWARE\DEVICEMAP\SERIALCOMM");
            spl = (strsplit(cmdo, 'Device'))';
            spl = spl(2:end);
            
            for i = 1:length(spl)
                if spl{i}(2:4) == 'VCP'
                    fport = spl{i}(20:23);
                end
            end
            
            for i = 1:length(lCOM_Port)
                if lCOM_Port{i,1} == fport
                    break
                end
            end
            
            lCOM_Port{i,1} = lCOM_Port{1,1};
            lCOM_Port{1,1} = fport;
            
        end
        
        
        function DrawAutoCampaignes(app,files_dir,model,sens_time,x_axis_format,name_format)
            
            %------------------------CONFIGURATION-------------------------------%
            %      %This config is taken from GUI
            
            
            %     model='CM2';          %CM1 , CM2, EM(EM/EQM), FM or FS
            %     sens_time='long';    %long or short //not ready, use only for long data
            %     Ref='square';        %square or sine
            %     Vp_ref_seno=3.76/2;  %Peak voltage of sine reference
            %     x_axis_format='Dates'; %Dates or no date only changes plots
            %     % name_format='Auto'; %Auto or No auto(todavia no implementado)
            %     order='date';         %name , date or campaigne
            %     temperature_correction='on'; %temp correction for FM values = on/off
            %     % filter_x_y='on';
            
            
            %------------------------FUNTIONALITY---------------------------------%
            %
            path=pwd;
            cd (files_dir);
            files1=dir('sens*.m');
            cd (path);
            addpath(files_dir);
            
            %         Config
            temperature_correction=app.FMTempcorrectionVcc2Switch_2.Value;
            Vp_ref_seno=app.VrefEditField_2;
            order=app.OrderfilesbyButtonGroup.SelectedObject.Text;  %how to order files. Time option doent work if the files are copy or edit
            Ref=app.RefSwitch_2.Value;
            WaveEmit = app.WaveEmitRDropDown_2;
            
            %save options
            info.model=model;
            info.sens_time=sens_time;
            info.Ref=Ref;
            info.Vp_ref_seno=Vp_ref_seno;
            info.x_axis_format=x_axis_format;
            % name_format='Auto';
            info.order=order;
            info.temperature_correction=temperature_correction;
            info.version=mfilename;
            info.date=datetime;
            
            
            %Sort
            Afields = fieldnames(files1);
            Acell = struct2cell(files1);
            sz = size(Acell) ;
            Acell = reshape(Acell, sz(1), []);
            Acell = Acell';
            
            temp_cell=Acell;
            for i=1:size(Acell,1)
                temp_name=split(temp_cell{i,1},'_');
                temp_cell{i,7}=str2double(temp_name(2));
            end
            
            switch order
                case 'name'
                    temp_cell = sortrows(temp_cell, 1);
                case 'date'
                    temp_cell = sortrows(temp_cell, 6);
                case 'campaigne'
                    temp_cell=sortrows(temp_cell, 7);
            end
            
            Acell=temp_cell(:,1:6);
            
            Acell = reshape(Acell', sz);
            files = cell2struct(Acell, Afields, 1);
            
            % Data length
            if strcmp(sens_time,'long')
                data_length=1232;
                data_length_total=1245;
                data_length_HK=82;
            else
                [data_length, data_length_total, data_length_HK] = DataLength_Calculate(app);
            end
            
            
            %Scale and Vp lock in reference signal
            if strcmp(Ref, 'Sine')
                scale = 5^2/(65536);
                
                if strcmp(WaveEmit,'Square')
                    Vref =  4/pi;
                else
                    Vref  = Vp_ref_seno;
                end
                
            else
                scale = (5/65536);
                
                if strcmp(WaveEmit,'Square')
                    Vref =  (pi^2)/(16*1.2337);
                else
                    Vref =   4/pi;
                end
            end
            
            errors=0;
            %% LOOP
            %Read and process all data files
            for count=1:length(files)
                
                run(strcat(files_dir,'\',files(count).name));
                if length(sensors_x)~=data_length_total
                    disp('Data length error. This data file will be ignore');
                    errors=errors+1;
                    continue;
                end
                %             Get timestamp from name
                name_splited=split(files(count).name,'_');
                if length(name_splited)==8
                    name_splited_2=split(name_splited{8},'.');
                    base_date=datetime(string(name_splited{3})+'-'+string(name_splited{4})+'-'+string(name_splited{5})+'-'+string(name_splited{6})+'-'+string(name_splited{7})+'-'+string(name_splited_2{1}),...
                        'InputFormat','dd-MM-yy-HH-mm-ss','Format','dd-MM-yy HH:mm:ss.S');
                else
                    name_splited_2=split(name_splited{7},'.');
                    base_date=datetime(string(name_splited{2})+'-'+string(name_splited{3})+'-'+string(name_splited{4})+'-'+string(name_splited{5})+'-'+string(name_splited{6})+'-'+string(name_splited_2{1}),...
                        'InputFormat','yyyy-MM-dd-HH-mm-ss','Format','dd-MM-yy HH:mm:ss.S');
                    
                end
                %             Generate dates vector for dates plot
                for i=1:data_length
                    date_v(data_length-i+1)=base_date-milliseconds(500)*i;
                end
                
                for i=1:data_length_HK
                    date_v_HK(data_length_HK-i+1)=base_date-milliseconds(7500)*i;
                end
                
                
                %Reformat and scale data
                for j = 1:4
                    for i = 1:data_length
                        if sensors_x(j,i) > 32767
                            mx(j,i)=sensors_x(j,i)-65536;
                        else
                            mx(j,i)=sensors_x(j,i);
                        end
                    end
                    for i = 1:data_length
                        if sensors_y(j,i) > 32767
                            my(j,i)=sensors_y(j,i)-65536;
                        else
                            my(j,i)=sensors_y(j,i);
                        end
                    end
                    mx(j,:)=scale*mx(j,:);
                    my(j,:)=scale*my(j,:);
                    
                end
                
                %Compute Vp
                R(1,:)=sqrt(mx(1,:).^2+my(1,:).^2);
                R(2,:)=sqrt(mx(2,:).^2+my(2,:).^2);
                R(3,:)=sqrt(mx(3,:).^2+my(3,:).^2);
                R(4,:)=sqrt(mx(4,:).^2+my(4,:).^2);
                Vp(1,:)=2*R(1,:)/Vref;
                Vp(2,:)=2*R(2,:)/Vref;
                Vp(3,:)=2*R(3,:)/Vref;
                Vp(4,:)=2*R(4,:)/Vref;
                
                %compute phase
                Phase(1,:)= atand(my(1,:)./mx(1,:));
                Phase(2,:)= atand(my(2,:)./mx(2,:));
                Phase(3,:)= atand(my(3,:)./mx(3,:));
                Phase(4,:)= atand(my(4,:)./mx(4,:));
                
                
                %compute phase x y filtered
                Phase_filt(1,:)= atand(median(my(1,:))/median(mx(1,:)));
                Phase_filt(2,:)= atand(median(my(2,:))/median(mx(2,:)));
                Phase_filt(3,:)= atand(median(my(3,:))/median(mx(3,:)));
                Phase_filt(4,:)= atand(median(my(4,:))/median(mx(4,:)));
                
                %Compute Vp x y filtered
                R_filt(1,:)=sqrt(median(mx(1,:))^2+median(my(1,:))^2);
                R_filt(2,:)=sqrt(median(mx(2,:))^2+median(my(2,:))^2);
                R_filt(3,:)=sqrt(median(mx(3,:))^2+median(my(3,:))^2);
                R_filt(4,:)=sqrt(median(mx(4,:))^2+median(my(4,:))^2);
                Vp_filt(1,:)=2*R_filt(1,:)/Vref;
                Vp_filt(2,:)=2*R_filt(2,:)/Vref;
                Vp_filt(3,:)=2*R_filt(3,:)/Vref;
                Vp_filt(4,:)=2*R_filt(4,:)/Vref;
                
                
                %Save data in a vector
                if strcmp(model,'EM')|| strcmp(model,'FM')||strcmp(model,'FS') %check model
                    
                    if strcmp(model,'FM')
                        if strcmp(temperature_correction,'on')
                            t1=(house_keep(1,1:end-1)*5/2^16-(3.103+1.217*(house_keep(2,1:end-1)*5/2^16-2.55)))./(25.01e-3+9.81e-3*(house_keep(2,1:end-1)*5/2^16-2.55));
                            t2=(house_keep(3,1:end-1)*5/2^16-(2.531+0.993*(house_keep(2,1:end-1)*5/2^16-2.55)))./(20.229e-3+7.933e-3*(house_keep(2,1:end-1)*5/2^16-2.55));
                        else
                            t1=(house_keep(1,1:end-1)*5/2^16-3.024)/0.02452; %FM
                            t2=(house_keep(3,1:end-1)*5/2^16-2.481)/0.01983;
                        end
                    elseif strcmp(model,'EM')
                        t1=(house_keep(1,1:end-1)*5/2^16-2.988)*41.437;
                        t2=(house_keep(3,1:end-1)*5/2^16-2.988)*41.437;
                    else %FS
                        % t1=(house_keep(1,1:end-1)*5/2^16-(2.965+1.16*(house_keep(2,1:end-1)*5/2^16-2.55)))./(20.314e-3+7.967e-3*(house_keep(2,1:end-1)*5/2^16-2.55));
                        %FM3
                        t1=(house_keep(1,1:end-1)*5/2^16-(3.108+1.219*(house_keep(2,1:end-1)*5/2^16-2.55)))./(20.939e-3+7.527e-3*(house_keep(2,1:end-1)*5/2^16-2.55)); %Fwd
                        t2=(house_keep(3,1:end-1)*5/2^16-(2.965+1.16*(house_keep(2,1:end-1)*5/2^16-2.55)))./(20.314e-3+7.967e-3*(house_keep(2,1:end-1)*5/2^16-2.55));
                        
                    end
                    t1_mean=mean(t1);
                    t2_mean=mean(t2);
                    v_house_keep=house_keep(2,1:end-1)*5/2^16;
                    
                    if ~exist('v_house_keep_t','var')
                        v_house_keep_t=v_house_keep;
                        Vp_t=Vp;
                        Vp_filt_t=Vp_filt;
                        Phase_filt_t=Phase_filt;
                        X_t=mx;
                        Y_t=my;
                        Phase_t=Phase;
                        t_1_v=t1;
                        t_2_v=t2;
                        t_1_mean_t=t1;
                        t_2_mean_t=t2;
                        date_t=date_v;
                        date_t_HK=date_v_HK;
                        for i=1:82
                            t_1_t(1+15*(i-1):15*i)=t1(i);
                            t_2_t(1+15*(i-1):15*i)=t2(i);
                        end
                        t_1_t(1231:1232)=t1(82);
                        t_2_t(1231:1232)=t2(82);
                    else
                        v_house_keep_t=horzcat(v_house_keep_t,v_house_keep);
                        Vp_t=horzcat(Vp_t,Vp);
                        X_t=horzcat(X_t,mx);
                        Y_t=horzcat(Y_t,my);
                        Phase_t=horzcat(Phase_t,Phase);
                        Vp_filt_t=horzcat(Vp_filt_t,Vp_filt);
                        Phase_filt_t=horzcat(Phase_filt_t,Phase_filt);
                        for i=1:82
                            t_1_temp(1+15*(i-1):15*i)=t1(i);
                            t_2_temp(1+15*(i-1):15*i)=t2(i);
                        end
                        date_t=horzcat(date_t,date_v);
                        date_t_HK=horzcat(date_t_HK,date_v_HK);
                        t_1_temp(1231:1232)=t1(82);
                        t_2_temp(1231:1232)=t2(82);
                        t_1_t=horzcat(t_1_t ,t_1_temp);
                        t_2_t=horzcat(t_2_t ,t_2_temp);
                        t_1_v=horzcat(t_1_v ,t1);
                        t_2_v=horzcat(t_2_v ,t2);
                        t_1_mean_t=horzcat(t_1_mean_t ,t1_mean);
                        t_2_mean_t=horzcat(t_2_mean_t ,t2_mean);
                        
                    end
                else %model CM1 or CM2
                    
                    if strcmpi(model,'CM1')
                        t1=(house_keep(2,1:end-1)*5/2^16-1.072)/0.02882;
                        t2=(house_keep(3,1:end-1)*5/2^16-1.072)/0.02882;
                        v_house_keep=house_keep(1,1:end-1)*5/2^16;
                    else
                        if strcmpi(model,'CM2')
                            t1=(house_keep(1,1:end-1)*5/2^16-1.072)/0.02882;
                            t2=(house_keep(3,1:end-1)*5/2^16-1.072)/0.02882;
                            v_house_keep=house_keep(1,1:end-1)*5/2^16;
                            
                        else %CM3
                            %                     t1=(house_keep(1,1:end-1)*5/2^16-2.915)/0.019905;
                            %                     t2=(house_keep(3,1:end-1)*5/2^16-2.915)/0.019905;
                            
                            v_house_keep=house_keep(2,1:end-1)*5/2^16;
                            
                            house_keep(1,1:end-1)
                            house_keep(3,1:end-1)
                            
                            Vo_t1(:) = house_keep(1,1:end-1)*5/2^16;
                            Vo_t2(:) = house_keep(3,1:end-1)*5/2^16;
                            
                            
                            if strcmp(app.FMTempcorrectionVcc2Switch.Value,'On')
                                
                                %Cambios 24/02/21
                                Vcc_2(:) = house_keep(2,1:end-1)*5/2^16;
                                m(:) = (19.905/1000) + (7.960/1000) *(Vcc_2- 2.5);
                                b(:) = 2.915 + 1.165 * (Vcc_2- 2.5) ;
                                
                                t1 = (Vo_t1-b)./m;
                                t2 = (Vo_t2-b)./m;
                                
                            else
                                
                                %Asumimos Vcc/2 = 2.5 V;
                                m = 19.905/1000;
                                b = 2.915;
                                
                                t1 = (Vo_t1-b)./m;
                                t2 = (Vo_t2-b)./m;
                                
                            end
                        end
                        
                    end
                    
                    
                    t3=(house_keep(4,1:end-1)*5/2^16-1.072)/0.02882;
                    t1_mean=mean(t1);
                    t2_mean=mean(t2);
                    t3_mean=mean(t3);
                    
                    if ~exist('v_house_keep_t','var')
                        
                        
                        disp('Aqui')
                        
                        v_house_keep_t=v_house_keep;
                        Vp_t=Vp;
                        Phase_t=Phase;
                        Vp_filt_t=Vp_filt;
                        Phase_filt_t=Phase_filt;
                        t_1_v=t1;
                        t_2_v=t2;
                        t_3_v=t3;
                        t_1_mean_t=t1_mean;
                        t_2_mean_t=t2_mean;
                        t_3_mean_t=t3_mean;
                        date_t=date_v;
                        date_t_HK=date_v_HK;
                        X_t=mx;
                        Y_t=my;
                        
                        for i=1:data_length_HK
                            
                            t_1_t(1+15*(i-1):15*i)=t1(i);
                            t_2_t(1+15*(i-1):15*i)=t2(i);
                            t_3_t(1+15*(i-1):15*i)=t3(i);
                        end
                        
                        t_1_t(data_length_HK*15+1:data_length)=t1(data_length_HK);
                        t_2_t(data_length_HK*15+1:data_length)=t2(data_length_HK);
                        t_3_t(data_length_HK*15+1:data_length)=t3(data_length_HK);
                        
                        
                        t_1_v;
                        t_1_t;
                        
                        t_3_v;
                        t_3_t;
                        
                    else
                        disp('Aquí_2');
                        v_house_keep_t=horzcat(v_house_keep_t,v_house_keep);
                        Vp_t=horzcat(Vp_t,Vp);
                        Phase_t=horzcat(Phase_t,Phase);
                        Vp_filt_t=horzcat(Vp_filt_t,Vp_filt);
                        Phase_filt_t=horzcat(Phase_filt_t,Phase_filt);
                        X_t=horzcat(X_t,mx);
                        Y_t=horzcat(Y_t,my);
                        for i=1:data_length_HK
                            t_1_temp(1+15*(i-1):15*i)=t1(i);
                            t_2_temp(1+15*(i-1):15*i)=t2(i);
                            t_3_temp(1+15*(i-1):15*i)=t3(i);
                        end
                        t_1_temp(data_length_HK*15+1:data_length)=t1(data_length_HK);
                        t_2_temp(data_length_HK*15+1:data_length)=t2(data_length_HK);
                        t_3_temp(data_length_HK*15+1:data_length)=t3(data_length_HK);
                        date_t=horzcat(date_t,date_v);
                        date_t_HK=horzcat(date_t_HK,date_v_HK);
                        t_1_t=horzcat(t_1_t ,t_1_temp);
                        t_2_t=horzcat(t_2_t ,t_2_temp);
                        t_3_t=horzcat(t_3_t ,t_3_temp);
                        t_1_v=horzcat(t_1_v ,t1);
                        t_2_v=horzcat(t_2_v ,t2);
                        t_3_v=horzcat(t_3_v ,t3);
                        t_1_mean_t=horzcat(t_1_mean_t ,t1_mean);
                        t_2_mean_t=horzcat(t_2_mean_t ,t2_mean);
                        t_3_mean_t=horzcat(t_3_mean_t ,t3_mean);
                    end
                    
                    
                end
                
                %Clear variables before reusing
                clear 'sensors_x' 'sensors_y' 't1' 't2' 't3' 't_1_temp' 't_2_temp' 't_3_temp'
                
            end
            
            %% %%%%%%%%%%%%%% Ratios %%%%%%%%%%%%%%%%%%%%%%%%%
            
            if strcmp(model,'EM')||strcmp(model,'FM') ||strcmp(model,'FS')
                Ratio_PbSe=Vp_t(1,:)./Vp_t(3,:);
                Ratio_PbS=Vp_t(2,:)./Vp_t(4,:);
            else
                Ratio_PbS=Vp_t(1,:)./Vp_t(3,:);
                Ratio_PbSe=Vp_t(2,:)./Vp_t(4,:);
            end
            
            
            %% %%%%%%%%%%% Vertical line generation %%%%%%%%%%%%%
            
            %Genation lines ratio PbS
            line_ratio_PbS=round(min(Ratio_PbS),2):0.01:round(max(Ratio_PbS),2);
            line_ratio_PbS_n_points=length(line_ratio_PbS);
            for loop=1:(length(files)-errors)
                line_ratio_PbS_pos(1+(loop-1)*line_ratio_PbS_n_points:loop*line_ratio_PbS_n_points)=data_length*loop;
            end
            
            %Genation lines ratio PbSe
            line_ratio_PbSe=round(min(Ratio_PbSe),2):0.01:round(max(Ratio_PbSe(Ratio_PbSe~=Inf)),2);
            line_ratio_PbSe_n_points=length(line_ratio_PbSe);
            for loop=1:(length(files)-errors)
                line_ratio_PbSe_pos(1+(loop-1)*line_ratio_PbSe_n_points:loop*line_ratio_PbSe_n_points)=data_length*loop;
            end
            
            %Genation lines temperature
            if exist('t_3_t','var')
                line_temp=round(min([min(t_1_t) min(t_2_t) min(t_3_t)]),2):0.01:round(max([min(t_1_t) max(t_2_t) max(t_3_t)]),2);
            else
                line_temp=round(min([min(t_1_t) min(t_2_t)]),2):0.01:round(max([min(t_1_t) max(t_2_t)]),2);
            end
            % line_temp=round(min(t_1_t),2):0.01:round(max(t_1_t),2);
            line_temp_n_points=length(line_temp);
            for loop=1:(length(files)-errors)
                line_temp_pos(1+(loop-1)*line_temp_n_points:loop*line_temp_n_points)=data_length*loop;
            end
            
            %Genation lines detector 1
            line_det1=0:0.01:round(max(Vp_t(1,:)),2);
            line_detec1_n_points=length(line_det1);
            for loop=1:(length(files)-errors)
                line_det1_pos(1+(loop-1)*line_detec1_n_points:loop*line_detec1_n_points)=data_length*loop;
            end
            
            %Genation lines detector 2
            line_det2=0:0.01:round(max(Vp_t(2,:)),2);
            line_detec2_n_points=length(line_det2);
            for loop=1:(length(files)-errors)
                line_det2_pos(1+(loop-1)*line_detec2_n_points:loop*line_detec2_n_points)=data_length*loop;
            end
            
            %Genation lines detector 3
            line_det3=0:0.01:round(max(Vp_t(3,:)),2);
            line_detec3_n_points=length(line_det3);
            for loop=1:(length(files)-errors)
                line_det3_pos(1+(loop-1)*line_detec3_n_points:loop*line_detec3_n_points)=data_length*loop;
            end
            
            %Genation lines detector 4
            line_det4=0:0.01:round(max(Vp_t(4,:)),2);
            line_detec4_n_points=length(line_det4);
            for loop=1:(length(files)-errors)
                line_det4_pos(1+(loop-1)*line_detec4_n_points:loop*line_detec4_n_points)=data_length*loop;
            end
            
            %Genation lines voltages house keepeing
            line_voltage_HK=round(min(v_house_keep_t),2):0.05:round(max(v_house_keep_t),2);
            line_voltage_HK_n_points=length(line_voltage_HK);
            for loop=1:(length(files)-errors)
                line_voltage_HK_pos(1+(loop-1)*line_voltage_HK_n_points:loop*line_voltage_HK_n_points)=data_length_HK*loop;
            end
            
            %Genation lines temperature for house keeping
            if exist('t_3_t','var')
                line_temp_HK=round(min([min(t_1_t) min(t_2_t) min(t_3_t)]),2):0.01:round(max([min(t_1_t) max(t_2_t) max(t_3_t)]),2);
            else
                line_temp_HK=round(min([min(t_1_t) min(t_2_t)]),2):0.01:round(max([min(t_1_t) max(t_2_t)]),2);
            end
            % line_temp_HK=round(min(t_1_t),2):0.05:round(max(t_1_t),2);
            line_temp_HK_n_points=length(line_temp_HK);
            for loop=1:(length(files)-errors)
                line_temp_HK_pos(1+(loop-1)*line_temp_HK_n_points:loop*line_temp_HK_n_points)=data_length_HK*loop;
            end
            
            %Genation lines phase dect 1
            line_phase_1=round(min(Phase_t(1,:)),2):0.5:round(max(Phase_t(1,:)),2);
            line_phase_n_points_1=length(line_phase_1);
            for loop=1:(length(files)-errors)
                line_phase_pos_1(1+(loop-1)*line_phase_n_points_1:loop*line_phase_n_points_1)=data_length*loop;
            end
            
            %Genation lines phase dect 2
            line_phase_2=round(min(Phase_t(2,:)),2):0.5:round(max(Phase_t(2,:)),2);
            line_phase_n_points_2=length(line_phase_2);
            for loop=1:(length(files)-errors)
                line_phase_pos_2(1+(loop-1)*line_phase_n_points_2:loop*line_phase_n_points_2)=data_length*loop;
            end
            
            %Genation lines phase dect 3
            line_phase_3=round(min(Phase_t(3,:)),2):0.5:round(max(Phase_t(3,:)),2);
            line_phase_n_points_3=length(line_phase_3);
            for loop=1:(length(files)-errors)
                line_phase_pos_3(1+(loop-1)*line_phase_n_points_3:loop*line_phase_n_points_3)=data_length*loop;
            end
            
            %Genation lines phase dect 4
            line_phase_4=round(min(Phase_t(4,:)),2):0.5:round(max(Phase_t(4,:)),2);
            line_phase_n_points_4=length(line_phase_4);
            for loop=1:(length(files)-errors)
                line_phase_pos_4(1+(loop-1)*line_phase_n_points_4:loop*line_phase_n_points_4)=data_length*loop;
            end


            clear subplot_instance


            %% %%%%%%%%%%%%%%%%%%%%% Detector 1 figure %%%%%%%%%%%%%%%%%%%%%
            
            
            fig(1)=figure;
            
            subplot_instance(1)=subplot(3,1,1);
            hold on
            
            if strcmp(x_axis_format,'Dates')
                plot(date_t,Vp_t(1,:),'b');
            else
                plot(Vp_t(1,:),'b');
                for loop=1:(length(files)-errors)
                    plot(line_det1_pos(1+(loop-1)*line_detec1_n_points:loop*line_detec1_n_points),line_det1,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end
            if strcmp(model,'EM')||strcmp(model,'FM')||strcmp(model,'FS')
                title ('Detect1 PbSe Fwd','FontWeight','bold');
                info.PbSe_Fwd='Vp_t(1,:)';
            else
                title ('Detec1 PbS Fwd','FontWeight','bold');
                info.PbS_Fwd='Vp_t(1,:)';
            end
            ylabel('Volts','FontWeight','bold');
            xlabel('Samples','FontWeight','bold');
            grid on
            
            subplot_instance(2)=subplot(3,1,2);
            title ('Temperature','FontWeight','bold');
            ylabel('ºC','FontWeight','bold');
            xlabel('Samples','FontWeight','bold');
            grid on
            hold on
            
            
            if strcmp(x_axis_format,'Dates')
                h(1)=plot(date_t,t_1_t,'b');
                h(2)=plot(date_t,t_2_t,'k');
            else
                h(1)=plot(t_1_t,'b');
                h(2)=plot(t_2_t,'k');
                for loop=1:(length(files)-errors)
                    plot(line_temp_pos(1+(loop-1)*line_temp_n_points:loop*line_temp_n_points),line_temp,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end
            if contains(model,'CM')
                if strcmp(x_axis_format,'Dates')
                    h(3)=plot(date_t,t_3_t,'r');
                else
                    h(3)=plot(t_3_t,'r');
                end
                legend(h,'Detectors Fwd','Detectors Bwd','Board');
            else
                if strcmp(model,'FM') ||strcmp(model,'FS')
                    legend(h,'Detectors Fwd','Board');
                else
                    legend(h,'Detectors Fwd','Detectors Bwd');
                end
            end
            
            
            subplot_instance(3)=subplot(3,1,3);
            title ('Phase','FontWeight','bold');
            ylabel('º','FontWeight','bold');
            
            grid on
            hold on
            
            
            if strcmp(x_axis_format,'Dates')
                plot(date_t,Phase_t(1,:),'b');
                xlabel('Date','FontWeight','bold');
            else
                plot(Phase_t(1,:),'b');
                xlabel('Samples','FontWeight','bold');
                for loop=1:(length(files)-errors)
                    plot(subplot_instance(3),line_phase_pos_1(1+(loop-1)*line_phase_n_points_1:loop*line_phase_n_points_1),line_phase_1,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end


            linkaxes(subplot_instance, 'x');
            
            
            %% %%%%%%%%%%%%%%%%%%%% Detector 2 figure %%%%%%%%%%%%%%%%%%%%%
            
            
            fig(2)=figure;
            
            subplot_instance(1)=subplot(3,1,1);
            hold on
            
            if strcmp(x_axis_format,'Dates')
                plot(date_t,Vp_t(2,:),'b');
            else
                plot(Vp_t(2,:),'b');
                for loop=1:(length(files)-errors)
                    plot(line_det2_pos(1+(loop-1)*line_detec2_n_points:loop*line_detec2_n_points),line_det2,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end
            if strcmp(model,'EM')||strcmp(model,'FM') ||strcmp(model,'FS')
                title ('Detect2 PbS Fwd','FontWeight','bold');
                info.PbS_Fwd='Vp_t(2,:)';
            else
                title ('Detect2 PbSe Fwd','FontWeight','bold');
                info.PbSe_Fwd='Vp_t(2,:)';
            end
            ylabel('Volts','FontWeight','bold');
            xlabel('Samples','FontWeight','bold');
            grid on
            
            subplot_instance(2)=subplot(3,1,2);
            title ('Temperature','FontWeight','bold');
            ylabel('ºC','FontWeight','bold');
            xlabel('Samples','FontWeight','bold');
            grid on
            hold on
            
            
            if strcmp(x_axis_format,'Dates')
                h(1)=plot(date_t,t_1_t,'b');
                h(2)=plot(date_t,t_2_t,'k');
            else
                h(1)=plot(t_1_t,'b');
                h(2)=plot(t_2_t,'k');
                for loop=1:(length(files)-errors)
                    plot(line_temp_pos(1+(loop-1)*line_temp_n_points:loop*line_temp_n_points),line_temp,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end
            if contains(model,'CM')
                if strcmp(x_axis_format,'Dates')
                    h(3)=plot(date_t,t_3_t,'r');
                else
                    h(3)=plot(t_3_t,'r');
                end
                legend(h,'Detectors Fwd','Detectors Bwd','Board');
            else
                if strcmp(model,'FM') ||strcmp(model,'FS')
                    legend(h,'Detectors Fwd','Board');
                else
                    legend(h,'Detectors Fwd','Detectors Bwd');
                end
            end
            
            
            subplot_instance(3)=subplot(3,1,3);
            title ('Phase','FontWeight','bold');
            ylabel('º','FontWeight','bold');
            
            grid on
            hold on
            
            
            if strcmp(x_axis_format,'Dates')
                plot(date_t,Phase_t(2,:),'b');
                xlabel('Date','FontWeight','bold');
            else
                plot(Phase_t(2,:),'b');
                xlabel('Samples','FontWeight','bold');
                for loop=1:(length(files)-errors)
                    plot(subplot_instance(3),line_phase_pos_2(1+(loop-1)*line_phase_n_points_2:loop*line_phase_n_points_2),line_phase_2,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end


            linkaxes(subplot_instance, 'x');


            %% %%%%%%%%%%%%%%%% Detector 3 figure %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            fig(3)=figure;
            
            subplot_instance(1)=subplot(3,1,1);
            hold on
            
            if strcmp(x_axis_format,'Dates')
                plot(date_t,Vp_t(3,:),'b');
            else
                plot(Vp_t(3,:),'b');
                for loop=1:(length(files)-errors)
                    plot(line_det3_pos(1+(loop-1)*line_detec3_n_points:loop*line_detec3_n_points),line_det3,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end
            if strcmp(model,'EM')||strcmp(model,'FM') ||strcmp(model,'FS')
                title ('Detect3 PbSe Bwd','FontWeight','bold');
                info.PbSe_Bwd='Vp_t(3,:)';
            else
                title ('Detect3 PbS Bwd','FontWeight','bold');
                info.PbS_Bwd='Vp_t(3,:)';
            end
            ylabel('Volts','FontWeight','bold');
            xlabel('Samples','FontWeight','bold');
            grid on
            
            subplot_instance(2)=subplot(3,1,2);
            title ('Temperature','FontWeight','bold');
            ylabel('ºC','FontWeight','bold');
            xlabel('Samples','FontWeight','bold');
            grid on
            hold on
            
            
            if strcmp(x_axis_format,'Dates')
                h(1)=plot(date_t,t_1_t,'b');
                h(2)=plot(date_t,t_2_t,'k');
            else
                h(1)=plot(t_1_t,'b');
                h(2)=plot(t_2_t,'k');
                for loop=1:(length(files)-errors)
                    plot(line_temp_pos(1+(loop-1)*line_temp_n_points:loop*line_temp_n_points),line_temp,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end
            if contains(model,'CM')
                if strcmp(x_axis_format,'Dates')
                    h(3)=plot(date_t,t_3_t,'r');
                else
                    h(3)=plot(t_3_t,'r');
                end
                legend(h,'Detectors Fwd','Detectors Bwd','Board');
            else
                if strcmp(model,'FM') ||strcmp(model,'FS')
                    legend(h,'Detectors Fwd','Board');
                else
                    legend(h,'Detectors Fwd','Detectors Bwd');
                end
            end
            
            subplot_instance(3)=subplot(3,1,3);
            title ('Phase','FontWeight','bold');
            ylabel('º','FontWeight','bold');
            
            grid on
            hold on
            
            
            if strcmp(x_axis_format,'Dates')
                plot(date_t,Phase_t(3,:),'b');
                xlabel('Date','FontWeight','bold');
            else
                plot(Phase_t(3,:),'b');
                xlabel('Samples','FontWeight','bold');
                for loop=1:(length(files)-errors)
                    plot(subplot_instance(3),line_phase_pos_3(1+(loop-1)*line_phase_n_points_3:loop*line_phase_n_points_3),line_phase_3,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end
            
            linkaxes(subplot_instance, 'x');


            %% %%%%%%%%%%%%%%%%%%%%% Detector 4 figure %%%%%%%%%%%%%%%%%%%%%%%%
            
            fig(4)=figure;
            
            subplot_instance(1)=subplot(3,1,1);
            hold on
            
            if strcmp(x_axis_format,'Dates')
                plot(date_t,Vp_t(4,:),'b');
            else
                plot(Vp_t(4,:),'b');
                for loop=1:(length(files)-errors)
                    plot(line_det4_pos(1+(loop-1)*line_detec4_n_points:loop*line_detec4_n_points),line_det4,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end
            if strcmp(model,'EM')||strcmp(model,'FM') ||strcmp(model,'FS')
                title ('Detect4 PbS Bwd','FontWeight','bold');
                info.PbS_Bwd='Vp_t(4,:)';
            else
                title ('Detect4 PbSe Bwd','FontWeight','bold');
                info.PbSe_Bwd='Vp_t(4,:)';
            end
            ylabel('Volts','FontWeight','bold');
            xlabel('Samples','FontWeight','bold');
            grid on
            
            subplot_instance(2)=subplot(3,1,2);
            title ('Temperature','FontWeight','bold');
            ylabel('ºC','FontWeight','bold');
            xlabel('Samples','FontWeight','bold');
            grid on
            hold on
            
            
            if strcmp(x_axis_format,'Dates')
                h(1)=plot(date_t,t_1_t,'b');
                h(2)=plot(date_t,t_2_t,'k');
            else
                h(1)=plot(t_1_t,'b');
                h(2)=plot(t_2_t,'k');
                for loop=1:(length(files)-errors)
                    plot(line_temp_pos(1+(loop-1)*line_temp_n_points:loop*line_temp_n_points),line_temp,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end
            if contains(model,'CM') ||strcmp(model,'FS')
                if strcmp(x_axis_format,'Dates')
                    h(3)=plot(date_t,t_3_t,'r');
                else
                    h(3)=plot(t_3_t,'r');
                end
                
                legend(h,'Detectors Fwd','Detectors Bwd','Board');
                info.temp_Fwd='t_1_t';
                info.temp_Bwd='t_2_t';
                info.temp_Board='t_3_t';
            else
                if strcmp(model,'FM')
                    legend(h,'Detectors Fwd','Board');
                    info.temp_Fwd='t_1_t';
                    info.temp_Board='t_2_t';
                else
                    legend(h,'Detectors Fwd','Detectors Bwd');
                    info.temp_Fwd='t_1_t';
                    info.temp_Bwd='t_2_t';
                    
                end
            end
            
            subplot_instance(3)=subplot(3,1,3);
            title ('Phase','FontWeight','bold');
            ylabel('º','FontWeight','bold');
            
            grid on
            hold on
            
            
            if strcmp(x_axis_format,'Dates')
                plot(date_t,Phase_t(4,:),'b');
                xlabel('Date','FontWeight','bold');
            else
                plot(Phase_t(4,:),'b');
                xlabel('Samples','FontWeight','bold');
                for loop=1:(length(files)-errors)
                    plot(subplot_instance(3),line_phase_pos_4(1+(loop-1)*line_phase_n_points_4:loop*line_phase_n_points_4),line_phase_4,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end
            linkaxes(subplot_instance, 'x');
            
            
            clear subplot_instance
            
            
            %% %%%%%%%%%%%%%% House keeping Voltage figure %%%%%%%%%%%%%%%%%%%%
            
            fig(5)=figure;
            
            subplot_instance(1)=subplot(2,1,1);
            
            title ('Voltage Vcc/2','FontWeight','bold');
            ylabel('Volts','FontWeight','bold');
            xlabel('Samples','FontWeight','bold');
            grid on
            hold on
            
            if strcmp(x_axis_format,'Dates')
                plot(date_t_HK,v_house_keep_t,'b');
            else
                plot(v_house_keep_t,'b');
                for loop=1:(length(files)-errors)
                    plot(line_voltage_HK_pos(1+(loop-1)*line_voltage_HK_n_points:loop*line_voltage_HK_n_points),line_voltage_HK,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end
            
            subplot_instance(2)=subplot(2,1,2);
            title ('Temperature')
            hold on
            title ('Temperature','FontWeight','bold');
            ylabel('ºC','FontWeight','bold');
            xlabel('Samples','FontWeight','bold');
            grid on
            
            
            if strcmp(x_axis_format,'Dates')
                h(1)=plot(date_t_HK,t_1_v,'b');
                h(2)=plot(date_t_HK,t_2_v,'k');
            else
                h(1)=plot(t_1_v,'b');
                h(2)=plot(t_2_v,'k');
                for loop=1:(length(files)-errors)
                    plot(line_temp_HK_pos(1+(loop-1)*line_temp_HK_n_points:loop*line_temp_HK_n_points),line_temp_HK,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end
            if contains(model,'CM')
                if strcmp(x_axis_format,'Dates')
                    h(3)=plot(date_t_HK,t_3_v,'r');
                else
                    h(3)=plot(t_3_v,'r');
                end
                legend(h,'Detectors Fwd','Detectors Bwd','Board');
            else
                if strcmp(model,'FM') ||strcmp(model,'FS')
                    legend(h,'Detectors Fwd','Board');
                else
                    legend(h,'Detectors Fwd','Detectors Bwd');
                end
            end
            linkaxes(subplot_instance, 'x');
            
            
            %% %%%%%%%%%%%%%%%%% Ratio PbS figure %%%%%%%%%%%%%%%%%%%%%%%
            
            fig(6)=figure;
            
            subplot_instance(1)=subplot(2,1,1);
            hold on
            
            if strcmp(x_axis_format,'Dates')
                plot(date_t,Ratio_PbS,'b');
                xlabel('Date','FontWeight','bold');
            else
                plot(Ratio_PbS,'b');
                for loop=1:(length(files)-errors)
                    plot(line_ratio_PbS_pos(1+(loop-1)*line_ratio_PbS_n_points:loop*line_ratio_PbS_n_points),line_ratio_PbS,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
                xlabel('Samples','FontWeight','bold');
            end
            
            title ('Ratio PbS','FontWeight','bold');
            
            
            grid on
            
            subplot_instance(2)=subplot(2,1,2);
            title ('Temperature','FontWeight','bold');
            ylabel('ºC','FontWeight','bold');
            xlabel('Samples','FontWeight','bold');
            grid on
            hold on
            
            
            if strcmp(x_axis_format,'Dates')
                h(1)=plot(date_t,t_1_t,'b');
                h(2)=plot(date_t,t_2_t,'k');
            else
                h(1)=plot(t_1_t,'b');
                h(2)=plot(t_2_t,'k');
                for loop=1:(length(files)-errors)
                    plot(line_temp_pos(1+(loop-1)*line_temp_n_points:loop*line_temp_n_points),line_temp,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end
            if contains(model,'CM')
                if strcmp(x_axis_format,'Dates')
                    h(3)=plot(date_t,t_3_t,'r');
                else
                    h(3)=plot(t_3_t,'r');
                end
                legend(h,'Detectors Fwd','Detectors Bwd','Board');
            else
                if strcmp(model,'FM') ||strcmp(model,'FS')
                    legend(h,'Detectors Fwd','Board');
                else
                    legend(h,'Detectors Fwd','Detectors Bwd');
                end
            end
            
            linkaxes(subplot_instance, 'x');


            %% %%%%%%%%%%%%%%%%% Ratio PbSe figure %%%%%%%%%%%%%%%%%%%%%%%
            
            fig(7)=figure;
            
            subplot_instance(1)=subplot(2,1,1);
            hold on
            
            if strcmp(x_axis_format,'Dates')
                plot(date_t,Ratio_PbSe,'b');
                xlabel('Date','FontWeight','bold');
            else
                plot(Ratio_PbSe,'b');
                for loop=1:(length(files)-errors)
                    plot(line_ratio_PbSe_pos(1+(loop-1)*line_ratio_PbSe_n_points:loop*line_ratio_PbSe_n_points),line_ratio_PbSe,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
                xlabel('Samples','FontWeight','bold');
            end
            
            title ('Ratio PbSe','FontWeight','bold');
            
            
            grid on
            
            subplot_instance(2)=subplot(2,1,2);
            title ('Temperature','FontWeight','bold');
            ylabel('ºC','FontWeight','bold');
            xlabel('Samples','FontWeight','bold');
            grid on
            hold on
            
            
            if strcmp(x_axis_format,'Dates')
                h(1)=plot(date_t,t_1_t,'b');
                h(2)=plot(date_t,t_2_t,'k');
            else
                h(1)=plot(t_1_t,'b');
                h(2)=plot(t_2_t,'k');
                for loop=1:(length(files)-errors)
                    plot(line_temp_pos(1+(loop-1)*line_temp_n_points:loop*line_temp_n_points),line_temp,'Color',[1,0.7,0.7],'LineStyle','-','LineWidth',0.1);
                end
            end
            if contains(model,'CM')
                if strcmp(x_axis_format,'Dates')
                    h(3)=plot(date_t,t_3_t,'r');
                else
                    h(3)=plot(t_3_t,'r');
                end
                legend(h,'Detectors Fwd','Detectors Bwd','Board');
            else
                if strcmp(model,'FM') ||strcmp(model,'FS')
                    legend(h,'Detectors Fwd','Board');
                else
                    legend(h,'Detectors Fwd','Detectors Bwd');
                end
            end
            
            linkaxes(subplot_instance, 'x');


            %% %%%%%%%%%%%%%%% Save data and figures %%%%%%%%%%%%%%%%%%%%%%%%
            C = strsplit(files_dir,'\');
            if strcmp(x_axis_format, 'Dates')
                savefig(fig,strcat(files_dir,'\',C{end},'_dates','.fig'));
                saveas(fig(6),strcat(files_dir,'\','Ratio_PbS_dates','.png'));
                saveas(fig(7),strcat(files_dir,'\','Ratio_PbSe_Fwd_dates','.png'));
                if strcmp(model,'EM')|| strcmp(model,'FM')||strcmp(model,'FS')
                    saveas(fig(1),strcat(files_dir,'\','Detect1_PbSe_Fwd_dates','.png'));
                    saveas(fig(2),strcat(files_dir,'\','Detect2_PbS_Fwd_dates','.png'));
                    saveas(fig(3),strcat(files_dir,'\','Detect3_PbSe_Bwd_dates','.png'));
                    saveas(fig(4),strcat(files_dir,'\','Detect4_PbS_Bwd_dates','.png'));
                    saveas(fig(5),strcat(files_dir,'\','voltage_house_keeping','.png'));
                    
                    %         saveas(fig(6),strcat(files_dir,'\','Detect1_PbSe_Fwd_Phase_dates','.png'));
                    %         saveas(fig(7),strcat(files_dir,'\','Detect2_PbS_Fwd_Phase_dates','.png'));
                    %         saveas(fig(8),strcat(files_dir,'\','Detect3_PbSe_Bwd_Phase_dates','.png'));
                    %         saveas(fig(9),strcat(files_dir,'\','Detect4_PbS_Bwd_Phase_dates','.png'));
                else
                    saveas(fig(1),strcat(files_dir,'\','Detec1_PbS_Fwd_dates','.png'));
                    saveas(fig(2),strcat(files_dir,'\','Detect2_PbSe_Fwd_dates','.png'));
                    saveas(fig(3),strcat(files_dir,'\','Detect3_PbS_Bwd_dates','.png'));
                    saveas(fig(4),strcat(files_dir,'\','Detect4_PbSe_Bwd_dates','.png'));
                    saveas(fig(5),strcat(files_dir,'\','voltage_house_keeping','.png'));
                    
                    %         saveas(fig(6),strcat(files_dir,'\','Detec1_PbS_Fwd_Phase_dates','.png'));
                    %         saveas(fig(7),strcat(files_dir,'\','Detect2_PbSe_Fwd_Phase_dates','.png'));
                    %         saveas(fig(8),strcat(files_dir,'\','Detect3_PbS_Bwd_Phase_dates','.png'));
                    %         saveas(fig(9),strcat(files_dir,'\','Detect4_PbSe_Bwd_Phase_dates','.png'))
                end
                
                if exist('t_3_t','var')
                    save(strcat(files_dir,'\',C{end},'.mat'),'t_1_t','t_2_t','v_house_keep_t','Vp_t','t_3_t','t_1_v','t_2_v','t_3_v','date_t','date_t_HK','Phase_t','Ratio_PbS','Ratio_PbSe','X_t','Y_t');
                else
                    save(strcat(files_dir,'\',C{end},'.mat'),'t_1_t','t_2_t','v_house_keep_t','Vp_t','t_1_v','t_2_v','date_t','date_t_HK','Phase_t','Ratio_PbS','Ratio_PbSe');
                end
            else
                savefig(fig,strcat(files_dir,'\',C{end},'.fig'));
                saveas(fig(6),strcat(files_dir,'\','Ratio_PbS','.png'));
                saveas(fig(7),strcat(files_dir,'\','Ratio_PbSe_Fwd','.png'));
                if strcmp(model,'EM')|| strcmp(model,'FM') ||strcmp(model,'FS')
                    saveas(fig(1),strcat(files_dir,'\','Detect1_PbSe_Fwd','.png'));
                    saveas(fig(2),strcat(files_dir,'\','Detect2_PbS_Fwd','.png'));
                    saveas(fig(3),strcat(files_dir,'\','Detect3_PbSe_Bwd','.png'));
                    saveas(fig(4),strcat(files_dir,'\','Detect4_PbS_Bwd','.png'));
                    saveas(fig(5),strcat(files_dir,'\','voltage_house_keeping','.png'));
                    
                    %         saveas(fig(6),strcat(files_dir,'\','Detect1_PbSe_Fwd_Phase','.png'));
                    %         saveas(fig(7),strcat(files_dir,'\','Detect2_PbS_Fwd_Phase','.png'));
                    %         saveas(fig(8),strcat(files_dir,'\','Detect3_PbSe_Bwd_Phase','.png'));
                    %         saveas(fig(9),strcat(files_dir,'\','Detect4_PbS_Bwd_Phase','.png'));
                else
                    saveas(fig(1),strcat(files_dir,'\','Detec1_PbS_Fwd','.png'));
                    saveas(fig(2),strcat(files_dir,'\','Detect2_PbSe_Fwd','.png'));
                    saveas(fig(3),strcat(files_dir,'\','Detect3_PbS_Bwd','.png'));
                    saveas(fig(4),strcat(files_dir,'\','Detect4_PbSe_Bwd','.png'));
                    saveas(fig(5),strcat(files_dir,'\','voltage_house_keeping','.png'));
                    
                    saveas(fig(6),strcat(files_dir,'\','Ratio_PbS','.png'));
                    saveas(fig(7),strcat(files_dir,'\','Ratio_PbSe_Fwd','.png'));
                    
                    %         saveas(fig(6),strcat(files_dir,'\','Detec1_PbS_Fwd_Phase','.png'));
                    %         saveas(fig(7),strcat(files_dir,'\','Detect2_PbSe_Fwd_Phase','.png'));
                    %         saveas(fig(8),strcat(files_dir,'\','Detect3_PbS_Bwd_Phase','.png'));
                    %         saveas(fig(9),strcat(files_dir,'\','Detect4_PbSe_Bwd_Phase','.png'))
                end
                
                % Establecer formato de resultados igual a los obtenidos en
                % campañas manuales
                
                PbS_Fwd.Vp = Vp_t(2,:);
                PbSe_Fwd.Vp = Vp_t(1,:);
                PbS_Bwd.Vp = Vp_t(4,:);
                PbSe_Bwd.Vp = Vp_t(3,:);
                
                PbS_Fwd.X = X_t(2,:);
                PbSe_Fwd.X = X_t(1,:);
                PbS_Bwd.X = X_t(4,:);
                PbSe_Bwd.X = X_t(3,:);
                
                PbS_Fwd.Y = Y_t(2,:);
                PbSe_Fwd.Y = Y_t(3,:);
                PbS_Bwd.Y = Y_t(4,:);
                PbSe_Bwd.Y = Y_t(3,:);
                
                
                % Registro de resultados en fichero.mat
                
                if exist('t_3_t','var')
                    save(strcat(files_dir,'\',C{end},'.mat'),'t_1_t','t_2_t','v_house_keep_t','Vp_t','t_3_t','t_1_v','t_2_v','t_3_v','date_t','date_t_HK','Phase_t','Ratio_PbS','Ratio_PbSe','X_t','Y_t','PbSe_Bwd','PbS_Bwd','PbSe_Fwd','PbS_Fwd');
                else
                    save(strcat(files_dir,'\',C{end},'.mat'),'t_1_t','t_2_t','v_house_keep_t','Vp_t','t_1_v','t_2_v','date_t','date_t_HK','Phase_t','Ratio_PbS','Ratio_PbSe','X_t','Y_t', 'PbSe_Bwd','PbS_Bwd','PbSe_Fwd','PbS_Fwd');
                end
            end


            if ~(app.PbSFwdCheckBox.Value)
                close(fig(1));
            end
            
            if ~(app.PbSeFwdCheckBox.Value)
                close(fig(2));
            end
            
            if ~(app.PbSBwdCheckBox.Value)
                close(fig(3));
            end
            
            if ~(app.PbSeBwdCheckBox.Value)
                close(fig(4));
            end
            
            if ~(app.Vcc2CheckBox.Value)
                close(fig(5));
            end
            
            if ~(app.RatioPbSCheckBox.Value)
                close(fig(6));
            end
            
            if ~(app.RatioPbSeCheckBox.Value)
                close(fig(7));
            end
        end

        function results = SendParam(app, cmd, fparam, sparam)
            app.ConfigString = strcat('egse_cli -p',app.COMPortDropDown.Value,' -b',app.BaudrateDropDown.Value );
            
            %%Add params
            if ( contains(cmd,'C0') || contains(cmd,'C1'))
                app.ConfigString = strcat(app.ConfigString,' -f', fparam,' -n',sparam); %If it is a time configuration, adds two parameters
            else
                app.ConfigString = strcat(app.ConfigString,' -f', fparam);
            end
            
            if strcmp(app.ModeSwitch.Value,'L') %lock in - no lock in modes
                command = strcat(app.ConfigString, ' -c ', cmd);
            else
                command = strcat(app.ConfigString, ' -c ', cmd);
                command = strrep(command,'egse_cli','egse_cli_no_lockin');
                
            end
            app.CommandlogTextArea.Value = command;   %log info
            
            [ok,cmdout] = system(command);      %Execute command
            if ok ~= 0  %System error(PC not executing egse_cli.exe or egse_cli.exe error)
                app.ErrorLamp.Color = 'red';
                app.ErrorLamp.Enable = 'on';
                results =0;
                app.error_text.Value='System error';
                app.CommandlogTextArea.Value = { command; cmdout };
            elseif contains(cmdout,'Ack not received') %no answer error
                app.ErrorLamp.Color = 'red';
                app.ErrorLamp.Enable = 'on';
                results =0;
                app.error_text.Value='No Ack';
                app.CommandlogTextArea.Value = { command; cmdout };
            else %no error or diferent type of error
                
                lines = strsplit(deblank(cmdout), '\n');
                %Print answer on screen (depends on the command)
                
                app.CommandlogTextArea.Value = { command; cmdout };
                
                
                %Proccess answer
                if length(lines)>3
                    line = replace(lines(4),'/ ','');
                    cmdline = deblank(extractAfter(line,') '));
                end
                if length(lines)>4
                    if length(cmd)==2  %not telemetry command
                        line = replace(lines(5),'/ ','');
                    else
                        line = replace(lines(6),'/ ','');
                    end
                    
                    ackline = deblank(extractAfter(line,') '));
                    result = split(ackline);
                    
                    size = result(2);
                    data = result(3:length(result)-2);
                    status = hex2dec(char(result(length(result)-1)));
                    crc = result(length(result));
                    %end process answer
                    
                    %Extract status and errors in response
                    ok = fix(status/16);
                    status = status - 16*ok;
                    command_status=fix(ok/8);
                    error_code=ok-command_status*8;
                    
                    %Decode error data and shows in GUI text box
                    if ~strcmp(cmd,'00')  %No nop command
                        if command_status  %Command not procesed
                            app.error_text.Value='Invalid state';
                        else
                            switch error_code
                                case 0
                                    app.error_text.Value='No error';
                                case 1
                                    app.error_text.Value='Wrong command';
                                case 2
                                    app.error_text.Value='Length error';
                                case 3
                                    app.error_text.Value='Checksum error';
                                case 4
                                    app.error_text.Value='Parity error';
                                case 5
                                    app.error_text.Value='Time error';
                            end
                        end
                    else
                        switch error_code
                            case 0
                                app.error_text.Value='No error';
                            case 1
                                app.error_text.Value='Wrong command';
                            case 2
                                app.error_text.Value='Length error';
                            case 3
                                app.error_text.Value='Checksum error';
                            case 4
                                app.error_text.Value='Parity error';
                            case 5
                                app.error_text.Value='Time error';
                        end
                        
                        
                    end  %end decode error
                    
                    
                    %Color indicator
                    if strcmp(app.error_text.Value,'No error')
                        app.ErrorLamp.Color = 'green';
                        app.ErrorLamp.Enable = 'on';
                        results=1;
                    else
                        app.ErrorLamp.Color = 'red';
                        app.ErrorLamp.Enable = 'on';
                        results=0;
                    end %End Color indicator
                    
                    %Show state in GUI text box
                    switch status
                        case 0
                            app.StateEditField.Value ='Idle';
                        case 1
                            app.StateEditField.Value ='Config';
                        case 2
                            app.StateEditField.Value ='W. up';
                        case 3
                            app.StateEditField.Value ='Sens';
                        case 4
                            app.StateEditField.Value ='Tele';
                        otherwise
                            app.error_text.Value='?';
                    end %end show sate
                    
                end
                
            end
            
            
        end
        
        
        function results = ApplyConfig(app)
            
            pause on;    %Enables the pause function
            
            
            %Clears Error Lamps%
            app.WarmUpTimeLamp.Enable = 'off';
            app.WarmUpTimeLamp.Color = 'white';
            
            app.SensingTimeLamp.Enable = 'off';
            app.SensingTimeLamp.Color = 'white';
            
            app.EmitterVpLamp.Enable = 'off';
            app.EmitterVpLamp.Color = 'white';
            
            app.EmitterFreqLamp.Enable = 'off';
            app.EmitterFreqLamp.Color = 'white';
            
            app.EmitterWaveLamp.Enable = 'off';
            app.EmitterWaveLamp.Color = 'white';
            
            %If it's selected, sends configuration parameter
            
            if app.WarmUpTimeCheckBox.Value
                
                
                %Convert decimal value to hexadecimal
                
                b = dec2hex(app.WarmUptimeC_EditField.Value,4);
                
                %Separate value into two bytes
                
                WUTH = b(1:2);
                WUTL = b(3:4);
                
                
                Results = SendParam(app,'C0',WUTH,WUTL);
                
                %Test [Delete]
                %app.WarmUpConfig_time = app.WarmUptimeC_EditField.Value; %If there's no error, saves the time value
                
                if(Results == 1)
                    
                    
                    app.WarmUpTimeLamp.Color = 'green';
                    app.WarmUpTimeLamp.Enable = 'on';
                    
                    app.WarmUpConfig_time =           app.WarmUptimeC_EditField.Value; %If there's no error, saves the time value
                    app.WarmUp_time =                app.WarmUptimeC_EditField.Value;
                    app.WarmuptimeEditField.Value =   app.WarmUptimeC_EditField.Value;
                    
                else
                    app.WarmUpTimeLamp.Color = 'red';
                    app.WarmUpTimeLamp.Enable = 'on';
                end
                
                pause(0.25); %Pause to ensure the communication before send the next parameter
                
            end


            if app.SensingTimeCheckBox.Value
                
                
                %Convert decimal value to hexadecimal
                
                b = dec2hex(app.SensetimeC_EditField.Value,4);
                
                %Separate value into two bytes
                
                STH = b(1:2);
                STL = b(3:4);
                
                
                Results = SendParam(app,'C1',STH,STL);
                
                %Test [Delete]
                %app.MeasConfig_time = app.SensetimeC_EditField.Value; %If there's no error, save the time value


                if(Results == 1)
                    app.MeasConfig_time =                app.SensetimeC_EditField.Value; %If there's no error, save the time value
                    app.Meas_time =                      app.SensetimeC_EditField.Value;
                    app.MeasurementtimeEditField.Value = app.SensetimeC_EditField.Value;
                    
                    app.SensingTimeLamp.Color = 'green';
                    app.SensingTimeLamp.Enable = 'on';
                else
                    app.SensingTimeLamp.Color = 'red';
                    app.SensingTimeLamp.Enable = 'on';
                end
                
                pause(0.25); %Pause to ensure the communication before send the next parameter
                
            end
            
            if app.EmitterFreqCheckBox.Value
                
                %Select the frequency parameter value
                switch app.FrequencyDropDown.Value
                    case ' 2 Hz'
                        freq_value = '00';
                    case ' 5 Hz'
                        freq_value = '01';
                    case '10 Hz'
                        freq_value = '02';
                    case '20 Hz'
                        freq_value = '03';
                    case '50 Hz'
                        freq_value = '04';
                    case '100 Hz'
                        freq_value = '05';
                    otherwise
                        freq_value = '02';
                end


                %Send EMITF command and its parameter
                Results = SendParam(app,'C2',freq_value,'00');
                
                %Test [Delete]
                %app.EmitterFreq = app.FrequencyDropDown.Value;
                
                %Check results
                if(Results == 1)
                    app.EmitterFreq = app.FrequencyDropDown.Value; %If the configuration its complete, save the new value
                    app.EmitterFreqLamp.Color = 'green';
                    app.EmitterFreqLamp.Enable = 'on';
                else
                    app.EmitterFreqLamp.Color = 'red';
                    app.EmitterFreqLamp.Enable = 'on';
                end
                
                pause(0.25); %Pause to ensure the communication before send the next parameter
                
            end
            
            if app.EmitterWaveCheckBox.Value
                
                
                %Select the wave parameter value
                switch app.WaveDropDown.Value
                    case 'Sine'
                        wave_value = '00';
                    case 'Square'
                        wave_value = '01';
                    case 'Sqrt Sine'
                        wave_value = '02';
                end
                
                %Send EMITW command and its parameter
                Results = SendParam(app,'C3',wave_value,'00');
                
                %Test [Delete]
                %app.EmitterWave = app.WaveDropDown.Value; %If the configuration its complete, save the new wave value
                
                
                %%
                
                %Check results
                if(Results == 1)
                    app.EmitterWaveLamp.Color = 'green';
                    app.EmitterWaveLamp.Enable = 'on';
                    app.EmitterWave = app.WaveDropDown.Value; %If the configuration its complete, save the new wave value
                    
                else
                    app.EmitterWaveLamp.Color = 'red';
                    app.EmitterWaveLamp.Enable = 'on';
                end
                
                pause(0.25); %Pause to ensure the communication before send the next parameter
            end
            
            if app.EmitterVpCheckBox.Value
                
                %Converts value to binary
                num = app.VpEditField.Value;
                nbit_e = 3;        % # Bits Whole number part
                nbit_f = 5;        % # Bits decimal number part
                
                
                e = floor(num);
                f = num - e;
                b = de2bi(e,nbit_e,'left-msb');
                t = f;
                
                %Converts decimal part to binary
                
                for i = nbit_e +1:nbit_e + nbit_f
                    
                    t = t*2;
                    b(i) = floor(t);
                    t = t - b(i);
                end
                
                %Converts binary value to hexadecimal
                EmitterVpHex = binaryVectorToHex(b);
                
                
                Results = SendParam(app,'C4',EmitterVpHex,'00');
                
                %Test [Delete]
                %app.EmitterVp = (bi2de(b,'left-msb'))/32;  %If configuration its correct, save the new value.
                %app.VpEditField.Value = app.EmitterVp;
                
                
                %%
                
                %Check results
                if(Results == 1)
                    app.EmitterVpLamp.Color = 'green';
                    app.EmitterVpLamp.Enable = 'on';
                    
                    app.EmitterVp = (bi2de(b,'left-msb'))/32;  %If configuration its correct, save the new value.
                    app.VpEditField.Value = app.EmitterVp;        %Configuration in Connect Tab


                else
                    app.EmitterVpLamp.Color = 'red';
                    app.EmitterVpLamp.Enable = 'on';
                end
                
                pause(0.25); %Pause to ensure the communication before send the next parameter
                
            end
            pause off;
            
        end
        
        function results = ApplyAutoConfig(app)
            
            pause on;    %Enables the pause function
            
            %Clears Error Lamps%
            app.WarmUpTimeLamp.Enable = 'off';
            app.WarmUpTimeLamp.Color = 'white';
            
            app.SensingTimeLamp.Enable = 'off';
            app.SensingTimeLamp.Color = 'white';
            
            
            %%Aply Warm Up Time
            %Convert decimal value to hexadecimal
            
            b = dec2hex(app.WarmUp_time,4);
            
            %Separate value into two bytes
            
            WUTH = b(1:2);
            WUTL = b(3:4);
            
            
            Results_WU = SendParam(app,'C0',WUTH,WUTL);
            
            
            if(Results_WU == 1)
                
                app.WarmUpTimeLamp.Color = 'green';
                app.WarmUpTimeLamp.Enable = 'on';
                
                %If there's no error, save the time value
                %                 app.WarmUp_time =                app.WarmuptimeEditField.Value;
                %                 app.WarmuptimeEditField.Value =   app.WarmuptimeEditField.Value;
                
                app.WarmUpConfig_time =  app.WarmuptimeEditField.Value;
                app.WarmUptimeC_EditField.Value = app.WarmuptimeEditField.Value;
                
                
                %else.... cancelar auto? como notifico?
            else
                
                app.WarmUpTimeLamp.Color = 'red';
                app.WarmUpTimeLamp.Enable = 'on';
                
            end
            
            pause(0.25); %Pause to ensure the communication before send the next parameter


            %%Aply Measurement Time
            
            
            %Convert decimal value to hexadecimal
            
            b = dec2hex(app.Meas_time,4);
            
            %Separate value into two bytes
            
            STH = b(1:2);
            STL = b(3:4);
            
            
            Results_M = SendParam(app,'C1',STH,STL);
            
            if(Results_M == 1)
                
                app.SensingTimeLamp.Enable = 'on';
                app.SensingTimeLamp.Color = 'green';
                
                %If there's no error, save the time value
                %                 app.Meas_time =                      app.MeasurementtimeEditField.Value;
                %                 app.MeasurementtimeEditField.Value = app.MeasurementtimeEditField.Value;
                
                app.MeasConfig_time = app.MeasurementtimeEditField.Value;
                app.SensetimeC_EditField.Value = app.MeasurementtimeEditField.Value;
            else
                
                app.SensingTimeLamp.Enable = 'on';
                app.SensingTimeLamp.Color = 'red';
                
            end
            
            pause(0.25); %Pause to ensure the communication before send the next parameter
            pause off;
            
            results = Results_WU && Results_M;
            
        end
        
        function [data_length, data_length_total, data_length_HK] = DataLength_Calculate(app)
            
            data_length = app.Meas_time * 2; %LockIn output: 2samples/second
            
            num_bytes = data_length * 16; %Each sample, 16 bytes ( 4bytes/sensor, 4 sensor)
            
            num_datapackets = ceil(num_bytes / 240); %240bytes of samples in each data packet
            
            data_length_total = num_datapackets * 15; %15 samples in each data packet
            
            data_length_HK = num_datapackets - 1; %1 sample in each data packet ( except the last one?)
            
        end
        
        function results = Export_and_Save_Data(app,save_data,path)
            if isempty(app.DatafileEditField.Value)
                return;
            end
            
            run(app.DatafileEditField.Value);
            
            scale = (app.YmaxEditField.Value-app.YminEditField.Value)/(2^app.BitsEditField.Value-1);
            if  (exist('sensors','var'))
                
                if app.SamplingFreqUserChange==0
                    if app.SamplingFreqHzEditField.Value~=2 && app.SamplingFreqHzEditField.Value~=1000
                        fs = app.SamplingFreqHzEditField.Value;
                    else
                        app.SamplingFreqHzEditField.Value=1000;
                        fs=1000;
                    end
                end
                
                if app.SignedCheckBox.Value
                    for i = 1:size(sensors,1)
                        for j = 1:size(sensors,2)
                            if sensors(i,j) >= 2^(app.BitsEditField.Value-1)
                                sensors(i,j) = sensors(i,j) - 2^app.BitsEditField.Value;
                            end
                        end
                    end
                    sensors = sensors + 2^(app.BitsEditField.Value-1);
                end
                voltages = sensors*scale + app.YminEditField.Value;
                l = length(voltages(1,:));
                x = linspace(0,(l-1)/fs,l);
                
                
            else
                %locking state
                
                
                if ~exist('sensors_x','var')
                    return
                end
                if ~exist('sensors_y','var')
                    return
                end
                if app.SamplingFreqUserChange==0
                    if app.SamplingFreqHzEditField.Value~=2 && app.SamplingFreqHzEditField.Value~=1000
                        fs = app.SamplingFreqHzEditField.Value;
                    else
                        app.SamplingFreqHzEditField.Value=2;
                        fs=2;
                    end
                end
                
                if strcmp(app.RefSwitch.Value,'Seno')
                    %                     app.VrefEditField.Value=app.Vpref_seno_default;
                    scale = (app.YmaxEditField.Value-app.YminEditField.Value)^2/(2^app.BitsEditField.Value);
                else
                    %                     app.VrefEditField.Value=4/pi;
                    scale = (app.YmaxEditField.Value-app.YminEditField.Value)/(2^app.BitsEditField.Value);
                end
                
                %                  scale = (app.YmaxEditField.Value-app.YminEditField.Value)/(2^app.BitsEditField.Value);
                if app.SignedCheckBox.Value
                    for i = 1:size(sensors_x,1)
                        for j = 1:size(sensors_x,2)
                            if sensors_x(i,j) >= 2^(app.BitsEditField.Value-1)
                                sensors_x(i,j) = sensors_x(i,j) - 2^app.BitsEditField.Value;
                                %                                 sensors_y(i,j) = sensors_y(i,j) - 2^app.BitsEditField.Value;
                            end
                        end
                    end
                    %sensors_x = sensors_x + 2^(app.BitsEditField.Value-1);
                    %                     sensors_y = sensors_y + 2^(app.BitsEditField.Value-1);
                end
                
                if app.SignedCheckBox.Value
                    for i = 1:size(sensors_y,1)
                        for j = 1:size(sensors_y,2)
                            if sensors_y(i,j) >= 2^(app.BitsEditField.Value-1)
                                %                                 sensors_(i,j) = sensors_x(i,j) - 2^app.BitsEditField.Value;
                                sensors_y(i,j) = sensors_y(i,j) - 2^app.BitsEditField.Value;
                            end
                        end
                    end
                    % sensors_x = sensors_x + 2^(app.BitsEditField.Value-1);
                    %                     sensors_y = sensors_y + 2^(app.BitsEditField.Value-1);
                end
                voltages_x = sensors_x*scale;%-((app.YmaxEditField.Value-app.YminEditField.Value)*2)/2;
                voltages_y = sensors_y*scale;%-((app.YmaxEditField.Value-app.YminEditField.Value)*2)/2;
                R=sqrt((voltages_x.^2)+(voltages_y.^2));
                Vp=R*2/app.VrefEditField.Value;
                
                Vp1=Vp(1,:);
                Vp2=Vp(2,:);
                Vp3=Vp(3,:);
                Vp4=Vp(4,:);
                %                 Vp1_mean=mean(Vp1(Vp1~=0));
                %                 Vp2_mean=mean(Vp2(Vp2~=0));
                %                 Vp3_mean=mean(Vp3(Vp3~=0));
                %                 Vp4_mean=mean(Vp4(Vp4~=0));
                %
                %                  app.Vp1EditField.Value=Vp1_mean;
                %                  app.Vp2EditField.Value=Vp2_mean;
                %                  app.Vp3EditField.Value=Vp3_mean;
                %                  app.Vp4EditField.Value=Vp4_mean;
                
                l = length(voltages_x(1,:));
                time = linspace(0,(l-1)/fs,l);
                
                if strcmp(app.TargetModel,'CM1')|| strcmp(app.TargetModel,'CM2') || strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB')
                    PbS_Fwd.Vp=Vp(1,:);
                    PbSe_Fwd.Vp=Vp(2,:);
                    PbS_Bwd.Vp=Vp(3,:);
                    PbSe_Bwd.Vp=Vp(4,:);
                    
                    PbS_Fwd.X=voltages_x(1,:);
                    PbSe_Fwd.X=voltages_x(2,:);
                    PbS_Bwd.X=voltages_x(3,:);
                    PbSe_Bwd.X=voltages_x(4,:);
                    
                    PbS_Fwd.Y=voltages_y(1,:);
                    PbSe_Fwd.Y=voltages_y(2,:);
                    PbS_Bwd.Y=voltages_y(3,:);
                    PbSe_Bwd.Y=voltages_y(4,:);
                    
                else
                    
                    PbS_Fwd.Vp=Vp(2,:);
                    PbSe_Fwd.Vp=Vp(1,:);
                    PbS_Bwd.Vp=Vp(4,:);
                    PbSe_Bwd.Vp=Vp(3,:);
                    
                    PbS_Fwd.X=voltages_x(2,:);
                    PbSe_Fwd.X=voltages_x(1,:);
                    PbS_Bwd.X=voltages_x(4,:);
                    PbSe_Bwd.X=voltages_x(3,:);
                    
                    PbS_Fwd.Y=voltages_y(2,:);
                    PbSe_Fwd.Y=voltages_y(3,:);
                    PbS_Bwd.Y=voltages_y(4,:);
                    PbSe_Bwd.Y=voltages_y(3,:);
                end
                assignin('base','PbS_Fwd',PbS_Fwd);
                assignin('base','PbSe_Fwd',PbSe_Fwd);
                assignin('base','PbS_Bwd',PbS_Bwd);
                assignin('base','PbSe_Bwd',PbSe_Bwd);
                assignin('base','time',time);
                
                if(strcmp(save_data,'On'))
                    save(strcat(path,'/',strtok(app.DatafileEditField.Value,'.'),'_data.mat'),'PbS_Bwd','PbS_Fwd','PbSe_Bwd','PbSe_Fwd','time');
                end
            end
        end


    end
    methods (Access = public)
        
        function AutoProgresTimerCallback(app, obj, event, string_arg)
            app.AutoProgresTimerCount=app.AutoProgresTimerCount+1;
            app.ProgresGauge.Value=app.AutoProgresTimerCount/app.AutoProgresTimerTotalCount*100;
            app.ProgresTimeEditField.Value = sprintf('%d / %d s',app.AutoProgresTimerCount,app.AutoProgresTimerTotalCount);
        end
        
        function  AutoProgresTimerStopCallback(app, obj, event, string_arg)
            
            app.ProgresGauge.Value = 100;
            app.ProgresTimeEditField.Value = sprintf('%d / %d s',app.AutoProgresTimerCount,app.AutoProgresTimerTotalCount);
        end
        
        function TimerStopCallback(app, obj, event, string_arg)
            pause on
            if app.AutoStop==0
                switch app.AutoState
                    case 0 %idle
                        if app.campaign > 1
                            time_soff = toc;
                            tic
                            fprintf(app.fileID_general_log,'[ %f s] ',time_soff);
                            %fprintf(app.fileID_log_concreto,'Time Switch Off: %f s\n\n',time_soff);
                            fprintf(app.fileID_general_log,'\n\n');
                        end
                        
                        disp('idle');
                        app.fileID_log_concreto = fopen(strcat(pwd,'\',app.dir_name,'\',app.Name,'_', sprintf('campaign_%i.txt',app.campaign)),'a');
                        fprintf(app.fileID_general_log,'........CAMPAIGN %i - %s...........\n',app.campaign,datestr(now,'dd-mm-yy_HH:MM:SS'));
                        
                        string=sprintf('........CAMPAIGN %i - %s...........\n',app.campaign,datestr(now,'dd-mm-yy_HH:MM:SS'));
                        fprintf(app.fileID_log_concreto,string);
                        app.LogwindowTextArea.Value =[app.LogwindowTextArea.Value;{string}];
                        
                        Result_Config = 1;
                        
                        if ~(app.AutoFirstRun && app.ContinuousmodeCheckBox.Value)
                            if  strcmp(app.Switch.Value,'Relays' ) %if relays control is enable, turn on DS
                                writeDigitalPin(app.arduino,app.pin_1,app.Relay_on);
                                writeDigitalPin(app.arduino,app.pin_3,app.Relay_on) ;
                                writeDigitalPin(app.arduino,app.pin_2,app.Relay_on);
                                
                                if(strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB'))
                                    %%Apply Auto Config
                                    pause(1);
                                    Result_Config = ApplyAutoConfig(app);
                                    if ~(Result_Config == 1)            %If the configuration fails, cancel the process
                                        app.AutoStop=1;
                                        stop(app.AutoTimer);
                                        
                                        %app.ProgresGauge.Value=0;
                                        app.CancelButton.Enable='off';
                                        app.StartButton_2.Enable='on';
                                        try
                                            writeDigitalPin(app.arduino,app.pin_1,app.Relay_off);
                                            writeDigitalPin(app.arduino,app.pin_2,app.Relay_off);
                                            writeDigitalPin(app.arduino,app.pin_3,app.Relay_off);
                                        catch
                                        end
                                        app.LogwindowTextArea.Value =[app.LogwindowTextArea.Value;{'Configuration Error'}];
                                        fclose(app.fileID_general_log);
                                        fclose(app.fileID_log_concreto);
                                    else
                                        app.LogwindowTextArea.Value =[app.LogwindowTextArea.Value;{'Configuration Success'}];
                                    end
                                end
                            end
                            
                            if(Result_Config == 1)
                                time_conf = toc;
                                tic
                                fprintf(app.fileID_log_concreto,'IDLE STATE\n');
                                fprintf(app.fileID_log_concreto,'Time Config: %f s\n\n',time_conf);
                                fprintf(app.fileID_general_log,'\nTime Config: %f s\n',time_conf);
                                fprintf(app.fileID_general_log,'IDL:');
                                app.AutoState=1;
                                %app.ProgresGauge.Value=(app.TotalTime*(app.campaign-1))/(app.TotalTime*app.N_campaign)*100;
                                app.AutoTimer.StartDelay=app.SelfTest_time;
                                if ~(app.AutoFirstRun)
                                    start(app.AutoProgresTimer);
                                end
                                
                                start(app.AutoTimer);
                                disp('idle');
                                
                            end
                        else
                            app.AutoState=1;
                            %app.ProgresGauge.Value=(app.TotalTime*(app.campaign-1))/(app.TotalTime*app.N_campaign)*100;
                            app.AutoTimer.StartDelay=1;
                            start(app.AutoTimer);
                            disp('idle');
                            
                        end
                        
                    case 1 %warming up
                        
                        disp('warm');
                        
                        if ~(app.AutoFirstRun && app.ContinuousmodeCheckBox.Value)
                            time_idle = toc;
                            tic
                            
                            
                            if(strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB'))
                                Next_State = 2;
                            else
                                Next_State = 1;
                            end
                            
                            app.error1=send_command(app,'01',Next_State,app.fileID_general_log,app.fileID_log_concreto); %warm up
                            app.number_errors=app.number_errors+app.error1;
                            fprintf(app.fileID_general_log,'[ %f s] ',time_idle);
                            fprintf(app.fileID_log_concreto,'Time Idle: %f s\n\n',time_idle);
                            fprintf(app.fileID_log_concreto,'WARM UP\n');
                            fprintf(app.fileID_general_log,'WARM:');
                            app.LogwindowTextArea.Value =[app.LogwindowTextArea.Value;{'WARM UP'}];
                            app.AutoState=2;
                            %app.ProgresGauge.Value=(app.TotalTime*(app.campaign-1)+app.SelfTest_time)/(app.TotalTime*app.N_campaign)*100;
                            app.AutoTimer.StartDelay=app.WarmUp_time;
                            
                            start(app.AutoTimer);
                            
                        else
                            app.AutoState=2;
                            %app.ProgresGauge.Value=(app.TotalTime*(app.campaign-1)+app.SelfTest_time)/(app.TotalTime*app.N_campaign)*100;
                            app.AutoTimer.StartDelay=1;
                            start(app.AutoTimer);
                            
                        end
                        
                    case 2 %sensing
                        
                        if ~(app.AutoFirstRun && app.ContinuousmodeCheckBox.Value)
                            time_wu = toc;
                            tic
                        end
                        disp('sensing');
                        
                        if(strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB'))
                            Next_State = 3;
                        else
                            Next_State = 2;
                        end
                        
                        app.error1=send_command(app,'02',Next_State,app.fileID_general_log,app.fileID_log_concreto); %sense
                        app.number_errors=app.number_errors+app.error1;
                        
                        if ~(app.AutoFirstRun && app.ContinuousmodeCheckBox.Value)
                            fprintf(app.fileID_general_log,'[ %f s] ',time_wu);
                            fprintf(app.fileID_log_concreto,'Time Warm Up: %f s\n\n',time_wu);
                        end
                        fprintf(app.fileID_log_concreto,'SENSING\n');
                        fprintf(app.fileID_general_log,'SENS:');
                        app.LogwindowTextArea.Value =[app.LogwindowTextArea.Value;{'SENSING'}];
                        app.AutoState=3;
                        app.AutoFirstRun=1;
                        %app.ProgresGauge.Value=(app.TotalTime*(app.campaign-1)+app.WarmUp_time+app.SelfTest_time)/(app.TotalTime*app.N_campaign)*100;
                        app.AutoTimer.StartDelay=app.Meas_time;
                        start(app.AutoTimer);
                        
                    case 3 %telemetry and status
                        time_sens = toc;
                        tic
                        
                        disp('tele');
                        
                        sens_file_name = sprintf('sens_%i_%s.m',app.campaign,datestr(now,'dd_mm_yy_HH_MM_SS'));
                        path = strcat(pwd,'\',app.dir_name,'\');
                        file_name= strcat(path,sens_file_name);
                        cmd = strcat('04 -d',file_name); %get data
                        cmd = insertAfter(cmd,'04 -d',' ');
                        
                        if(strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB'))
                            Next_State = 4;
                        else
                            Next_State = 3;
                        end
                        
                        
                        app.error1=send_command(app,cmd,Next_State,app.fileID_general_log,app.fileID_log_concreto);
                        app.number_errors=app.number_errors+app.error1;
                        
                        if app.error1==0
                            if exist(file_name,'file')
                                
                                run(file_name);
                                
                                switch app.TargetModel
                                    case 'CM1'
                                        t1=(house_keep(2,1:end-1)*5/2^16-1.072)/0.02882;
                                        t2=(house_keep(3,1:end-1)*5/2^16-1.072)/0.02882;
                                        t3=(house_keep(4,1:end-1)*5/2^16-1.072)/0.02882;
                                    case 'CM2'
                                        t1=(house_keep(1,1:end-1)*5/2^16-1.072)/0.02882;
                                        t2=(house_keep(3,1:end-1)*5/2^16-1.072)/0.02882;
                                        t3=(house_keep(4,1:end-1)*5/2^16-1.072)/0.02882;
                                        
                                    case 'EM/EQM'
                                        t1=(house_keep(1,1:end-1)*5/2^16-2.988)*41.437;
                                        t2=(house_keep(3,1:end-1)*5/2^16-2.988)*41.437;
                                        t3=-inf;
                                        
                                    case 'FM'
                                        if strcmpi(app.FMTempcorrectionVcc2Switch.Value,'OFF')
                                            t1=(house_keep(1,1:end-1)*5/2^16-3.024)/0.02452; %FM
                                            t2=(house_keep(3,1:end-1)*5/2^16-2.481)/0.01983;
                                            t3=-inf;
                                        else
                                            t1=(house_keep(1,1:end-1)*5/2^16-(3.103+1.217*(house_keep(2,1:end-1)*5/2^16-2.55)))./(25.01e-3+9.81e-3*(house_keep(2,1:end-1)*5/2^16-2.55));
                                            t2=(house_keep(3,1:end-1)*5/2^16-(2.531+0.993*(house_keep(2,1:end-1)*5/2^16-2.55)))./(20.229e-3+7.933e-3*(house_keep(2,1:end-1)*5/2^16-2.55));
                                            t3=-inf;
                                        end
                                    case 'FS'
                                        %FM3
                                        t1=(house_keep(1,1:end-1)*5/2^16-(3.108+1.219*(house_keep(2,1:end-1)*5/2^16-2.55)))./(20.939e-3+7.527e-3*(house_keep(2,1:end-1)*5/2^16-2.55)); %Fwd
                                        %t1=(house_keep(1,1:end-1)*5/2^16-(2.965+1.16*(house_keep(2,1:end-1)*5/2^16-2.55)))./(20.314e-3+7.967e-3*(house_keep(2,1:end-1)*5/2^16-2.55));
                                        t2=(house_keep(3,1:end-1)*5/2^16-(2.965+1.16*(house_keep(2,1:end-1)*5/2^16-2.55)))./(20.314e-3+7.967e-3*(house_keep(2,1:end-1)*5/2^16-2.55));
                                        t3=-inf;
                                    case 'CM3'
                                        t1=(house_keep(1,1:end-1)*5/2^16-2.915)/0.019905;
                                        t2=(house_keep(2,1:end-1)*5/2^16-2.915)/0.019905;
                                        t3=(house_keep(4,1:end-1)*5/2^16-1.072)/0.02882;
                                    case 'EBB'
                                        t1=(house_keep(1,1:end-1)*5/2^16-1.072)/0.02882;
                                        t2=(house_keep(3,1:end-1)*5/2^16-1.072)/0.02882;
                                        t3=(house_keep(4,1:end-1)*5/2^16-1.072)/0.02882;
                                        
                                end
                                
                            end
                        else
                            if app.Meas_time < 10
                                t1=1;
                                t2=1;
                                t3=1;
                            else
                                t1=0;
                                t2=0;
                                t3=0;
                            end
                        end
                        
                        fprintf(app.fileID_general_log,'[ %f s] ',time_sens);
                        fprintf(app.fileID_log_concreto,'Time Sens: %f s\n\n',time_sens);
                        fprintf(app.fileID_log_concreto,'TELEMETRY\n');
                        fprintf(app.fileID_general_log,'TELE:');
                        pause(4);
                        app.LogwindowTextArea.Value =[app.LogwindowTextArea.Value;{'TELEMETRY'}];
                        app.error1=send_command(app,'03',Next_State,app.fileID_general_log,app.fileID_log_concreto); %status
                        app.number_errors=app.number_errors+app.error1;
                        %app.ProgresGauge.Value=(app.TotalTime*(app.campaign-1)+app.Meas_time+app.WarmUp_time+app.SelfTest_time)/(app.TotalTime*app.N_campaign)*100;
                        app.AutoState=4;
                        app.AutoTimer.StartDelay=1;
                        
                        if((max(t1)>app.MaxtempEditField.Value)) %|| (max(t2)>app.MaxtempEditField.Value) || (max(t3)>app.MaxtempEditField.Value))
                            
                            app.LogwindowTextArea.Value =[app.LogwindowTextArea.Value;{'TEMPERATURE ALERT: TEST STOPPED'}];
                            
                            try
                                writeDigitalPin(app.arduino,app.pin_2,app.Relay_off);
                                writeDigitalPin(app.arduino,app.pin_1,app.Relay_off);
                                writeDigitalPin(app.arduino,app.pin_3,app.Relay_off);
                            catch
                            end
                            
                            app.AutoStop=1;
                            stop(app.AutoTimer);
                            app.ProgresGauge.Value=0;
                            stop(app.AutoProgresTimer);
                            stop(app.AutoProgresTimer);
                            app.CancelButton.Enable='off';
                            app.StartButton_2.Enable='on';
                            
                            fclose(app.fileID_general_log);
                            fclose(app.fileID_log_concreto);
                        else
                            
                            addpath(path);
                            app.DatafileEditField.Value=sens_file_name;
                            Draw(app);
                            
                            start(app.AutoTimer);
                        end
                        
                    case 4 %switch off
                        time_tele = toc;
                        tic
                        disp('off');
                        
                        if ~(app.ContinuousmodeCheckBox.Value)
                            if  strcmp(app.Switch.Value,'Relays' )
                                writeDigitalPin(app.arduino,app.pin_2,app.Relay_off);
                                writeDigitalPin(app.arduino,app.pin_1,app.Relay_off);
                                writeDigitalPin(app.arduino,app.pin_3,app.Relay_off);
                            else
                                app.error1=send_command(app,'08',0,app.fileID_general_log,app.fileID_log_concreto); %switch off
                                app.number_errors=app.number_errors+app.error1;
                            end
                        end
                        
                        fprintf(app.fileID_general_log,'[ %f s] ',time_tele);
                        fprintf(app.fileID_log_concreto,'Time Tele: %f s\n\n',time_tele);
                        fprintf(app.fileID_log_concreto,'SWITCH OFF STATE\n');
                        fprintf(app.fileID_general_log,'OFF S');
                        
                        fclose(app.fileID_log_concreto);
                        app.LogwindowTextArea.Value =[app.LogwindowTextArea.Value;{'SWITCH OFF STATE'}];
                        app.AutoState=0;
                        %app.ProgresGauge.Value=(app.TotalTime*(app.campaign-1)+app.Meas_time+app.WarmUp_time+app.SelfTest_time+1)/(app.TotalTime*app.N_campaign)*100;
                        
                        
                        if app.campaign<app.N_campaign
                            if ~(app.ContinuousmodeCheckBox.Value)
                                app.AutoTimer.StartDelay=app.switch_off_time;
                            else
                                app.AutoTimer.StartDelay=1;
                            end
                            
                            app.campaign=app.campaign+1;
                            start(app.AutoTimer);
                            
                        else
                            
                            time_soff = toc;
                            tic
                            stop(app.AutoProgresTimer);
                            fprintf(app.fileID_general_log,'[ %f s] ',time_soff);
                            %fprintf(app.fileID_log_concreto,'Time Switch Off: %f s\n\n',time_soff);
                            fprintf(app.fileID_general_log,'\n\n');
                            app.ProgresGauge.Value=100;
                            string=sprintf('...................RESULTS......................\nNumber of errors=%i\n.................END OF MEASUREMENT....................',app.number_errors);
                            fprintf(app.fileID_general_log,string);
                            fclose(app.fileID_general_log);
                            
                            %fprintf(app.fileID_log_concreto,'Time End: %f s\n\n',time_end);
                            app.LogwindowTextArea.Value =[app.LogwindowTextArea.Value;{string}];
                            app.CancelButton.Enable='off';
                            app.StartButton_2.Enable='on';
                            if app.Meas_time==616
                                meas_t='long';
                            else
                                meas_t = 'short';
                            end
                            
                            switch app.TargetModel
                                case 'CM1'
                                    modelo='CM1';
                                case 'CM2'
                                    modelo='CM2';
                                case 'EM/EQM'
                                    modelo='EM';
                                case 'FM'
                                    modelo='FM';
                                case 'FS'
                                    modelo='FS';
                                case 'EBB'
                                    modelo='EBB';
                                case 'CM3'
                                    modelo = 'CM3';
                            end
                            
                            DrawAutoCampaignes(app,strcat(pwd,'\',app.dir_name),modelo,meas_t,'no dates','Auto');
                            %
                        end
                        
                end
            end
            pause off
        end
        
        
        function TimerCallback(app, obj, event, string_arg)
            
        end
        function ConnectTimerStopCallback(app, obj, event, string_arg)
            app.ConnectProgresGauge.Value=app.ConnectTimerCount/app.ConnectTimerTotalCount*100;
            sound(app.Sonido,16000);
        end
        
        function ConnectTimerCallback(app, obj, event, string_arg)
            app.ConnectTimerCount=app.ConnectTimerCount+1;
            app.ConnectProgresGauge.Value=app.ConnectTimerCount/app.ConnectTimerTotalCount*100;
        end
        function [error,state]=CheckState(app)
            
            app.ConfigString = strcat('egse_cli -p',app.COMPortDropDown.Value,' -b',app.BaudrateDropDown.Value );
            if strcmp(app.ModeSwitch.Value,'L')
                %                 command = strcat(app.ConfigString, ' -c ', '00','-l');  %locking command
                command = strcat(app.ConfigString, ' -c ', '00');
            else
                %                 command = strcat(app.ConfigString, ' -c ', cmd,'-nl');
                command = strcat(app.ConfigString, ' -c ', '00');
                command = strrep(command,'egse_cli','egse_cli_no_lockin');
                
            end
            
            %             command = strcat(app.ConfigString, ' -c ', cmd);
            %             fprintf(fileID_log_concreto,'Comand: %s\n',command);
            [ok,cmdout] = system(command);
            error=0;
            if ok ~= 0 %system error
                %                 fprintf(fileID_log_concreto,'SYSTEM ERROR\nComand response: %s\n',cmdout);
                %                 fprintf(fileID_general_log,'System Error ');
                error=1;
            elseif contains(cmdout,'Ack not received')
                %                 fprintf(fileID_log_concreto,'ACK NOT RECEIVED ERROR\nComand response: %s\n',cmdout);
                %                 fprintf(fileID_general_log,'ACK NOT RECEIVED ERROR ');
                error=1;
            elseif contains(cmdout,'<CRC error>')
                %                 fprintf(fileID_log_concreto,'CRC ERROR\nComand response: %s\n',cmdout);
                %                 fprintf(fileID_general_log,'CRC ERROR ');
                error=1;
            else
                lines = strsplit(deblank(cmdout), '\n');
                if length(lines)>3
                    line = replace(lines(4),'/ ','');
                    cmdline = deblank(extractAfter(line,') '));
                end
                
                if length(lines)>4
                    if length(cmd)==2  %telemetry command
                        line = replace(lines(5),'/ ','');
                    else
                        line = replace(lines(6),'/ ','');
                    end
                    ackline = deblank(extractAfter(line,') '));
                    result = split(ackline);
                    
                    size = result(2);
                    data = result(3:length(result)-2);
                    status = hex2dec(char(result(length(result)-1)));
                    crc = result(length(result));
                    
                    ok = fix(status/16);
                    status = status - 16*ok;
                    state=status;
                end
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %app.TargetModel='CM3';
            
            app.TargetModel = questdlg({'Select the target model.'
                }, ...
                'Configuration', ...
                'EBB','CM3','FS','CM3');
            
            
            app.AutoplotconfigPanel.Visible='off';
            app.ConfigPanel_2.Visible='off';
            app.COMPortDropDown.Value = 'COM9';
            app.BaudrateDropDown.Value = '57600';
            app.ConfigString = 'egse_cli -pCOM9 -b57600';
            app.StatusDS     = 1;
            app.InitComm = 0;
            app.ErrorLamp.Enable = 'off';
            app.ErrorLamp.Color = 'white';
            app.UIAxes_1.XAxis.Visible = 'off';
            app.UIAxes_2.XAxis.Visible = 'off';
            app.UIAxes_3.XAxis.Visible = 'off';
            app.UIAxes_4.XAxis.Visible = 'on';
            app.UIAxes_fft_1.XAxis.Visible = 'off';
            app.UIAxes_fft_2.XAxis.Visible = 'off';
            app.UIAxes_fft_3.XAxis.Visible = 'off';
            app.UIAxes_fft_4.XAxis.Visible = 'on';
            app.DatafileEditField.Value = FindSenseFile(app);
            app.NopButton.Enable = 'off';
            app.StatusButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.SensemodeButton.Enable = 'off';
            app.TestdataButton.Enable = 'off';
            app.SensedataButton.Enable = 'off';
            app.SwitchOffButton.Enable = 'off';
            app.SamplingFreqUserChange=0;
            app.AutoTimer=timer('TimerFcn',@app.TimerCallback,'StopFcn',@app.TimerStopCallback);
            app.AutoProgresTimer = timer('TimerFcn',@app.AutoProgresTimerCallback,'StopFcn',@app.AutoProgresTimerStopCallback);
            app.AutoProgresTimer.ExecutionMode='fixedRate';
            app.ConnectTimer=timer('TimerFcn',@app.ConnectTimerCallback,'StopFcn',@app.ConnectTimerStopCallback);
            app.ConnectTimer.ExecutionMode='fixedRate';
            app.ConnectTimer.StartDelay=1;
            app.DirEditField.Value=pwd;
            app.FilenameEditField.Value='Default';
            app.FilenameEditField.Enable='off';
            app.ConfigPanel.Visible='off';
            app.WarmUptimeC_EditField.Value=309;
            app.SensetimeC_EditField.Value=616;
            
            app.WarmUpConfig_time = 309;
            app.MeasConfig_time =  616;
            
            app.WarmUp_time  = 309;
            app.Meas_time    = 616;
            
            app.EmitterWave = 'Sine';
            app.EmitterFreq = '10 Hz';
            app.EmitterVp = 1;
            
            
            switch app.TargetModel
                case 'CM1'
                    app.ModelButtonGroup.Buttons(1).Value=1;
                case 'EM/EQM'
                    app.ModelButtonGroup.Buttons(2).Value=1;
                case 'FM'
                    app.ModelButtonGroup.Buttons(3).Value=1;
                case 'CM2'
                    app.ModelButtonGroup.Buttons(4).Value=1;
                case 'FS'
                    app.ModelButtonGroup.Buttons(5).Value=1;
                case 'CM3'
                    app.ModelButtonGroup.Buttons(6).Value=1;
                case 'EBB'
                    app.ModelButtonGroup.Buttons(7).Value=1;
            end
            
            if(strcmpi(app.TargetModel,'CM1'))||(strcmpi(app.TargetModel,'CM2')) ||(strcmpi(app.TargetModel,'CM3')) ||(strcmpi(app.TargetModel,'EBB'))
                
                
                %                 try
                %                     app.arduino = arduino('COM5','UNO');
                %                 catch
                %                 end
                
                try
                    [status, cmdout] = system("wmic path Win32_SerialPort");
                    ComPort = strsplit(cmdout, 'Arduino Uno ');
                    ComPort = ComPort{2}(2:5);
                    fclose all;
                    app.arduino = arduino(ComPort, 'Uno');
                catch ME
                    disp('Error en validación de arduino');
                end
                
                
                try
                    writeDigitalPin(app.arduino,app.pin_1,app.Relay_off);
                    writeDigitalPin(app.arduino,app.pin_2,app.Relay_off);
                    writeDigitalPin(app.arduino,app.pin_3,app.Relay_off);
                catch
                end
                app.Detector1PbSFwdLabel.Text='Detec1 PbS Fwd:';
                app.Detector2PbSeFwdLabel.Text='Detec2 PbSe Fwd:';
                app.Detector3PbSBwdLabel.Text='Detect3 PbS Bwd:';
                app.Detector4PbSeBwdLabel.Text='Detec4 PbSe Bwd:';
                
            else
                
                try
                    app.arduino = arduino();
                catch
                end
                try
                    writeDigitalPin(app.arduino,app.pin_1,app.Relay_off);
                    writeDigitalPin(app.arduino,app.pin_2,app.Relay_off);
                    writeDigitalPin(app.arduino,app.pin_3,app.Relay_off);
                catch
                end
                app.NopButton.Enable = 'on';
                app.StatusButton.Enable = 'off';
                app.StartButton.Enable = 'on';
                app.SensemodeButton.Enable = 'on';
                app.TestdataButton.Enable = 'off';
                app.SensedataButton.Enable = 'on';
                app.SwitchOffButton.Enable = 'on';
                app.StatusButton.Enable = 'on';
                
                app.Detector1PbSFwdLabel.Text='Detec1 PbSe Fwd:';
                app.Detector2PbSeFwdLabel.Text='Detec2 PbS Fwd:';
                app.Detector3PbSBwdLabel.Text='Detec3 PbSe Bwd:';
                app.Detector4PbSeBwdLabel.Text='Detec4 PbS Bwd:';
                
            end
            t=(0:1/16000:0.5);
            app.Sonido=sin(t*2*pi*500);
            
            app.VrefEditField.Value=4/pi;
            app.Vpref_seno_default=1;
            
            lCOM_Port = getAvailableComPort(app);
            app.COMPortDropDown.Items=lCOM_Port;
            Draw(app);


        end

        % Button pushed function: NopButton
        function NopButtonPushed(app, event)
            SendCmd(app,'00');
        end

        % Button pushed function: ClearlogButton
        function ClearlogButtonPushed(app, event)
            app.CommandlogTextArea.Value = '';
            app.ErrorLamp.Enable = 'off';
            app.ErrorLamp.Color = 'white';
            %             app.ErrorEditField.Value = '?';
        end

        % Button pushed function: StatusButton
        function StatusButtonPushed(app, event)
            SendCmd(app,'03');
        end

        % Value changed function: COMPortDropDown
        function COMPortDropDownValueChanged(app, event)
            value = app.COMPortDropDown.Value;
            app.ConfigString = strcat('egse_cli -p',app.COMPortDropDown.Value,' -b',app.BaudrateDropDown.Value );
        end

        % Value changed function: BaudrateDropDown
        function BaudrateDropDownValueChanged(app, event)
            value = app.BaudrateDropDown.Value;
            app.ConfigString = strcat('egse_cli -p',app.COMPortDropDown.Value,' -b',app.BaudrateDropDown.Value );
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if SendCmd(app,'02')
                if ~((strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB'))&&(app.MeasConfig_time == 0 ))
                    stop(app.ConnectTimer);
                    app.ConnectTimerCount=0;
                    app.ConnectTimerTotalCount=app.MeasConfig_time;
                    app.ConnectTimer.TasksToExecute =app.ConnectTimerTotalCount;
                    app.ConnectProgresGauge.Value=0;
                    start(app.ConnectTimer);
                end
            end
        end

        % Button pushed function: SensemodeButton
        function SensemodeButtonPushed(app, event)
            if ~(strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB'))
                if SendCmd(app,'01')
                    stop(app.ConnectTimer);
                    app.ConnectTimerCount=0;
                    app.ConnectTimerTotalCount=app.WarmUpConfig_time;
                    app.ConnectTimer.TasksToExecute=app.ConnectTimerTotalCount;
                    app.ConnectProgresGauge.Value=0;
                    start(app.ConnectTimer);
                end
            else
                pause on;
                if SendCmd(app,'09')
                    pause(0.5);
                    if SendCmd(app,'01')
                        if ~((strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB'))&&(app.WarmUpConfig_time == 0 ))
                            stop(app.ConnectTimer);
                            app.ConnectTimerCount=0;
                            app.ConnectTimerTotalCount=app.WarmUpConfig_time;
                            app.ConnectTimer.TasksToExecute=app.ConnectTimerTotalCount;
                            app.ConnectProgresGauge.Value=0;
                            start(app.ConnectTimer);
                        end
                    end
                end
                pause off;
            end
        end

        % Button pushed function: TestdataButton
        function TestdataButtonPushed(app, event)
            SendCmd(app,'05');
        end

        % Button pushed function: SensedataButton
        function SensedataButtonPushed(app, event)
            
            folder_name = 'sens';
            % time_stamp=datestr(now,'dd-mm-yy_HH_MM_SS');
            dir_name=char(strcat(folder_name,'_',string(datetime('now','Format','yyyy_MM_dd_HH_mm_ss'))));
            mkdir(dir_name);
            
            if strcmp(app.FilenameEditField.Value,'Default')
                app.LastFile=char(strcat('sens_',string(datetime('now','Format','yyyy_MM_dd_HH_mm_ss')),'.m'))  ;
            else
                if contains(app.FilenameEditField.Value,'.m')
                    app.LastFile=app.FilenameEditField.Value;
                else
                    app.LastFile=char(strcat(app.FilenameEditField.Value,'.m'));
                end
            end
            path = strcat(app.DirEditField.Value,'\',dir_name)
            cmd = char(strcat('04 -d ',path,'\',app.LastFile));
            err=SendCmd(app,cmd);
            %             app.DatafileEditField.Value = FindSenseFile(app);
            
            if err
                addpath(path);
                if exist(app.LastFile,'file')
                    app.DatafileEditField.Value=app.LastFile
                    Draw(app);
                    Export_and_Save_Data(app,'On',path);
                end
            end
            
            if strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB')
                fileID_campaign_config_log = fopen(strcat(pwd,'\',dir_name,'\',dir_name,'_config_log.txt'),'a');
                fprintf(fileID_campaign_config_log,strcat('.................',dir_name,'...................\n\n'));
                fprintf(fileID_campaign_config_log,'\n....CONFIGURATION....\n');
                fprintf(fileID_campaign_config_log,'Model: %s\n', app.TargetModel);
                fprintf(fileID_campaign_config_log,'WarmUp Time: %i s\n', app.WarmUpConfig_time);
                fprintf(fileID_campaign_config_log,'Measurement Time: %i s\n', app.MeasConfig_time);
                fprintf(fileID_campaign_config_log,'Emitter Frequency: %s \n', app.EmitterFreq);
                fprintf(fileID_campaign_config_log,'Emitter Vp: %i V\n', app.EmitterVp);
                fprintf(fileID_campaign_config_log,'Emitter Wave: %s \n', app.EmitterWave);
            end
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            Draw(app);
        end

        % Callback function
        function SampleButtonPushed(app, event)
            
        end

        % Value changed function: V1CheckBox
        function V1CheckBoxValueChanged(app, event)
            Draw(app);
        end

        % Value changed function: V2CheckBox
        function V2CheckBoxValueChanged(app, event)
            Draw(app);
        end

        % Value changed function: V3CheckBox
        function V3CheckBoxValueChanged(app, event)
            Draw(app);
        end

        % Value changed function: V4CheckBox
        function V4CheckBoxValueChanged(app, event)
            Draw(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            lCOM_Port = getAvailableComPort(app);%
            app.COMPortDropDown.Items=lCOM_Port;
            if strcmp(app.TargetModel,'CM1')|| strcmp(app.TargetModel,'CM2')|| strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB')
                try
                    app.arduino = arduino();
                catch
                end
            end
            Draw(app);
            
        end

        % Value changed function: SignedCheckBox
        function SignedCheckBoxValueChanged(app, event)
            Draw(app);
        end

        % Button pushed function: ButtonBrowse
        function ButtonBrowsePushed(app, event)
            [filename,path] = uigetfile('sens*.m');
            
            if filename ~= 0
                addpath(path);
                app.DatafileEditField.Value = filename;
                Draw(app);
            end
        end

        % Button pushed function: SwitchOffButton
        function SwitchOffButtonPushed(app, event)
            SendCmd(app,'08');
        end

        % Value changed function: SampleSwitch_2
        function SampleSwitch_InitValueChanged(app, event)
            if strcmpi(app.SampleSwitch_2.Value, 'On')
                app.NopButton.Enable = 'on';
                app.StatusButton.Enable = 'on';
                app.StartButton.Enable = 'on';
                app.SensemodeButton.Enable = 'on';
                app.TestdataButton.Enable = 'off';
                app.SensedataButton.Enable = 'on';
                app.SwitchOffButton.Enable = 'on';
                
                if strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB')
                    
                    app.ReadConfigButton.Enable = 'on';
                else
                    app.ReadConfigButton.Enable = 'off';
                end
                
                try
                    app.arduino = arduino();
                catch
                end
                
                %               try
                %                 [status, cmdout] = system("wmic path Win32_SerialPort");
                %                 ComPort = strsplit(cmdout, 'Arduino Uno ');
                %                 ComPort = ComPort{2}(2:5);
                %                 fclose app.arduino;
                %                 clear app.arduino;
                %                 app.arduino = arduino(ComPort, 'Uno');
                %               catch ME
                %                 disp('Error en validación de arduino');
                %               end
                
                
                try
                    writeDigitalPin(app.arduino,app.pin_1,app.Relay_on);
                    writeDigitalPin(app.arduino,app.pin_3,app.Relay_on);
                    writeDigitalPin(app.arduino,app.pin_2,app.Relay_on);
                catch
                end
                
            elseif strcmpi(app.SampleSwitch_2.Value, 'Off')
                app.NopButton.Enable = 'off';
                app.StatusButton.Enable = 'off';
                app.StartButton.Enable = 'off';
                app.SensemodeButton.Enable = 'off';
                app.TestdataButton.Enable = 'off';
                app.SensedataButton.Enable = 'off';
                app.SwitchOffButton.Enable = 'off';
                app.ReadConfigButton.Enable = 'off';
                
                try
                    app.arduino = arduino();
                catch
                end
                
                %               try
                %                 [status, cmdout] = system("wmic path Win32_SerialPort");
                %                 ComPort = strsplit(cmdout, 'Arduino Uno ');
                %                 ComPort = ComPort{2}(2:5);
                %                 fclose app.arduino;
                %                 clear app.arduino;
                %                 app.arduino = arduino(ComPort, 'Uno');
                %               catch ME
                %                 disp('Error en validación de arduino');
                %               end
                
                try
                    writeDigitalPin(app.arduino,app.pin_1,app.Relay_off);
                    writeDigitalPin(app.arduino,app.pin_2,app.Relay_off);
                    writeDigitalPin(app.arduino,app.pin_3,app.Relay_off);
                catch
                end
                
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            h = msgbox({'DS-M3T30 Help'; 'In order to complete a successful trial, please follow the next steps:';
                '1. Press "Sense Mode" Button - DS will be in Warming-Up State for specific time';
                '2. Press "Start" Button - DS will be in Sensing State for specific time';
                '3. Press "Sense Data" Button - DS will transmit all the data adquired. New "sens_" file is created';
                '4. Press "Switch Off" Button - DS will be back on Idle mode';
                '5. Repeat from 1';
                'Note: for continuous measurements, after step 3 repeat step 2 and 3 in loop'}, 'Help','help');
        end

        % Button pushed function: FigureButton
        function FigureButtonPushed(app, event)
            if isempty(app.DatafileEditField.Value)
                return;
            end
            run(app.DatafileEditField.Value);
            if  (exist('sensors','var'))
                fig = figure('Name','Detectors','NumberTitle','off');
                tabgp = uitabgroup(fig);
                sampling_freq = app.SamplingFreqHzEditField.Value;
                
                scale = (app.YmaxEditField.Value-app.YminEditField.Value)/(2^app.BitsEditField.Value);
                
                if app.SignedCheckBox.Value
                    if app.SignedCheckBox.Value
                        for i = 1:size(sensors,1)
                            for j = 1:size(sensors,2)
                                if sensors(i,j) >= 2^(app.BitsEditField.Value-1)
                                    sensors(i,j) = sensors(i,j) - 2^app.BitsEditField.Value;
                                end
                            end
                        end
                        sensors = sensors + 2^(app.BitsEditField.Value-1);
                    end
                end
                voltages = sensors*scale + app.YminEditField.Value;
                l = length(voltages(1,:));
                x = linspace(0,(l-1)/sampling_freq,l);
                if app.V1CheckBox.Value
                    tab_D1 = uitab(tabgp,'Title',app.Detector1PbSFwdLabel.Text);
                    ax1 = axes(tab_D1);
                    d1 = voltages(1,:);
                    plot(ax1,x,d1,'r');
                    xlabel('Time[s]');
                    ylabel('Volts');
                    grid on;
                end
                if app.V2CheckBox.Value
                    tab_D2 = uitab(tabgp,'Title', app.Detector2PbSeFwdLabel.Text);
                    ax2 = axes(tab_D2);
                    d2 = voltages(2,:);
                    plot(ax2,x,d2,'g');
                    xlabel('Time[s]');
                    ylabel('Volts');
                    grid on;
                end
                if app.V3CheckBox.Value
                    tab_D3 = uitab(tabgp,'Title', app.Detector3PbSBwdLabel.Text);
                    ax3 = axes(tab_D3);
                    d3 = voltages(3,:);
                    plot(ax3,x,d3,'b');
                    xlabel('Time[s]');
                    ylabel('Volts');
                    grid on;
                end
                if app.V4CheckBox.Value
                    tab_D4 = uitab(tabgp,'Title',app.Detector4PbSeBwdLabel.Text);
                    ax4 = axes(tab_D4);
                    d4 = voltages(4,:);
                    plot(ax4,x,d4,'k');
                    xlabel('Time[s]');
                    ylabel('Volts');
                    grid on;
                end
            else
                if ~exist('sensors_x','var')
                    return
                end
                if ~exist('sensors_y','var')
                    return
                end


                sampling_freq = app.SamplingFreqHzEditField.Value;
                if strcmp(app.RefSwitch.Value,'Seno')
                    scale = (app.YmaxEditField.Value-app.YminEditField.Value)^2/(2^app.BitsEditField.Value);
                else
                    scale = (app.YmaxEditField.Value-app.YminEditField.Value)/(2^app.BitsEditField.Value);
                end
                if app.SignedCheckBox.Value
                    for i = 1:size(sensors_x,1)
                        for j = 1:size(sensors_x,2)
                            if sensors_x(i,j) >= 2^(app.BitsEditField.Value-1)
                                sensors_x(i,j) = sensors_x(i,j) - 2^app.BitsEditField.Value;
                            end
                        end
                    end
                end
                
                if app.SignedCheckBox.Value
                    for i = 1:size(sensors_y,1)
                        for j = 1:size(sensors_y,2)
                            if sensors_y(i,j) >= 2^(app.BitsEditField.Value-1)
                                sensors_y(i,j) = sensors_y(i,j) - 2^app.BitsEditField.Value;
                            end
                        end
                    end
                end
                voltages_x = sensors_x*scale;
                voltages_y = sensors_y*scale;
                R=sqrt((voltages_x.^2)+(voltages_y.^2));
                Vp=R*2/app.VrefEditField.Value;
                
                l = length(voltages_x(1,:));
                x = linspace(0,(l-1)/sampling_freq,l);
                
                fig = figure('Name','Detectors','NumberTitle','off');
                tabgp = uitabgroup(fig);
                
                if app.V1CheckBox.Value
                    tab_D1 = uitab(tabgp,'Title',app.Detector1PbSFwdLabel.Text);
                    ax1 = axes(tab_D1);
                    d1_vp=Vp(1,:);
                    plot(ax1,x,d1_vp,'r');
                    hold(ax1,'on');
                    xlabel('Time[s]');
                    ylabel('Volts');
                    grid on;
                    legend(ax1,{'Vp'});
                end
                if app.V2CheckBox.Value
                    tab_D2 = uitab(tabgp,'Title',app.Detector2PbSeFwdLabel.Text);
                    ax2 = axes(tab_D2);
                    d2_vp=Vp(2,:);
                    plot(ax2,x,d2_vp,'r');
                    hold(ax2,'on');
                    xlabel('Time[s]');
                    ylabel('Volts');
                    grid on;
                    legend(ax2,{'Vp'});
                end
                if app.V3CheckBox.Value
                    tab_D3 = uitab(tabgp,'Title',app.Detector3PbSBwdLabel.Text);
                    ax3 = axes(tab_D3);
                    d3_vp=Vp(3,:);
                    plot(ax3,x,d3_vp,'r');
                    hold(ax3,'on');
                    xlabel('Time[s]');
                    ylabel('Volts');
                    grid on;
                    legend(ax3,{'Vp'});
                end
                if app.V4CheckBox.Value
                    tab_D4 = uitab(tabgp,'Title',app.Detector4PbSeBwdLabel.Text);
                    ax4 = axes(tab_D4);
                    d4_vp=Vp(4,:);
                    plot(ax4,x,d4_vp,'r');
                    hold(ax4,'on');
                    xlabel('Time[s]');
                    ylabel('Volts');
                    grid on;
                    legend(ax4,{'Vp'});
                end


            end
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            tic
            pause on;
            choice='No';
            choice = questdlg({'Ensure that the DS is in the mode (Lock in/Non Lock in) configured in the connect tab.';
                'Do you want to continue?'}, ...
                'WARNING', ...
                'Yes','No','No');
            % Handle response
            
            if(strcmpi(choice,'Yes'))
                app.CancelButton.Enable='on';
                app.StartButton_2.Enable='off';
                app.LogwindowTextArea.Value ={''};
                app.SelfTest_time   = app.SelftesttimeEditField.Value;
                app.WarmUp_time     = app.WarmuptimeEditField.Value;
                app.Meas_time       = app.MeasurementtimeEditField.Value;
                app.switch_off_time = app.SwitchofftimeEditField.Value;
                app.N_campaign      = app.NofcampaignsEditField.Value;
                app.Name            = app.NameEditField.Value;
                app.AutoFirstRun    = 0;
                
                app.TotalTime= (app.SelfTest_time+ app.WarmUp_time+app.Meas_time+app.switch_off_time);
                
                time_stamp=datestr(now,'dd-mm-yy_HH_MM_SS');
                app.dir_name=strcat(app.Name,'_',time_stamp);
                mkdir(app.dir_name);
                app.fileID_general_log = fopen(strcat(pwd,'\',app.dir_name,'\',app.Name,'_log.txt'),'a');
                fprintf(app.fileID_general_log,strcat('.................',app.Name,'...................\n\n'));
                fprintf(app.fileID_general_log,'\n....CONFIGURATION....\n');
                fprintf(app.fileID_general_log,'Self-Test Time: %i s\n', app.SelfTest_time);
                fprintf(app.fileID_general_log,'WarmUp Time: %i s\n', app.WarmUp_time);
                fprintf(app.fileID_general_log,'Measurement Time: %i s\n', app.Meas_time);
                fprintf(app.fileID_general_log,'Switch Off Time: %i s\n\n', app.switch_off_time);
                fprintf(app.fileID_general_log,'Model: %s\n', app.TargetModel);
                fprintf(app.fileID_general_log,'Auto mode: %s\n', app.Switch.Value);
                
                if(app.ContinuousmodeCheckBox.Value)
                    fprintf(app.fileID_general_log,'Continuous mode: On\n\n');
                else
                    fprintf(app.fileID_general_log,'Continuous mode: Off\n\n');
                end
                %to do....(freq, wave, vp)
                
                %         diary(strcat(pwd,'\',dir_name,'\EIS_complet_log.txt'))
                app.number_errors=0;
                app.error1=0;
                Result = 1;
                
                %%Turn off the system
                try
                    app.arduino = arduino();
                catch
                end
                
                try
                    writeDigitalPin(app.arduino,app.pin_1,app.Relay_off);
                    writeDigitalPin(app.arduino,app.pin_2,app.Relay_off);
                    writeDigitalPin(app.arduino,app.pin_3,app.Relay_off);
                catch
                end
                
                %If Cmd mode, turn on and configure the system
                if(strcmp(app.Switch.Value,'Cmd'))
                    try
                        writeDigitalPin(app.arduino,app.pin_1,app.Relay_on);
                        writeDigitalPin(app.arduino,app.pin_2,app.Relay_on);
                        writeDigitalPin(app.arduino,app.pin_3,app.Relay_on);
                    catch
                    end
                    
                    if strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB')
                        pause (1);
                        Result = ApplyAutoConfig(app);
                        if ~(Result == 1)            %If the configuration fails, cancel the process
                            app.AutoStop=1;
                            stop(app.AutoTimer);
                            
                            app.ProgresGauge.Value=0;
                            app.CancelButton.Enable='off';
                            app.StartButton_2.Enable='on';
                            try
                                writeDigitalPin(app.arduino,app.pin_1,app.Relay_off);
                                writeDigitalPin(app.arduino,app.pin_2,app.Relay_off);
                                writeDigitalPin(app.arduino,app.pin_3,app.Relay_off);
                            catch
                            end
                            app.LogwindowTextArea.Value =[app.LogwindowTextArea.Value;{'Configuration Error'}];
                            fclose(app.fileID_general_log);
                            
                        else
                            app.LogwindowTextArea.Value =[app.LogwindowTextArea.Value;{'Configuration Success'}];
                        end
                    end
                end
                if (Result == 1)
                    app.AutoState=0;
                    app.campaign=1;
                    %             app.AutoContinue=1;
                    app.AutoStop=1;
                    app.ProgresGauge.Value=0;
                    
                    if(app.ContinuousmodeCheckBox.Value)
                        app.AutoProgresTimerTotalCount = (app.Meas_time  +5) * app.N_campaign + app.SelfTest_time + app.WarmUp_time;
                    else
                        app.AutoProgresTimerTotalCount = (app.TotalTime + 3) * app.N_campaign - app.switch_off_time;
                    end
                    
                    
                    app.AutoProgresTimer.TasksToExecute = app.AutoProgresTimerTotalCount + 30;
                    app.AutoProgresTimerCount = 0;
                    app.ProgresTimeEditField.Value = sprintf('%d / %d s',app.AutoProgresTimerCount,app.AutoProgresTimerTotalCount);
                    stop(app.AutoTimer);
                    app.AutoStop=0;
                    app.AutoTimer.StartDelay=1;
                    time_st = toc
                    tic
                    fprintf(app.fileID_general_log,'Time Start: %f s\n\n',time_st);
                    start(app.AutoTimer);
                    
                end
                
            end
            
            pause off;
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            choice='No';
            choice = questdlg('Are you sure you want to cancel the procress?', ...
                'WARNING', ...
                'Yes','No','No');
            % Handle response
            
            if(strcmpi(choice,'Yes'))
                app.AutoStop=1;
                stop(app.AutoTimer);
                stop(app.AutoProgresTimer);
                app.ProgresGauge.Value=0;
                app.CancelButton.Enable='off';
                app.StartButton_2.Enable='on';
                try
                    writeDigitalPin(app.arduino,app.pin_1,app.Relay_off);
                    writeDigitalPin(app.arduino,app.pin_2,app.Relay_off);
                    writeDigitalPin(app.arduino,app.pin_3,app.Relay_off);
                catch
                end
                fprintf(app.fileID_general_log,'\n\n');
                string=sprintf('...................RESULTS......................\nNumber of errors=%i\n.................END OF MEASUREMENT(Cancelled)....................',app.number_errors);
                fprintf(app.fileID_general_log,string);
                app.LogwindowTextArea.Value =sprintf('Process Cancelled\n');
                app.LogwindowTextArea.Value =sprintf('Number of campaignes: %i',(app.campaign-1));
                app.LogwindowTextArea.Value =[app.LogwindowTextArea.Value;{string}];
                fclose(app.fileID_general_log);
                fclose(app.fileID_log_concreto);
                
                if ( app.campaign > 1)
                    if app.Meas_time==616
                        meas_t='long';
                    else
                        meas_t = 'short';
                    end
                    
                    switch app.TargetModel
                        case 'CM1'
                            modelo='CM1';
                        case 'CM2'
                            modelo='CM2';
                        case 'EM/EQM'
                            modelo='EM';
                        case 'FM'
                            modelo='FM';
                        case 'FS'
                            modelo='FS';
                        case 'EBB'
                            modelo = 'EBB';
                        case 'CM3'
                            modelo = 'CM3';
                    end
                    
                    DrawAutoCampaignes(app,strcat(pwd,'\',app.dir_name),modelo,meas_t,'no dates','Auto');
                end
            end
        end

        % Close request function: egse_guiUIFigure
        function egse_guiUIFigureCloseRequest(app, event)
            app.AutoStop=1;
            stop(app.ConnectTimer);
            stop(app.AutoTimer);
            stop(app.AutoProgresTimer);
            delete(app.AutoTimer);
            delete(app.ConnectTimer);
            delete(app);
        end

        % Value changed function: SamplingFreqHzEditField
        function SamplingFreqHzEditFieldValueChanged(app, event)
            %             value = app.SamplingFreqHzEditField.Value;
            app.SamplingFreqUserChange=1;
            Draw(app);
            
        end

        % Value changed function: YmaxEditField
        function YmaxEditFieldValueChanged(app, event)
            Draw(app);
        end

        % Value changed function: YminEditField
        function YminEditFieldValueChanged(app, event)
            %             value = app.YminEditField.Value;
            Draw(app);
        end

        % Value changed function: BitsEditField
        function BitsEditFieldValueChanged(app, event)
            %             value = app.BitsEditField.Value;
            Draw(app);
        end

        % Value changed function: VrefEditField
        function VrefEditFieldValueChanged(app, event)
            %            value = app.VrefEditField.Value;
            Draw(app);
        end

        % Value changed function: RefSwitch
        function RefSwitchValueChanged(app, event)
            %             value = app.RefSwitch.Value;
            
            
            if strcmp(app.RefSwitch.Value,'Sqr')
                switch app.WaveEmitRDropDown.Value
                    
                    case 'Sine' %Sine [Sine^2]
                        app.VrefEditField.Value = 4/pi;
                    case 'Square' %Square [Square]
                        app.VrefEditField.Value =  (pi^2)/(16*1.2337);
                    case 'Sqrt Sine' %Square root of sine [Sine]
                        app.VrefEditField.Value =  4/pi;
                end
            else
                
                app.VrefEditField.Value = 1;
            end
            
            Draw(app);
        end

        % Value changed function: CheckBoxFileName
        function CheckBoxFileNameValueChanged(app, event)
            %             value = app.CheckBoxFileName.Value;
            if app.CheckBoxFileName.Value
                app.FilenameEditField.Enable='on';
            else
                app.FilenameEditField.Enable='off';
                app.FilenameEditField.Value='Default';
            end
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            %             value = app.FilenameEditField.Value;
            if contains(app.FilenameEditField.Value,'.m')
                name=app.FilenameEditField.Value;
            else
                name=char(strcat(app.FilenameEditField.Value,'.m'));
            end
            if exist(name,'file')
                msgbox('File already exists. Please change it or it will be overwrite. ','WARNING');
            end
        end

        % Button pushed function: ButtonBrowseDir
        function ButtonBrowseDirPushed(app, event)
            path=uigetdir(app.DirEditField.Value);
            if path~=0
                addpath(path);
                app.DirEditField.Value=path;
            end
        end

        % Button pushed function: ConfigButton
        function ConfigButtonPushed(app, event)
            if strcmp(app.ConfigPanel.Visible,'on')
                app.ConfigInfoPanel.Visible   = 'off';
                app.ConfigPanel.Visible       = 'off';
            else
                app.ConfigPanel.Visible       = 'on';
            end
        end

        % Callback function
        function TimeSwitchValueChanged(app, event)
            if strcmp(app.TimeSwitch.Value,'Long')
                app.WarmUptimeC_EditField.Value=309;
                app.SensetimeC_EditField.Value=616;
            else
                app.WarmUptimeC_EditField.Value=10;
                app.SensetimeC_EditField.Value=60;
            end
            
        end

        % Button pushed function: CloseConfigButton
        function CloseConfigButtonPushed(app, event)
            app.ConfigInfoPanel.Visible   = 'off';
            app.ConfigPanel.Visible       = 'off';
        end

        % Button pushed function: TButton
        function TButtonPushed(app, event)
            if isempty(app.DatafileEditField.Value)
                return;
            end
            run(app.DatafileEditField.Value);
            fig = figure('Name','House keeping Temperature');
            
            data_length = length(sensors_x);
            data_length_HK = length(house_keep(1,1:end-1));
            
            if (strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB')) && ~ (data_length == 1245)
                x = (1:data_length_HK) %%to do
            else
                x=(1:data_length_HK)/82*616;
            end
            
            switch app.TargetModel
                case {'CM1', 'CM2', 'CM3', 'EBB'}
                    if strcmp(app.TargetModel,'CM1')
                        t1=(house_keep(2,1:end-1)*5/2^16-1.072)/0.02882;
                        t2=(house_keep(3,1:end-1)*5/2^16-1.072)/0.02882;
                        
                    else
                        if strcmp(app.TargetModel,'CM2') || strcmp(app.TargetModel,'EBB')
                            t1=(house_keep(1,1:end-1)*5/2^16-1.072)/0.02882;
                            t2=(house_keep(3,1:end-1)*5/2^16-1.072)/0.02882;
                        else
                            
                            %t1=(house_keep(1,1:end-1)*5/2^16-1.072)/0.02882;
                            
                            Vo_t1(:) = house_keep(1,1:end-1)*5/2^16;
                            Vo_t2(:) = house_keep(3,1:end-1)*5/2^16;
                            %Vo_t3(:) = house_keep(4,1:end-1)*5/2^16;
                            
                            if strcmp(app.FMTempcorrectionVcc2Switch.Value,'On')
                                Vcc_2(:) = house_keep(2,1:end-1)*5/2^16;
                                m(:) = (19.905/1000) + (7.960/1000) *(Vcc_2(:)- 2.5);
                                b(:) = 2.915 + 1.165 * (Vcc_2(:)- 2.5);
                                
                                t1 = (Vo_t1(:)-b(:))./m(:);
                                t2 = (Vo_t2(:)-b(:))./m(:);
                                
                            else
                                %Asumimos Vcc/2 = 2.5 V;
                                m = 19.905/1000;
                                b = 2.915;
                                
                                t1 = (Vo_t1(:)-b)/m;
                                t2 = (Vo_t2(:)-b)/m;
                            end
                            
                            
                            %t3 = (Vo_t3(:)-b(:))./m(:);
                        end
                        
                        
                    end
                    
                    if ~(strcmp(app.TargetModel,'CM3'))
                        
                        t3=(house_keep(4,1:end-1)*5/2^16-1.072)/0.02882;
                        
                        plot(x,t1,'r');
                        grid on;
                        hold on;
                        plot(x,t2,'b');
                        plot(x,t3,'k');
                        ylabel('Temperature[ºC]');
                        legend('Detectors Fwd','Detectors Bwd','Board');
                        
                    else
                        t3=(house_keep(4,1:end-1)*5/2^16-1.072)/0.02882;
                        plot(x,t1,'r');
                        grid on;
                        hold on;
                        plot(x,t3,'k');
                        ylabel('Temperature[ºC]');
                        legend('Detectors Fwd','Board');
                        
                    end
                    
                    
                    if (strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB')) && ~ (data_length == 1245)
                        xlabel('Samples'); %to do
                    else
                        xlabel('Time[s]');
                    end
                    
                    
                case 'EM/EQM'
                    t1=(house_keep(1,1:end-1)*5/2^16-2.988)*41.437;
                    t2=(house_keep(3,1:end-1)*5/2^16-2.988)*41.437;
                    plot(x,t1,'r');
                    xlabel('Time[s]');
                    ylabel('Temperature[ºC]');
                    grid on;
                    hold on;
                    plot(x,t2,'b');
                    legend('Detectors Fwd','Detectors Bwd');
                    
                    
                case 'FM'
                    if strcmp(app.FMTempcorrectionVcc2Switch.Value,'Off')   %Temperature without correction
                        t1=(house_keep(1,1:end-1)*5/2^16-3.024)/0.02452; %FM
                        t2=(house_keep(3,1:end-1)*5/2^16-2.481)/0.01983;
                    else
                        %                     Temperature correction
                        t1=(house_keep(1,1:end-1)*5/2^16-(3.103+1.217*(house_keep(2,1:end-1)*5/2^16-2.55)))./(25.01e-3+9.81e-3*(house_keep(2,1:end-1)*5/2^16-2.55));
                        t2=(house_keep(3,1:end-1)*5/2^16-(2.531+0.993*(house_keep(2,1:end-1)*5/2^16-2.55)))./(20.229e-3+7.933e-3*(house_keep(2,1:end-1)*5/2^16-2.55));
                        
                    end
                    plot(x,t1,'r');
                    xlabel('Time[s]');
                    ylabel('Temperature[ºC]');
                    grid on;
                    hold on;
                    plot(x,t2,'b');
                    legend('Detectors Fwd','Board');
                    
                case 'FS'
                    %FM2
                    %t1=(house_keep(1,1:end-1)*5/2^16-(2.965+1.16*(house_keep(2,1:end-1)*5/2^16-2.55)))./(20.314e-3+7.967e-3*(house_keep(2,1:end-1)*5/2^16-2.55));
                    %t2=(house_keep(3,1:end-1)*5/2^16-(2.965+1.16*(house_keep(2,1:end-1)*5/2^16-2.55)))./(20.314e-3+7.967e-3*(house_keep(2,1:end-1)*5/2^16-2.55));
                    
                    %FM3
                    t1=(house_keep(1,1:end-1)*5/2^16-(3.108+1.219*(house_keep(2,1:end-1)*5/2^16-2.55)))./(20.939e-3+7.527e-3*(house_keep(2,1:end-1)*5/2^16-2.55)); %Fwd
                    t2=(house_keep(3,1:end-1)*5/2^16-(2.965+1.16*(house_keep(2,1:end-1)*5/2^16-2.55)))./(20.314e-3+7.967e-3*(house_keep(2,1:end-1)*5/2^16-2.55)); %Board
                    
                    plot(x,t1,'r');
                    xlabel('Time[s]');
                    ylabel('Temperature[ºC]');
                    grid on;
                    hold on;
                    plot(x,t2,'b');
                    legend('Detectors Fwd','Board');
            end


        end

        % Button pushed function: VButton
        function VButtonPushed(app, event)
            if isempty(app.DatafileEditField.Value)
                return;
            end
            run(app.DatafileEditField.Value);
            fig = figure('Name','House keeping Voltage/2');
            %                 tabgp = uitabgroup(fig);
            %                 sampling_freq = 1/30;
            %             l = length(house_keep(1,1:end-1));
            %             x = linspace(0,(l-1)/sampling_freq,l);
            
            data_length = length(sensors_x);
            data_length_HK = length(house_keep(1,1:end-1));
            
            if (strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB'))&& ~ (data_length == 1245)
                x=1:data_length_HK;
            else
                x=(1:data_length_HK)/82*616;
            end
            
            %               tab_D1 = uitab(tabgp,'Title','House keeping Temeprature');
            %               ax1 = axes(tab_D1);
            %               d1 = voltages(1,:);
            if(strcmp(app.TargetModel,'CM1'))
                v=house_keep(1,1:end-1)*5/2^16;
            else
                v=house_keep(2,1:end-1)*5/2^16;
            end
            mean_v=mean(v);
            std_v=std(v);
            plot(x,v,'r');
            ylabel('V');
            grid on;
            hold on;
            legend({sprintf('Media:%i, Desviacion:%i',mean_v,std_v)});
            if (strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB')) && ~ (data_length == 1245)
                xlabel('Samples'); %to do
            else
                xlabel('Time[s]');
            end
            
        end

        % Selection changed function: ModelButtonGroup
        function ModelButtonGroupSelectionChanged(app, event)
            app.TargetModel = app.ModelButtonGroup.SelectedObject.Text;


            if strcmp(app.TargetModel,'CM1')|| strcmp(app.TargetModel,'CM2') || strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB')
                
                app.Detector1PbSFwdLabel.Text='Detec1 PbS Fwd:';
                app.Detector2PbSeFwdLabel.Text='Detec2 PbSe Fwd:';
                app.Detector3PbSBwdLabel.Text='Detect3 PbS Bwd:';
                app.Detector4PbSeBwdLabel.Text='Detec4 PbSe Bwd:';
                
            else
                % same config for EM,EQM ,FM and FS
                app.Detector1PbSFwdLabel.Text='Detec1 PbSe Fwd:';
                app.Detector2PbSeFwdLabel.Text='Detec2 PbS Fwd:';
                app.Detector3PbSBwdLabel.Text='Detec3 PbSe Bwd:';
                app.Detector4PbSeBwdLabel.Text='Detec4 PbS Bwd:';
            end
            
            %If CM3 is selected enable the parameters configuration options
            if strcmp(app.TargetModel,'CM3') || strcmp(app.TargetModel,'EBB')
                
                
                app.ConfigApplyButton.Enable = 'on';
                if strcmpi(app.SampleSwitch_2.Value, 'On')
                    app.ReadConfigButton.Enable = 'on';
                else
                    app.ReadConfigButton.Enable = 'off';
                end
                
            else
                
                app.ConfigApplyButton.Enable = 'off';
                app.ReadConfigButton.Enable = 'off';
                
                app.WarmUpConfig_time = 309;
                app.MeasConfig_time = 616;
                
                
            end


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            switch app.TargetModel
                case 'CM1'
                    modelo='CM1';
                case 'CM2'
                    modelo='CM2';
                case 'EM/EQM'
                    modelo='EM';
                case 'FM'
                    modelo='FM';
                case 'FS'
                    modelo='FS';
                case 'CM3'
                    modelo='CM3';
                case 'EBB'
                    modelo='EBB';
            end
            
            path=pwd;
            files_dir=uigetdir(path);
            
            DrawAutoCampaignes(app,files_dir,modelo,'long','no dates','Auto');
        end

        % Button pushed function: PhaseButton
        function PhaseButtonPushed(app, event)
            run(app.DatafileEditField.Value);
            if ~exist('sensors_x','var')
                return
            end
            if ~exist('sensors_y','var')
                return
            end
            
            if app.SignedCheckBox.Value
                for i = 1:size(sensors_x,1)
                    for j = 1:size(sensors_x,2)
                        if sensors_x(i,j) >= 2^(app.BitsEditField.Value-1)
                            sensors_x(i,j) = sensors_x(i,j) - 2^app.BitsEditField.Value;
                            %                                 sensors_y(i,j) = sensors_y(i,j) - 2^app.BitsEditField.Value;
                        end
                    end
                end
                %sensors_x = sensors_x + 2^(app.BitsEditField.Value-1);
                %                     sensors_y = sensors_y + 2^(app.BitsEditField.Value-1);
            end
            
            if app.SignedCheckBox.Value
                for i = 1:size(sensors_y,1)
                    for j = 1:size(sensors_y,2)
                        if sensors_y(i,j) >= 2^(app.BitsEditField.Value-1)
                            %                                 sensors_(i,j) = sensors_x(i,j) - 2^app.BitsEditField.Value;
                            sensors_y(i,j) = sensors_y(i,j) - 2^app.BitsEditField.Value;
                        end
                    end
                end
                % sensors_x = sensors_x + 2^(app.BitsEditField.Value-1);
                %                     sensors_y = sensors_y + 2^(app.BitsEditField.Value-1);
            end
            %                 voltages_x = sensors_x*scale;%-((app.YmaxEditField.Value-app.YminEditField.Value)*2)/2;
            %                 voltages_y = sensors_y*scale;%-((app.YmaxEditField.Value-app.YminEditField.Value)*2)/2;
            
            Phase(1,:)=atand(sensors_y(1,:)./sensors_x(1,:));
            Phase(2,:)=atand(sensors_y(2,:)./sensors_x(2,:));
            Phase(3,:)=atand(sensors_y(3,:)./sensors_x(3,:));
            Phase(4,:)=atand(sensors_y(4,:)./sensors_x(4,:));
            
            
            if app.SamplingFreqUserChange==0
                if app.SamplingFreqHzEditField.Value~=2 && app.SamplingFreqHzEditField.Value~=1000
                    fs = app.SamplingFreqHzEditField.Value;
                else
                    app.SamplingFreqHzEditField.Value=2;
                    fs=2;
                end
            end
            
            l = length(sensors_x(1,:));
            x = linspace(0,(l-1)/fs,l);


            fig = figure('Name','Phase');
            xlabel('Time[s]');
            ylabel('Phase[º]');
            grid on;
            hold on;
            plot(x,Phase(1,:),'red');
            plot(x,Phase(2,:),'green');
            plot(x,Phase(3,:),'blue');
            plot(x,Phase(4,:),'black');
            legend(app.Detector1PbSFwdLabel.Text,app.Detector2PbSeFwdLabel.Text,app.Detector3PbSBwdLabel.Text,app.Detector4PbSeBwdLabel.Text);
            
        end

        % Button pushed function: ConfigButton_2
        function ConfigButton_2Pushed(app, event)
            if strcmp(app.ConfigPanel_2.Visible,'on')
                app.ConfigPanel_2.Visible='off';
            else
                app.ConfigPanel_2.Visible='on';
            end
        end

        % Button pushed function: ExportdataButton
        function ExportdataButtonPushed(app, event)
            
            save_data = app.SaveExportedDataSwitch.Value;
            Export_and_Save_Data(app,save_data,pwd);
            
        end

        % Value changed function: XCheckBox
        function XCheckBoxValueChanged(app, event)
            Draw(app);
        end

        % Button pushed function: ConfigButton_3
        function ConfigButton_3Pushed(app, event)
            if strcmp(app.AutoplotconfigPanel.Visible,'on')
                app.AutoplotconfigPanel.Visible='off';
            else
                app.AutoplotconfigPanel.Visible='on';
            end
        end

        % Button pushed function: ConfigApplyButton
        function ApplyConfigButtonPushed(app, event)
            ApplyConfig(app);
        end

        % Button pushed function: ReadConfigButton
        function ReadConfigButtonPushed(app, event)
            SendCmd(app,'09');
            
        end

        % Value changed function: WaveEmitRDropDown
        function WaveEmitRValueChange(app, event)
            %value = app.WaveEmitRDropDown.Value;
            
            
            if strcmp(app.RefSwitch.Value,'Sqr')
                
                if strcmp(app.WaveEmitRDropDown.Value,'Square')
                    app.VrefEditField.Value =  (pi^2)/(16*1.2337);
                else
                    app.VrefEditField.Value =   4/pi;
                end
                
            else
                if strcmp(app.WaveEmitRDropDown.Value,'Square')
                    app.VrefEditField.Value =  4/pi;
                else
                    app.VrefEditField.Value =  1;
                end
            end
            
            Draw(app);
            
        end

        % Button pushed function: ConfigInfoButton
        function ConfigInfoButtonPushed(app, event)
            
            app.EmitterWaveInfoEditField.Value            = app.EmitterWave;
            app.EmitterFrequencyInfoEditField.Value       = app.EmitterFreq;
            app.EmitterVpInfoEditField.Value              = app.EmitterVp;
            app.WarmUptimeInfoEditField.Value             = app.WarmUpConfig_time;
            app.SensetimeInfoEditField.Value              = app.MeasConfig_time;
            
            
            if strcmp(app.ConfigInfoPanel.Visible,'on')
                app.ConfigInfoPanel.Visible='off';
            else
                app.ConfigInfoPanel.Visible='on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.egse_guiUIFigure = uifigure('Visible', 'off');
            app.egse_guiUIFigure.Name = 'egse_gui';
            app.egse_guiUIFigure.CloseRequestFcn = createCallbackFcn(app, @egse_guiUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.egse_guiUIFigure);
            app.ConnectTab = uitab(app.TabGroup);
            app.ConnectTab.Title = 'Connect';
            app.CommandlogTextAreaLabel = uilabel(app.ConnectTab);
            app.CommandlogTextAreaLabel.Text = 'Command log';
            app.CommandlogTextArea = uitextarea(app.ConnectTab);
            app.CommandlogTextArea.Editable = 'off';
            app.NopButton = uibutton(app.ConnectTab, 'push');
            app.NopButton.ButtonPushedFcn = createCallbackFcn(app, @NopButtonPushed, true);
            app.NopButton.Text = 'Nop';
            app.StatusButton = uibutton(app.ConnectTab, 'push');
            app.StatusButton.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
            app.StatusButton.Text = 'Status';
            app.COMPortDropDownLabel = uilabel(app.ConnectTab);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.ConnectTab);
            app.COMPortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM18'};
            app.COMPortDropDown.ValueChangedFcn = createCallbackFcn(app, @COMPortDropDownValueChanged, true);
            app.COMPortDropDown.Value = 'COM1';
            app.BaudrateDropDownLabel = uilabel(app.ConnectTab);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.ConnectTab);
            app.BaudrateDropDown.Items = {'9600', '19200', '38400', '57600', '115200'};
            app.BaudrateDropDown.ValueChangedFcn = createCallbackFcn(app, @BaudrateDropDownValueChanged, true);
            app.BaudrateDropDown.Value = '9600';
            app.ErrorLampLabel = uilabel(app.ConnectTab);
            app.ErrorLampLabel.Text = 'Error';
            app.ErrorLamp = uilamp(app.ConnectTab);
            app.ClearlogButton = uibutton(app.ConnectTab, 'push');
            app.ClearlogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlogButtonPushed, true);
            app.ClearlogButton.Text = 'Clear log';
            app.SensedataButton = uibutton(app.ConnectTab, 'push');
            app.SensedataButton.ButtonPushedFcn = createCallbackFcn(app, @SensedataButtonPushed, true);
            app.SensedataButton.Text = 'Sense data';
            app.StateEditFieldLabel = uilabel(app.ConnectTab);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.ConnectTab, 'text');
            app.StateEditField.Editable = 'off';
            app.StateEditField.Value = '?';
            app.SwitchOffButton = uibutton(app.ConnectTab, 'push');
            app.SwitchOffButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchOffButtonPushed, true);
            app.SwitchOffButton.Text = 'Switch Off';
            app.SampleSwitch_2 = uiswitch(app.ConnectTab, 'slider');
            app.SampleSwitch_2.ValueChangedFcn = createCallbackFcn(app, @SampleSwitch_InitValueChanged, true);
            app.HelpButton = uibutton(app.ConnectTab, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.SensemodeButton = uibutton(app.ConnectTab, 'push');
            app.SensemodeButton.ButtonPushedFcn = createCallbackFcn(app, @SensemodeButtonPushed, true);
            app.SensemodeButton.Text = 'Sense mode';
            app.StartButton = uibutton(app.ConnectTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TestdataButton = uibutton(app.ConnectTab, 'push');
            app.TestdataButton.ButtonPushedFcn = createCallbackFcn(app, @TestdataButtonPushed, true);
            app.TestdataButton.Text = 'Test data';
            app.ProgresGauge_2Label = uilabel(app.ConnectTab);
            app.ProgresGauge_2Label.Text = 'Progres';
            app.ConnectProgresGauge = uigauge(app.ConnectTab, 'linear');
            app.ConnectProgresGauge.MajorTicks = [0 20 40 60 80 100];
            app.error_text = uieditfield(app.ConnectTab, 'text');
            app.error_text.Editable = 'off';
            app.DirEditFieldLabel = uilabel(app.ConnectTab);
            app.DirEditFieldLabel.Text = 'Dir:';
            app.DirEditField = uieditfield(app.ConnectTab, 'text');
            app.DirEditField.Editable = 'off';
            app.ConfigButton = uibutton(app.ConnectTab, 'push');
            app.ConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigButtonPushed, true);
            app.ConfigButton.Icon = 'settings_black_24dp.png';
            app.ConfigButton.Text = '';
            app.FilenameEditFieldLabel = uilabel(app.ConnectTab);
            app.FilenameEditFieldLabel.Text = 'File name:';
            app.FilenameEditField = uieditfield(app.ConnectTab, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.ButtonBrowseDir = uibutton(app.ConnectTab, 'push');
            app.ButtonBrowseDir.ButtonPushedFcn = createCallbackFcn(app, @ButtonBrowseDirPushed, true);
            app.ButtonBrowseDir.Icon = 'open-file-icon.png';
            app.ButtonBrowseDir.Text = '';
            app.CheckBoxFileName = uicheckbox(app.ConnectTab);
            app.CheckBoxFileName.ValueChangedFcn = createCallbackFcn(app, @CheckBoxFileNameValueChanged, true);
            app.CheckBoxFileName.Text = '';
            app.ReadConfigButton = uibutton(app.ConnectTab, 'push');
            app.ReadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ReadConfigButtonPushed, true);
            app.ReadConfigButton.Enable = 'off';
            app.ReadConfigButton.Text = 'Read Config';
            app.ConfigPanel = uipanel(app.ConnectTab);
            app.ConfigPanel.Title = 'Configuration';
            app.ModelButtonGroup = uibuttongroup(app.ConfigPanel);
            app.ModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelButtonGroupSelectionChanged, true);
            app.ModelButtonGroup.Title = 'Model';
            app.CM1Button = uiradiobutton(app.ModelButtonGroup);
            app.CM1Button.Text = 'CM1';
            app.CM1Button.Value = true;
            app.EMEQMButton = uiradiobutton(app.ModelButtonGroup);
            app.EMEQMButton.Text = 'EM/EQM';
            app.FMButton = uiradiobutton(app.ModelButtonGroup);
            app.FMButton.Text = 'FM';
            app.CM2Button = uiradiobutton(app.ModelButtonGroup);
            app.CM2Button.Text = 'CM2';
            app.FSButton = uiradiobutton(app.ModelButtonGroup);
            app.FSButton.Text = 'FS';
            app.CM3Button = uiradiobutton(app.ModelButtonGroup);
            app.CM3Button.Text = 'CM3';
            app.EBBButton = uiradiobutton(app.ModelButtonGroup);
            app.EBBButton.Text = 'EBB';
            app.DustSensorPreferencesPanel = uipanel(app.ConfigPanel);
            app.DustSensorPreferencesPanel.Title = 'Dust Sensor Preferences';
            app.EmitterPanel = uipanel(app.DustSensorPreferencesPanel);
            app.EmitterPanel.Title = 'Emitter';
            app.WaveDropDownLabel = uilabel(app.EmitterPanel);
            app.WaveDropDownLabel.Text = 'Wave';
            app.WaveDropDown = uidropdown(app.EmitterPanel);
            app.WaveDropDown.Items = {'Sine', 'Square', 'Sqrt Sine'};
            app.WaveDropDown.Value = 'Sine';
            app.FrequencyDropDownLabel = uilabel(app.EmitterPanel);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.EmitterPanel);
            app.FrequencyDropDown.Items = {' 2 Hz', ' 5 Hz', '10 Hz', '20 Hz', '50 Hz', '100 Hz'};
            app.FrequencyDropDown.Value = '10 Hz';
            app.EmitterWaveCheckBox = uicheckbox(app.EmitterPanel);
            app.EmitterWaveCheckBox.Enable = 'off';
            app.EmitterWaveCheckBox.Text = '';
            app.EmitterFreqCheckBox = uicheckbox(app.EmitterPanel);
            app.EmitterFreqCheckBox.Enable = 'off';
            app.EmitterFreqCheckBox.Text = '';
            app.EmitterVpCheckBox = uicheckbox(app.EmitterPanel);
            app.EmitterVpCheckBox.Enable = 'off';
            app.EmitterVpCheckBox.Text = '';
            app.EmitterWaveLamp = uilamp(app.EmitterPanel);
            app.EmitterFreqLamp = uilamp(app.EmitterPanel);
            app.EmitterVpLamp = uilamp(app.EmitterPanel);
            app.VpLabel = uilabel(app.EmitterPanel);
            app.VpLabel.Text = 'Vp';
            app.VpEditField = uieditfield(app.EmitterPanel, 'numeric');
            app.VpEditField.Limits = [0 1];
            app.VpEditField.ValueDisplayFormat = '%.5f';
            app.VpEditField.Value = 1;
            app.SensetimeEditFieldLabel = uilabel(app.DustSensorPreferencesPanel);
            app.SensetimeEditFieldLabel.Text = 'Sense time';
            app.SensetimeC_EditField = uieditfield(app.DustSensorPreferencesPanel, 'numeric');
            app.SensetimeC_EditField.Limits = [0 1023];
            app.SensetimeC_EditField.RoundFractionalValues = 'on';
            app.SensetimeC_EditField.ValueDisplayFormat = '%.0f';
            app.SensetimeC_EditField.Value = 616;
            app.ConfigApplyButton = uibutton(app.DustSensorPreferencesPanel, 'push');
            app.ConfigApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyConfigButtonPushed, true);
            app.ConfigApplyButton.Text = 'Apply';
            app.CloseConfigButton = uibutton(app.DustSensorPreferencesPanel, 'push');
            app.CloseConfigButton.ButtonPushedFcn = createCallbackFcn(app, @CloseConfigButtonPushed, true);
            app.CloseConfigButton.Text = 'Close';
            app.WarmUpTimeLamp = uilamp(app.DustSensorPreferencesPanel);
            app.SensingTimeLamp = uilamp(app.DustSensorPreferencesPanel);
            app.WarmUpTimeCheckBox = uicheckbox(app.DustSensorPreferencesPanel);
            app.WarmUpTimeCheckBox.Text = '';
            app.SensingTimeCheckBox = uicheckbox(app.DustSensorPreferencesPanel);
            app.SensingTimeCheckBox.Text = '';
            app.ConfigInfoButton = uibutton(app.DustSensorPreferencesPanel, 'push');
            app.ConfigInfoButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigInfoButtonPushed, true);
            app.ConfigInfoButton.Text = 'Info';
            app.WarmUptimeEditFieldLabel = uilabel(app.DustSensorPreferencesPanel);
            app.WarmUptimeEditFieldLabel.Text = 'WarmUp time';
            app.WarmUptimeC_EditField = uieditfield(app.DustSensorPreferencesPanel, 'numeric');
            app.WarmUptimeC_EditField.Limits = [0 1023];
            app.WarmUptimeC_EditField.RoundFractionalValues = 'on';
            app.WarmUptimeC_EditField.ValueDisplayFormat = '%.0f';
            app.WarmUptimeC_EditField.Value = 309;
            app.ModeLabel = uilabel(app.ConfigPanel);
            app.ModeLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.ConfigPanel, 'slider');
            app.ModeSwitch.Items = {'L', 'NL'};
            app.ModeSwitch.Value = 'L';
            app.ConfigInfoPanel = uipanel(app.ConfigPanel);
            app.ConfigInfoPanel.Title = 'Dust Sensor Preferences Information';
            app.ConfigInfoPanel.Visible = 'off';
            app.EmitterWaveEditFieldLabel = uilabel(app.ConfigInfoPanel);
            app.EmitterWaveEditFieldLabel.Text = 'Emitter Wave';
            app.EmitterWaveInfoEditField = uieditfield(app.ConfigInfoPanel, 'text');
            app.EmitterWaveInfoEditField.Editable = 'off';
            app.EmitterVpEditFieldLabel = uilabel(app.ConfigInfoPanel);
            app.EmitterVpEditFieldLabel.Text = 'Emitter Vp';
            app.EmitterVpInfoEditField = uieditfield(app.ConfigInfoPanel, 'numeric');
            app.EmitterVpInfoEditField.Editable = 'off';
            app.WarmUptimeEditFieldLabel_2 = uilabel(app.ConfigInfoPanel);
            app.WarmUptimeEditFieldLabel_2.Text = 'WarmUp time';
            app.WarmUptimeInfoEditField = uieditfield(app.ConfigInfoPanel, 'numeric');
            app.WarmUptimeInfoEditField.Editable = 'off';
            app.SensetimeEditFieldLabel_2 = uilabel(app.ConfigInfoPanel);
            app.SensetimeEditFieldLabel_2.Text = 'Sense time';
            app.SensetimeInfoEditField = uieditfield(app.ConfigInfoPanel, 'numeric');
            app.SensetimeInfoEditField.Editable = 'off';
            app.EmitterFrequencyLabel = uilabel(app.ConfigInfoPanel);
            app.EmitterFrequencyLabel.Text = 'Emitter Frequency';
            app.EmitterFrequencyInfoEditField = uieditfield(app.ConfigInfoPanel, 'text');
            app.EmitterFrequencyInfoEditField.Editable = 'off';
            app.AcquireTab = uitab(app.TabGroup);
            app.AcquireTab.Title = 'Acquire';
            app.DrawButton = uibutton(app.AcquireTab, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.FigureButton = uibutton(app.AcquireTab, 'push');
            app.FigureButton.ButtonPushedFcn = createCallbackFcn(app, @FigureButtonPushed, true);
            app.FigureButton.Text = 'Figure';
            app.SignalsPanel = uipanel(app.AcquireTab);
            app.SignalsPanel.Title = 'Signals';
            app.V1CheckBox = uicheckbox(app.SignalsPanel);
            app.V1CheckBox.ValueChangedFcn = createCallbackFcn(app, @V1CheckBoxValueChanged, true);
            app.V1CheckBox.Text = 'V1';
            app.V1CheckBox.Value = true;
            app.Detector1PbSFwdLabel = uilabel(app.SignalsPanel);
            app.Detector1PbSFwdLabel.Text = 'Detector1 PbS Fwd:';
            app.CheckBox = uicheckbox(app.SignalsPanel);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = 'θ';
            app.XCheckBox = uicheckbox(app.SignalsPanel);
            app.XCheckBox.ValueChangedFcn = createCallbackFcn(app, @XCheckBoxValueChanged, true);
            app.XCheckBox.Enable = 'off';
            app.XCheckBox.Text = 'X';
            app.YCheckBox = uicheckbox(app.SignalsPanel);
            app.YCheckBox.Enable = 'off';
            app.YCheckBox.Text = 'Y';
            app.V2CheckBox = uicheckbox(app.SignalsPanel);
            app.V2CheckBox.ValueChangedFcn = createCallbackFcn(app, @V2CheckBoxValueChanged, true);
            app.V2CheckBox.Text = 'V2';
            app.V2CheckBox.Value = true;
            app.Detector2PbSeFwdLabel = uilabel(app.SignalsPanel);
            app.Detector2PbSeFwdLabel.Text = 'Detector2 PbSe Fwd:';
            app.V3CheckBox = uicheckbox(app.SignalsPanel);
            app.V3CheckBox.ValueChangedFcn = createCallbackFcn(app, @V3CheckBoxValueChanged, true);
            app.V3CheckBox.Text = 'V3';
            app.V3CheckBox.Value = true;
            app.Detector3PbSBwdLabel = uilabel(app.SignalsPanel);
            app.Detector3PbSBwdLabel.Text = 'Detector3 PbS Bwd:';
            app.CheckBox_2 = uicheckbox(app.SignalsPanel);
            app.CheckBox_2.Enable = 'off';
            app.CheckBox_2.Text = 'θ';
            app.XCheckBox_2 = uicheckbox(app.SignalsPanel);
            app.XCheckBox_2.Enable = 'off';
            app.XCheckBox_2.Text = 'X';
            app.YCheckBox_2 = uicheckbox(app.SignalsPanel);
            app.YCheckBox_2.Enable = 'off';
            app.YCheckBox_2.Text = 'Y';
            app.CheckBox_3 = uicheckbox(app.SignalsPanel);
            app.CheckBox_3.Enable = 'off';
            app.CheckBox_3.Text = 'θ';
            app.XCheckBox_3 = uicheckbox(app.SignalsPanel);
            app.XCheckBox_3.Enable = 'off';
            app.XCheckBox_3.Text = 'X';
            app.YCheckBox_3 = uicheckbox(app.SignalsPanel);
            app.YCheckBox_3.Enable = 'off';
            app.YCheckBox_3.Text = 'Y';
            app.Detector4PbSeBwdLabel = uilabel(app.SignalsPanel);
            app.Detector4PbSeBwdLabel.Text = 'Detector4 PbSe Bwd:';
            app.V4CheckBox = uicheckbox(app.SignalsPanel);
            app.V4CheckBox.ValueChangedFcn = createCallbackFcn(app, @V4CheckBoxValueChanged, true);
            app.V4CheckBox.Text = 'V4';
            app.V4CheckBox.Value = true;
            app.CheckBox_4 = uicheckbox(app.SignalsPanel);
            app.CheckBox_4.Enable = 'off';
            app.CheckBox_4.Text = 'θ';
            app.XCheckBox_4 = uicheckbox(app.SignalsPanel);
            app.XCheckBox_4.Enable = 'off';
            app.XCheckBox_4.Text = 'X';
            app.YCheckBox_4 = uicheckbox(app.SignalsPanel);
            app.YCheckBox_4.Enable = 'off';
            app.YCheckBox_4.Text = 'Y';
            app.Vp1EditFieldLabel = uilabel(app.AcquireTab);
            app.Vp1EditFieldLabel.Text = 'Vp1';
            app.Vp1EditField = uieditfield(app.AcquireTab, 'numeric');
            app.Vp1EditField.Editable = 'off';
            app.Vp2EditFieldLabel = uilabel(app.AcquireTab);
            app.Vp2EditFieldLabel.Text = 'Vp2';
            app.Vp2EditField = uieditfield(app.AcquireTab, 'numeric');
            app.Vp2EditField.Editable = 'off';
            app.Vp3EditFieldLabel = uilabel(app.AcquireTab);
            app.Vp3EditFieldLabel.Text = 'Vp3';
            app.Vp3EditField = uieditfield(app.AcquireTab, 'numeric');
            app.Vp3EditField.Editable = 'off';
            app.Vp4EditFieldLabel = uilabel(app.AcquireTab);
            app.Vp4EditFieldLabel.Text = 'Vp4';
            app.Vp4EditField = uieditfield(app.AcquireTab, 'numeric');
            app.Vp4EditField.Editable = 'off';
            app.TButton = uibutton(app.AcquireTab, 'push');
            app.TButton.ButtonPushedFcn = createCallbackFcn(app, @TButtonPushed, true);
            app.TButton.Text = 'Tª';
            app.VButton = uibutton(app.AcquireTab, 'push');
            app.VButton.ButtonPushedFcn = createCallbackFcn(app, @VButtonPushed, true);
            app.VButton.Text = 'V';
            app.PhaseButton = uibutton(app.AcquireTab, 'push');
            app.PhaseButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseButtonPushed, true);
            app.PhaseButton.Text = 'θ';
            app.ExportdataButton = uibutton(app.AcquireTab, 'push');
            app.ExportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdataButtonPushed, true);
            app.ExportdataButton.Text = 'Export data';
            app.ConfigButton_2 = uibutton(app.AcquireTab, 'push');
            app.ConfigButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConfigButton_2Pushed, true);
            app.ConfigButton_2.Icon = 'settings_black_24dp.png';
            app.ConfigButton_2.Text = '';
            app.ConfigPanel_2 = uipanel(app.AcquireTab);
            app.ConfigPanel_2.Title = 'Config';
            app.InputdataPanel = uipanel(app.ConfigPanel_2);
            app.InputdataPanel.Title = 'Input data';
            app.SignedCheckBox = uicheckbox(app.InputdataPanel);
            app.SignedCheckBox.ValueChangedFcn = createCallbackFcn(app, @SignedCheckBoxValueChanged, true);
            app.SignedCheckBox.Text = 'Signed';
            app.SignedCheckBox.Value = true;
            app.BitsEditFieldLabel = uilabel(app.InputdataPanel);
            app.BitsEditFieldLabel.Text = 'Bits';
            app.BitsEditField = uieditfield(app.InputdataPanel, 'numeric');
            app.BitsEditField.Limits = [1 Inf];
            app.BitsEditField.ValueChangedFcn = createCallbackFcn(app, @BitsEditFieldValueChanged, true);
            app.BitsEditField.Value = 16;
            app.AxisPanel = uipanel(app.ConfigPanel_2);
            app.AxisPanel.Title = 'Axis';
            app.SamplingFreqHzEditFieldLabel = uilabel(app.AxisPanel);
            app.SamplingFreqHzEditFieldLabel.Text = {'Sampling'; 'Freq. (Hz)'};
            app.SamplingFreqHzEditField = uieditfield(app.AxisPanel, 'numeric');
            app.SamplingFreqHzEditField.Limits = [1e-08 Inf];
            app.SamplingFreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFreqHzEditFieldValueChanged, true);
            app.SamplingFreqHzEditField.Value = 1000;
            app.YmaxEditField_2Label = uilabel(app.AxisPanel);
            app.YmaxEditField_2Label.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.AxisPanel, 'numeric');
            app.YmaxEditField.Limits = [0 Inf];
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxEditFieldValueChanged, true);
            app.YmaxEditField.Value = 2.5;
            app.YminEditFieldLabel = uilabel(app.AxisPanel);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.AxisPanel, 'numeric');
            app.YminEditField.Limits = [-Inf 0];
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YminEditFieldValueChanged, true);
            app.YminEditField.Value = -2.5;
            app.SaveExportedDataLabel = uilabel(app.ConfigPanel_2);
            app.SaveExportedDataLabel.Text = 'Save Exported Data';
            app.SaveExportedDataSwitch = uiswitch(app.ConfigPanel_2, 'slider');
            app.ReferenceSignalPanel = uipanel(app.ConfigPanel_2);
            app.ReferenceSignalPanel.Title = 'Reference Signal';
            app.RefSwitch_3Label = uilabel(app.ReferenceSignalPanel);
            app.RefSwitch_3Label.Text = 'Ref';
            app.RefSwitch = uiswitch(app.ReferenceSignalPanel, 'slider');
            app.RefSwitch.Items = {'Sqr', 'Sine'};
            app.RefSwitch.ValueChangedFcn = createCallbackFcn(app, @RefSwitchValueChanged, true);
            app.RefSwitch.Value = 'Sqr';
            app.EmitterDropDownLabel = uilabel(app.ReferenceSignalPanel);
            app.EmitterDropDownLabel.Text = 'Emitter';
            app.WaveEmitRDropDown = uidropdown(app.ReferenceSignalPanel);
            app.WaveEmitRDropDown.Items = {'Sine', 'Square', 'Sqrt Sine'};
            app.WaveEmitRDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveEmitRValueChange, true);
            app.WaveEmitRDropDown.Value = 'Sine';
            app.VrefEditFieldLabel = uilabel(app.ReferenceSignalPanel);
            app.VrefEditFieldLabel.Text = 'Vref';
            app.VrefEditField = uieditfield(app.ReferenceSignalPanel, 'numeric');
            app.VrefEditField.Limits = [0 Inf];
            app.VrefEditField.ValueChangedFcn = createCallbackFcn(app, @VrefEditFieldValueChanged, true);
            app.TempCorrectionPanel = uipanel(app.ConfigPanel_2);
            app.TempCorrectionPanel.Title = 'Temp Correction';
            app.CM3TempcorrectionVcc2SwitchLabel = uilabel(app.TempCorrectionPanel);
            app.CM3TempcorrectionVcc2SwitchLabel.Text = 'CM3 Temp correction Vcc/2';
            app.CM3TempcorrectionVcc2Switch = uiswitch(app.TempCorrectionPanel, 'slider');
            app.FMTempcorrectionVcc2SwitchLabel = uilabel(app.TempCorrectionPanel);
            app.FMTempcorrectionVcc2SwitchLabel.Text = 'FM Temp correction Vcc/2';
            app.FMTempcorrectionVcc2Switch = uiswitch(app.TempCorrectionPanel, 'slider');
            app.UIAxes = uiaxes(app.AcquireTab);
            title(app.UIAxes, 'Sensor data')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'V')
            app.MultiTab = uitab(app.TabGroup);
            app.MultiTab.Title = 'Multi';
            app.UIAxes_1 = uiaxes(app.MultiTab);
            ylabel(app.UIAxes_1, 'V(1)')
            app.UIAxes_2 = uiaxes(app.MultiTab);
            ylabel(app.UIAxes_2, 'V(2)')
            app.UIAxes_3 = uiaxes(app.MultiTab);
            ylabel(app.UIAxes_3, 'V(3)')
            app.UIAxes_4 = uiaxes(app.MultiTab);
            xlabel(app.UIAxes_4, 't')
            ylabel(app.UIAxes_4, 'V(4)')
            app.MultiTab_fft = uitab(app.TabGroup);
            app.MultiTab_fft.Title = 'FFT';
            app.UIAxes_fft_1 = uiaxes(app.MultiTab_fft);
            ylabel(app.UIAxes_fft_1, 'V(1)(dB)')
            app.UIAxes_fft_2 = uiaxes(app.MultiTab_fft);
            ylabel(app.UIAxes_fft_2, 'V(2)(dB)')
            app.UIAxes_fft_3 = uiaxes(app.MultiTab_fft);
            ylabel(app.UIAxes_fft_3, 'V(3)(dB)')
            app.UIAxes_fft_4 = uiaxes(app.MultiTab_fft);
            xlabel(app.UIAxes_fft_4, 'Hz')
            ylabel(app.UIAxes_fft_4, 'V(4)(dB)')
            app.AutoTab = uitab(app.TabGroup);
            app.AutoTab.Title = 'Auto';
            app.LogwindowTextAreaLabel = uilabel(app.AutoTab);
            app.LogwindowTextAreaLabel.Text = 'Log window';
            app.LogwindowTextArea = uitextarea(app.AutoTab);
            app.LogwindowTextArea.Editable = 'off';
            app.AutosetupPanel = uipanel(app.AutoTab);
            app.AutosetupPanel.Title = 'Auto setup';
            app.SelftesttimeLabel = uilabel(app.AutosetupPanel);
            app.SelftesttimeLabel.Text = 'Self-test time';
            app.SelftesttimeEditField = uieditfield(app.AutosetupPanel, 'numeric');
            app.SelftesttimeEditField.Limits = [0 Inf];
            app.SelftesttimeEditField.Value = 10;
            app.WarmuptimeEditFieldLabel = uilabel(app.AutosetupPanel);
            app.WarmuptimeEditFieldLabel.Text = 'Warm up time';
            app.WarmuptimeEditField = uieditfield(app.AutosetupPanel, 'numeric');
            app.WarmuptimeEditField.Limits = [0 Inf];
            app.WarmuptimeEditField.Value = 309;
            app.MeasurementtimeEditFieldLabel = uilabel(app.AutosetupPanel);
            app.MeasurementtimeEditFieldLabel.Text = 'Measurement time';
            app.MeasurementtimeEditField = uieditfield(app.AutosetupPanel, 'numeric');
            app.MeasurementtimeEditField.Limits = [0 Inf];
            app.MeasurementtimeEditField.Value = 616;
            app.SwitchofftimeEditFieldLabel = uilabel(app.AutosetupPanel);
            app.SwitchofftimeEditFieldLabel.Text = 'Switch off time';
            app.SwitchofftimeEditField = uieditfield(app.AutosetupPanel, 'numeric');
            app.SwitchofftimeEditField.Limits = [0 Inf];
            app.SwitchofftimeEditField.Value = 5;
            app.NofcampaignsEditFieldLabel = uilabel(app.AutosetupPanel);
            app.NofcampaignsEditFieldLabel.Text = 'Nº of campaigns';
            app.NofcampaignsEditField = uieditfield(app.AutosetupPanel, 'numeric');
            app.NofcampaignsEditField.Limits = [0 Inf];
            app.NofcampaignsEditField.RoundFractionalValues = 'on';
            app.NofcampaignsEditField.ValueDisplayFormat = '%.0f';
            app.NofcampaignsEditField.Value = 1;
            app.NameEditFieldLabel = uilabel(app.AutosetupPanel);
            app.NameEditFieldLabel.Text = 'Name ';
            app.NameEditField = uieditfield(app.AutosetupPanel, 'text');
            app.NameEditField.Value = 'Automatic_measurements';
            app.StartButton_2 = uibutton(app.AutoTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.ProgresGaugeLabel = uilabel(app.AutoTab);
            app.ProgresGaugeLabel.Text = 'Progres';
            app.ProgresGauge = uigauge(app.AutoTab, 'linear');
            app.CancelButton = uibutton(app.AutoTab, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Cancel';
            app.Switch = uiswitch(app.AutoTab, 'slider');
            app.Switch.Items = {'Cmd', 'Relays'};
            app.Switch.Value = 'Cmd';
            app.MaxtempEditFieldLabel = uilabel(app.AutoTab);
            app.MaxtempEditFieldLabel.Text = 'Max temp';
            app.MaxtempEditField = uieditfield(app.AutoTab, 'numeric');
            app.MaxtempEditField.Value = 80;
            app.PlotButton = uibutton(app.AutoTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ContinuousmodeCheckBox = uicheckbox(app.AutoTab);
            app.ContinuousmodeCheckBox.Text = 'Continuous mode';
            app.ConfigButton_3 = uibutton(app.AutoTab, 'push');
            app.ConfigButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConfigButton_3Pushed, true);
            app.ConfigButton_3.Icon = 'settings_black_24dp.png';
            app.ConfigButton_3.Text = '';
            app.AutoplotconfigPanel = uipanel(app.AutoTab);
            app.AutoplotconfigPanel.Title = 'Auto plot config';
            app.OrderfilesbyButtonGroup = uibuttongroup(app.AutoplotconfigPanel);
            app.OrderfilesbyButtonGroup.Title = 'Order files by:';
            app.nameButton = uiradiobutton(app.OrderfilesbyButtonGroup);
            app.nameButton.Text = 'name';
            app.campaigneButton = uiradiobutton(app.OrderfilesbyButtonGroup);
            app.campaigneButton.Text = 'campaigne';
            app.campaigneButton.Value = true;
            app.dateButton = uiradiobutton(app.OrderfilesbyButtonGroup);
            app.dateButton.Text = 'date';
            app.FMTempcorrectionVcc2Switch_2Label = uilabel(app.AutoplotconfigPanel);
            app.FMTempcorrectionVcc2Switch_2Label.Text = 'FM Temp correction Vcc/2';
            app.FMTempcorrectionVcc2Switch_2 = uiswitch(app.AutoplotconfigPanel, 'slider');
            app.RefSwitchLabel_2 = uilabel(app.AutoplotconfigPanel);
            app.RefSwitchLabel_2.Text = 'Ref';
            app.VrefEditField_2Label = uilabel(app.AutoplotconfigPanel);
            app.VrefEditField_2Label.Text = 'Vref';
            app.VrefEditField_2 = uieditfield(app.AutoplotconfigPanel, 'numeric');
            app.VrefEditField_2.Limits = [0 Inf];
            app.RefSwitch_2 = uiswitch(app.AutoplotconfigPanel, 'slider');
            app.RefSwitch_2.Items = {'Sqr', 'Sine'};
            app.RefSwitch_2.Value = 'Sqr';
            app.EmitterLabel = uilabel(app.AutoplotconfigPanel);
            app.EmitterLabel.Text = 'Emitter';
            app.WaveEmitRDropDown_2 = uidropdown(app.AutoplotconfigPanel);
            app.WaveEmitRDropDown_2.Items = {'Sine', 'Square', 'Sqrt Sine'};
            app.WaveEmitRDropDown_2.Value = 'Sine';
            app.DrawingoptionsPanel = uipanel(app.AutoplotconfigPanel);
            app.DrawingoptionsPanel.Title = 'Drawing options';
            app.PbSFwdCheckBox = uicheckbox(app.DrawingoptionsPanel);
            app.PbSFwdCheckBox.Text = 'PbS Fwd';
            app.PbSeFwdCheckBox = uicheckbox(app.DrawingoptionsPanel);
            app.PbSeFwdCheckBox.Text = 'PbSe Fwd';
            app.PbSBwdCheckBox = uicheckbox(app.DrawingoptionsPanel);
            app.PbSBwdCheckBox.Text = 'PbS Bwd';
            app.Vcc2CheckBox = uicheckbox(app.DrawingoptionsPanel);
            app.Vcc2CheckBox.Text = 'Vcc/2';
            app.RatioPbSCheckBox = uicheckbox(app.DrawingoptionsPanel);
            app.RatioPbSCheckBox.Text = 'Ratio PbS';
            app.RatioPbSeCheckBox = uicheckbox(app.DrawingoptionsPanel);
            app.RatioPbSeCheckBox.Text = 'Ratio PbSe';
            app.PbSeBwdCheckBox = uicheckbox(app.DrawingoptionsPanel);
            app.PbSeBwdCheckBox.Text = 'PbSe Bwd';
            app.ProgresTimeEditField = uieditfield(app.AutoTab, 'text');
            app.ProgresTimeEditField.Editable = 'off';
            app.DatafileEditFieldLabel = uilabel(app.egse_guiUIFigure);
            app.DatafileEditFieldLabel.Text = 'Data file';
            app.DatafileEditField = uieditfield(app.egse_guiUIFigure, 'text');
            app.DatafileEditField.Editable = 'off';
            app.ButtonBrowse = uibutton(app.egse_guiUIFigure, 'push');
            app.ButtonBrowse.ButtonPushedFcn = createCallbackFcn(app, @ButtonBrowsePushed, true);
            app.ButtonBrowse.Icon = 'open-file-icon.png';
            app.ButtonBrowse.Text = '';
            app.RefreshButton = uibutton(app.egse_guiUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.egse_guiUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = egse_DS20_11_11_21

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.egse_guiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.egse_guiUIFigure)
        end
    end
end